classdef scpThreshold < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UIAxes                        matlab.ui.control.UIAxes
        DefineParametersforAutoDetectionLabel  matlab.ui.control.Label
        SearchRadiusEditFieldLabel    matlab.ui.control.Label
        SearchRadiusEditField         matlab.ui.control.NumericEditField
        SearchRadiusEditFieldLabel_2  matlab.ui.control.Label
        IntensityThresholdSlider      matlab.ui.control.Slider
        SCPTypeButtonGroup            matlab.ui.container.ButtonGroup
        BrightButton_2                matlab.ui.control.RadioButton
        DarkButton                    matlab.ui.control.RadioButton
        UIAxes_2                      matlab.ui.control.UIAxes
        Label                         matlab.ui.control.Label
        SaveParametersButton          matlab.ui.control.Button
        HelpButton                    matlab.ui.control.Button
        IntensityThresholdEditFieldLabel  matlab.ui.control.Label
        IntensityThresholdEditField   matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        %% pull image from 
         global  Iscp UVdClick
         initializeSCPThresh(app)   


        end

        % Selection changed function: SCPTypeButtonGroup
        function SCPTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.SCPTypeButtonGroup.SelectedObject;
            global  Iscp UVdClick
            replotSCP(app)
        end

        % Value changed function: SearchRadiusEditField
        function SearchRadiusEditFieldValueChanged(app, event)
            value = app.SearchRadiusEditField.Value;
            global  Iscp UVdClick
            replotSCP(app)
        end

        % Callback function
        function IntensityThresholdSliderValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Value changed function: IntensityThresholdSlider
        function IntensityThresholdSliderValueChanged(app, event)
            value = app.IntensityThresholdSlider.Value;
            global  Iscp UVdClick
            replotSCP(app)
            app.IntensityThresholdEditField.Value = app.IntensityThresholdSlider.Value;
        end

        % Button pushed function: SaveParametersButton
        function SaveParametersButtonPushed(app, event)
            global scpList
           
            if app.BrightButton_2.Value==1
             brightFlag='bright';
            end
            if app.DarkButton.Value==1
             brightFlag='dark';
            end
          
            L=length(scpList);
                   scpList(L+1).R=app.SearchRadiusEditField.Value;
                 scpList(L+1).T=app.IntensityThresholdSlider.Value;
                 scpList(L+1).brightFlag=brightFlag;            


            close(app.UIFigure)
        end

        % Value changed function: IntensityThresholdEditField
        function IntensityThresholdEditFieldValueChanged(app, event)
            value = app.IntensityThresholdEditField.Value;
                         app.IntensityThresholdSlider.Value=app.IntensityThresholdEditField.Value;
                         replotSCP(app)

        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            help_scpThresh(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Intensity')
            xlabel(app.UIAxes, 'U')
            ylabel(app.UIAxes, 'V')
            app.DefineParametersforAutoDetectionLabel = uilabel(app.UIFigure);
            app.DefineParametersforAutoDetectionLabel.Text = 'Define Parameters for Auto Detection';
            app.SearchRadiusEditFieldLabel = uilabel(app.UIFigure);
            app.SearchRadiusEditFieldLabel.Text = 'Search Radius';
            app.SearchRadiusEditField = uieditfield(app.UIFigure, 'numeric');
            app.SearchRadiusEditField.ValueChangedFcn = createCallbackFcn(app, @SearchRadiusEditFieldValueChanged, true);
            app.SearchRadiusEditField.Value = 50;
            app.SearchRadiusEditFieldLabel_2 = uilabel(app.UIFigure);
            app.SearchRadiusEditFieldLabel_2.Text = 'pixels';
            app.IntensityThresholdSlider = uislider(app.UIFigure);
            app.IntensityThresholdSlider.Limits = [0 256];
            app.IntensityThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @IntensityThresholdSliderValueChanged, true);
            app.IntensityThresholdSlider.Value = 100;
            app.SCPTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.SCPTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SCPTypeButtonGroupSelectionChanged, true);
            app.SCPTypeButtonGroup.Title = 'SCP Type';
            app.BrightButton_2 = uiradiobutton(app.SCPTypeButtonGroup);
            app.BrightButton_2.Text = 'Bright';
            app.BrightButton_2.Value = true;
            app.DarkButton = uiradiobutton(app.SCPTypeButtonGroup);
            app.DarkButton.Text = 'Dark';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Thresholded Intensity')
            xlabel(app.UIAxes_2, 'U')
            ylabel(app.UIAxes_2, 'V')
            app.Label = uilabel(app.UIFigure);
            app.SaveParametersButton = uibutton(app.UIFigure, 'push');
            app.SaveParametersButton.ButtonPushedFcn = createCallbackFcn(app, @SaveParametersButtonPushed, true);
            app.SaveParametersButton.Text = 'Save Parameters';
            app.HelpButton = uibutton(app.UIFigure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.IntensityThresholdEditFieldLabel = uilabel(app.UIFigure);
            app.IntensityThresholdEditFieldLabel.Text = 'Intensity Threshold';
            app.IntensityThresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.IntensityThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @IntensityThresholdEditFieldValueChanged, true);
            app.IntensityThresholdEditField.Value = 100;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = scpThreshold

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end