classdef uasToolbox < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ProjectInitiationTab            matlab.ui.container.Tab
        Label                           matlab.ui.control.Label
        TabGroup2                       matlab.ui.container.TabGroup
        CreateLoadProjectTab            matlab.ui.container.Tab
        CreateNewProjectButton          matlab.ui.control.Button
        OpenExistingProjectButton       matlab.ui.control.Button
        LoadVideosButton                matlab.ui.control.Button
        Label_2                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        Label_9                         matlab.ui.control.Label
        Label_10                        matlab.ui.control.Label
        Label_11                        matlab.ui.control.Label
        Label_12                        matlab.ui.control.Label
        Label_120                       matlab.ui.control.Label
        Label_121                       matlab.ui.control.Label
        Lamp                            matlab.ui.control.Lamp
        Lamp_2                          matlab.ui.control.Lamp
        Lamp_3                          matlab.ui.control.Lamp
        UIAxes_2                        matlab.ui.control.UIAxes
        GenerateFramesTab_2             matlab.ui.container.Tab
        UITable                         matlab.ui.control.Table
        Label_13                        matlab.ui.control.Label
        Label_14                        matlab.ui.control.Label
        Label_15                        matlab.ui.control.Label
        Label_16                        matlab.ui.control.Label
        Label_17                        matlab.ui.control.Label
        UITable2                        matlab.ui.control.Table
        Label_18                        matlab.ui.control.Label
        Label_19                        matlab.ui.control.Label
        Label_20                        matlab.ui.control.Label
        Label_21                        matlab.ui.control.Label
        FrameRateHzEditFieldLabel       matlab.ui.control.Label
        FrameRateHzEditField            matlab.ui.control.NumericEditField
        Label_22                        matlab.ui.control.Label
        Label_23                        matlab.ui.control.Label
        Label_24                        matlab.ui.control.Label
        ExtractFramesButton             matlab.ui.control.Button
        Label_25                        matlab.ui.control.Label
        UITable3                        matlab.ui.control.Table
        UITable3_2                      matlab.ui.control.Table
        Label_26                        matlab.ui.control.Label
        Label_32                        matlab.ui.control.Label
        DirectoryNameEditFieldLabel     matlab.ui.control.Label
        DirectoryNameEditField          matlab.ui.control.EditField
        Label_33                        matlab.ui.control.Label
        Label_122                       matlab.ui.control.Label
        Label_123                       matlab.ui.control.Label
        Label_124                       matlab.ui.control.Label
        Label_125                       matlab.ui.control.Label
        Label_126                       matlab.ui.control.Label
        Lamp_4                          matlab.ui.control.Lamp
        UIAxes_3                        matlab.ui.control.UIAxes
        LoadIntrinsicsTab_2             matlab.ui.container.Tab
        Label_27                        matlab.ui.control.Label
        Label_28                        matlab.ui.control.Label
        LoadIntrinsicsButton            matlab.ui.control.Button
        Label_31                        matlab.ui.control.Label
        UITable4                        matlab.ui.control.Table
        Label_35                        matlab.ui.control.Label
        Label_34                        matlab.ui.control.Label
        FilenameEditFieldLabel          matlab.ui.control.Label
        FilenameEditField               matlab.ui.control.EditField
        SaveIntrinsicsButton            matlab.ui.control.Button
        Label_38                        matlab.ui.control.Label
        Label_39                        matlab.ui.control.Label
        Label_127                       matlab.ui.control.Label
        Label_128                       matlab.ui.control.Label
        Lamp_5                          matlab.ui.control.Lamp
        Lamp_6                          matlab.ui.control.Lamp
        HelpButton_17                   matlab.ui.control.Button
        Label_367                       matlab.ui.control.Label
        NoDistortionButton              matlab.ui.control.Button
        Lamp_30                         matlab.ui.control.Lamp
        Label_369                       matlab.ui.control.Label
        Label_370                       matlab.ui.control.Label
        GCPsTab                         matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        ClickGCPsTab                    matlab.ui.container.Tab
        AddGCPButton_2                  matlab.ui.control.Button
        Label_41                        matlab.ui.control.Label
        Label_43                        matlab.ui.control.Label
        Label_45                        matlab.ui.control.Label
        DeleteGCPButton                 matlab.ui.control.Button
        Label_48                        matlab.ui.control.Label
        Label_50                        matlab.ui.control.Label
        FilenameEditField_2Label        matlab.ui.control.Label
        FilenameEditField_2             matlab.ui.control.EditField
        Label_49                        matlab.ui.control.Label
        Label_54                        matlab.ui.control.Label
        ListBox                         matlab.ui.control.ListBox
        Label_44                        matlab.ui.control.Label
        Label_52                        matlab.ui.control.Label
        Label_55                        matlab.ui.control.Label
        Label_56                        matlab.ui.control.Label
        SaveGCPsButton                  matlab.ui.control.Button
        Label_113                       matlab.ui.control.Label
        Label_114                       matlab.ui.control.Label
        Label_115                       matlab.ui.control.Label
        Label_116                       matlab.ui.control.Label
        HelpButton_3                    matlab.ui.control.Button
        Lamp_7                          matlab.ui.control.Lamp
        UIAxes_4                        matlab.ui.control.UIAxes
        AssignGCPCoordinatesTab         matlab.ui.container.Tab
        Label_57                        matlab.ui.control.Label
        ListBox_2                       matlab.ui.control.ListBox
        Label_58                        matlab.ui.control.Label
        Label_59                        matlab.ui.control.Label
        Label_60                        matlab.ui.control.Label
        Label_61                        matlab.ui.control.Label
        Label_62                        matlab.ui.control.Label
        SaveGCPCoordinatesButton        matlab.ui.control.Button
        HorizontalEditFieldLabel        matlab.ui.control.Label
        HorizontalEditField             matlab.ui.control.EditField
        FilenameEditField_4Label        matlab.ui.control.Label
        FilenameEditField_4             matlab.ui.control.EditField
        VerticalEditFieldLabel          matlab.ui.control.Label
        VerticalEditField               matlab.ui.control.EditField
        Label_64                        matlab.ui.control.Label
        Label_65                        matlab.ui.control.Label
        UITable5                        matlab.ui.control.Table
        UnitsButtonGroup                matlab.ui.container.ButtonGroup
        ftButton                        matlab.ui.control.RadioButton
        mButton                         matlab.ui.control.RadioButton
        Label_117                       matlab.ui.control.Label
        Label_118                       matlab.ui.control.Label
        Label_119                       matlab.ui.control.Label
        Lamp_8                          matlab.ui.control.Lamp
        Label_360                       matlab.ui.control.Label
        UIAxes_5                        matlab.ui.control.UIAxes
        ExtrinsicsTab                   matlab.ui.container.Tab
        TabGroup4                       matlab.ui.container.TabGroup
        InitialSolutionTab              matlab.ui.container.Tab
        Label_66                        matlab.ui.control.Label
        ListBox_3                       matlab.ui.control.ListBox
        Label_67                        matlab.ui.control.Label
        CheckBox                        matlab.ui.control.CheckBox
        CheckBox_4                      matlab.ui.control.CheckBox
        CheckBox_5                      matlab.ui.control.CheckBox
        CheckBox_6                      matlab.ui.control.CheckBox
        CheckBox_7                      matlab.ui.control.CheckBox
        CheckBox_8                      matlab.ui.control.CheckBox
        Label_70                        matlab.ui.control.Label
        Label_75                        matlab.ui.control.Label
        Label_76                        matlab.ui.control.Label
        GCPNumbersEditFieldLabel        matlab.ui.control.Label
        GCPNumbersEditField             matlab.ui.control.EditField
        Label_81                        matlab.ui.control.Label
        UITable7                        matlab.ui.control.Table
        UITable7_2                      matlab.ui.control.Table
        Label_79                        matlab.ui.control.Label
        Label_83                        matlab.ui.control.Label
        Label_84                        matlab.ui.control.Label
        SolveButton                     matlab.ui.control.Button
        UITable8                        matlab.ui.control.Table
        SaveButton                      matlab.ui.control.Button
        Label_86                        matlab.ui.control.Label
        ListBox_5                       matlab.ui.control.ListBox
        Label_85                        matlab.ui.control.Label
        ListBox_4                       matlab.ui.control.ListBox
        Label_71                        matlab.ui.control.Label
        Label_72                        matlab.ui.control.Label
        Label_73                        matlab.ui.control.Label
        Label_74                        matlab.ui.control.Label
        FilenameEditField_5Label        matlab.ui.control.Label
        FilenameEditField_5             matlab.ui.control.EditField
        HelpButton                      matlab.ui.control.Button
        Label_101                       matlab.ui.control.Label
        Label_103                       matlab.ui.control.Label
        Label_98                        matlab.ui.control.Label
        Label_100                       matlab.ui.control.Label
        Label_104                       matlab.ui.control.Label
        Label_105                       matlab.ui.control.Label
        Label_106                       matlab.ui.control.Label
        Lamp_9                          matlab.ui.control.Lamp
        Lamp_10                         matlab.ui.control.Lamp
        FocalLengthEditField            matlab.ui.control.EditField
        CheckBox_9                      matlab.ui.control.CheckBox
        FocalLengthEditFieldLabel_2     matlab.ui.control.Label
        Label_371                       matlab.ui.control.Label
        FocalLengthEditFieldLabel_3     matlab.ui.control.Label
        FocalLengthEditFieldLabel_4     matlab.ui.control.Label
        Label_372                       matlab.ui.control.Label
        UIAxes_6                        matlab.ui.control.UIAxes
        SCPSelectionTab                 matlab.ui.container.Tab
        AddSCPButton                    matlab.ui.control.Button
        Label_87                        matlab.ui.control.Label
        Label_88                        matlab.ui.control.Label
        Label_89                        matlab.ui.control.Label
        DeleteSCPButton                 matlab.ui.control.Button
        Label_90                        matlab.ui.control.Label
        Label_91                        matlab.ui.control.Label
        Label_92                        matlab.ui.control.Label
        Label_93                        matlab.ui.control.Label
        ListBox_6                       matlab.ui.control.ListBox
        Label_94                        matlab.ui.control.Label
        Label_95                        matlab.ui.control.Label
        Label_96                        matlab.ui.control.Label
        Label_97                        matlab.ui.control.Label
        SaveSCPsButton                  matlab.ui.control.Button
        FilenameEditField_6Label        matlab.ui.control.Label
        FilenameEditField_6             matlab.ui.control.EditField
        Label_108                       matlab.ui.control.Label
        Label_109                       matlab.ui.control.Label
        Label_110                       matlab.ui.control.Label
        Label_111                       matlab.ui.control.Label
        Label_112                       matlab.ui.control.Label
        HelpButton_2                    matlab.ui.control.Button
        Lamp_11                         matlab.ui.control.Lamp
        UIAxes_7                        matlab.ui.control.UIAxes
        SCPStabilizationTab             matlab.ui.container.Tab
        ListBox_7                       matlab.ui.control.ListBox
        Label_130                       matlab.ui.control.Label
        ListBox_8                       matlab.ui.control.ListBox
        Label_131                       matlab.ui.control.Label
        ListBox_9                       matlab.ui.control.ListBox
        Label_132                       matlab.ui.control.Label
        Label_133                       matlab.ui.control.Label
        Label_134                       matlab.ui.control.Label
        Label_135                       matlab.ui.control.Label
        Label_136                       matlab.ui.control.Label
        Label_137                       matlab.ui.control.Label
        Label_138                       matlab.ui.control.Label
        Label_139                       matlab.ui.control.Label
        SolveButton_2                   matlab.ui.control.Button
        Label_140                       matlab.ui.control.Label
        SaveButton_2                    matlab.ui.control.Button
        Label_141                       matlab.ui.control.Label
        Label_142                       matlab.ui.control.Label
        FilenameEditField_7Label        matlab.ui.control.Label
        FilenameEditField_7             matlab.ui.control.EditField
        Label_143                       matlab.ui.control.Label
        Label_129                       matlab.ui.control.Label
        UITable9                        matlab.ui.control.Table
        UITable9_2                      matlab.ui.control.Table
        Label_144                       matlab.ui.control.Label
        Label_145                       matlab.ui.control.Label
        Label_146                       matlab.ui.control.Label
        Lamp_12                         matlab.ui.control.Lamp
        Lamp_13                         matlab.ui.control.Lamp
        Label_358                       matlab.ui.control.Label
        Label_359                       matlab.ui.control.Label
        UIAxes_8                        matlab.ui.control.UIAxes
        NoDistortionStabilizationTab    matlab.ui.container.Tab
        Label_373                       matlab.ui.control.Label
        ListBox_34                      matlab.ui.control.ListBox
        Label_374                       matlab.ui.control.Label
        ListBox_35                      matlab.ui.control.ListBox
        Label_375                       matlab.ui.control.Label
        Label_376                       matlab.ui.control.Label
        SaveButton_3                    matlab.ui.control.Button
        Label_377                       matlab.ui.control.Label
        Label_378                       matlab.ui.control.Label
        FilenameEditField_7Label_2      matlab.ui.control.Label
        FilenameEditField_13            matlab.ui.control.EditField
        Label_379                       matlab.ui.control.Label
        Lamp_31                         matlab.ui.control.Lamp
        Label_380                       matlab.ui.control.Label
        Label_381                       matlab.ui.control.Label
        Label_382                       matlab.ui.control.Label
        AddROIButton                    matlab.ui.control.Button
        Label_383                       matlab.ui.control.Label
        Label_385                       matlab.ui.control.Label
        StabilizeButton                 matlab.ui.control.Button
        Lamp_32                         matlab.ui.control.Lamp
        ThresholdEditFieldLabel         matlab.ui.control.Label
        ThresholdEditField              matlab.ui.control.EditField
        Label_384                       matlab.ui.control.Label
        Lamp_33                         matlab.ui.control.Lamp
        FindPointsButton                matlab.ui.control.Button
        Label_386                       matlab.ui.control.Label
        Label_387                       matlab.ui.control.Label
        Label_388                       matlab.ui.control.Label
        Label_389                       matlab.ui.control.Label
        Label_390                       matlab.ui.control.Label
        Label_391                       matlab.ui.control.Label
        UIAxes_16                       matlab.ui.control.UIAxes
        GeorectifcationTab              matlab.ui.container.Tab
        TabGroup5                       matlab.ui.container.TabGroup
        GridGenerationTab               matlab.ui.container.Tab
        Label_147                       matlab.ui.control.Label
        ListBox_10                      matlab.ui.control.ListBox
        Label_148                       matlab.ui.control.Label
        ListBox_11                      matlab.ui.control.ListBox
        Label_149                       matlab.ui.control.Label
        Label_150                       matlab.ui.control.Label
        Label_151                       matlab.ui.control.Label
        Label_152                       matlab.ui.control.Label
        RotationSlider                  matlab.ui.control.Slider
        UITable10                       matlab.ui.control.Table
        Label_153                       matlab.ui.control.Label
        HelpButton_5                    matlab.ui.control.Button
        Label_154                       matlab.ui.control.Label
        Label_155                       matlab.ui.control.Label
        ResolutionSlider                matlab.ui.control.Slider
        HelpButton_6                    matlab.ui.control.Button
        UITable10_2                     matlab.ui.control.Table
        Label_156                       matlab.ui.control.Label
        Label_157                       matlab.ui.control.Label
        Label_158                       matlab.ui.control.Label
        Label_159                       matlab.ui.control.Label
        SaveGridButton                  matlab.ui.control.Button
        FilenameEditField_8Label        matlab.ui.control.Label
        FilenameEditField_8             matlab.ui.control.EditField
        ResolutionEditFieldLabel        matlab.ui.control.Label
        ResolutionEditField             matlab.ui.control.EditField
        AngledegEditFieldLabel          matlab.ui.control.Label
        AngledegEditField               matlab.ui.control.EditField
        ClickOriginButton               matlab.ui.control.Button
        MWLElevationEditFieldLabel      matlab.ui.control.Label
        MWLElevationEditField           matlab.ui.control.EditField
        ResetOriginButton               matlab.ui.control.Button
        Lamp_14                         matlab.ui.control.Lamp
        Label_315                       matlab.ui.control.Label
        Label_316                       matlab.ui.control.Label
        Label_352                       matlab.ui.control.Label
        Label_356                       matlab.ui.control.Label
        Label_357                       matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_9                        matlab.ui.control.UIAxes
        OrthophotoGenerationTab         matlab.ui.container.Tab
        Label_160                       matlab.ui.control.Label
        ListBox_12                      matlab.ui.control.ListBox
        Label_161                       matlab.ui.control.Label
        ListBox_13                      matlab.ui.control.ListBox
        Label_162                       matlab.ui.control.Label
        ListBox_14                      matlab.ui.control.ListBox
        Label_163                       matlab.ui.control.Label
        Label_164                       matlab.ui.control.Label
        Label_165                       matlab.ui.control.Label
        Label_166                       matlab.ui.control.Label
        Label_167                       matlab.ui.control.Label
        Label_170                       matlab.ui.control.Label
        OutputDirectoryNameEditFieldLabel  matlab.ui.control.Label
        OutputDirectoryNameEditField    matlab.ui.control.EditField
        Label_172                       matlab.ui.control.Label
        UITable3_3                      matlab.ui.control.Table
        Label_173                       matlab.ui.control.Label
        StartButton                     matlab.ui.control.Button
        Label_171                       matlab.ui.control.Label
        Label_174                       matlab.ui.control.Label
        EditField                       matlab.ui.control.NumericEditField
        Lamp_15                         matlab.ui.control.Lamp
        Label_317                       matlab.ui.control.Label
        UIAxes_10                       matlab.ui.control.UIAxes
        UIAxes_11                       matlab.ui.control.UIAxes
        OrthophotoMovieTab              matlab.ui.container.Tab
        Label_175                       matlab.ui.control.Label
        ListBox_15                      matlab.ui.control.ListBox
        Label_176                       matlab.ui.control.Label
        CoordinateSystemButtonGroup     matlab.ui.container.ButtonGroup
        LocalButton                     matlab.ui.control.RadioButton
        GeographicButton                matlab.ui.control.RadioButton
        FrameTypeButtonGroup            matlab.ui.container.ButtonGroup
        AxesButton                      matlab.ui.control.RadioButton
        NoAxesButton                    matlab.ui.control.RadioButton
        Label_177                       matlab.ui.control.Label
        Label_178                       matlab.ui.control.Label
        PlaybackSpeedListBoxLabel       matlab.ui.control.Label
        PlaybackSpeedListBox            matlab.ui.control.ListBox
        QualityAffectsMovieSizeListBoxLabel  matlab.ui.control.Label
        QualityAffectsMovieSizeListBox  matlab.ui.control.ListBox
        Label_179                       matlab.ui.control.Label
        Label_180                       matlab.ui.control.Label
        Label_181                       matlab.ui.control.Label
        FilenameEditField_9Label        matlab.ui.control.Label
        FilenameEditField_9             matlab.ui.control.EditField
        StartButton_2                   matlab.ui.control.Button
        Label_182                       matlab.ui.control.Label
        Label_183                       matlab.ui.control.Label
        Label_184                       matlab.ui.control.Label
        Label_185                       matlab.ui.control.Label
        Label_186                       matlab.ui.control.Label
        UITable3_4                      matlab.ui.control.Table
        Lamp_16                         matlab.ui.control.Lamp
        UIAxes_12                       matlab.ui.control.UIAxes
        cBathyTab                       matlab.ui.container.Tab
        TabGroup6                       matlab.ui.container.TabGroup
        TimestackGenerationTab          matlab.ui.container.Tab
        Label_187                       matlab.ui.control.Label
        ListBox_16                      matlab.ui.control.ListBox
        Label_188                       matlab.ui.control.Label
        Label_189                       matlab.ui.control.Label
        Label_190                       matlab.ui.control.Label
        Label_191                       matlab.ui.control.Label
        Label_192                       matlab.ui.control.Label
        Label_193                       matlab.ui.control.Label
        ListBox_18                      matlab.ui.control.ListBox
        Label_194                       matlab.ui.control.Label
        Label_195                       matlab.ui.control.Label
        Label_196                       matlab.ui.control.Label
        Label_197                       matlab.ui.control.Label
        Label_198                       matlab.ui.control.Label
        Label_199                       matlab.ui.control.Label
        Label_200                       matlab.ui.control.Label
        Label_201                       matlab.ui.control.Label
        SaveTimestackButton             matlab.ui.control.Button
        FilenameEditField_10Label       matlab.ui.control.Label
        FilenameEditField_10            matlab.ui.control.EditField
        BuildTimestackButton            matlab.ui.control.Button
        Label_202                       matlab.ui.control.Label
        Label_203                       matlab.ui.control.Label
        Label_204                       matlab.ui.control.Label
        Label_205                       matlab.ui.control.Label
        UITable3_5                      matlab.ui.control.Table
        HelpButton_7                    matlab.ui.control.Button
        FilenameEditField_10Label_2     matlab.ui.control.Label
        Label_206                       matlab.ui.control.Label
        Label_207                       matlab.ui.control.Label
        Label_208                       matlab.ui.control.Label
        NumberofTimestoEnhanceButtonGroup  matlab.ui.container.ButtonGroup
        Button                          matlab.ui.control.RadioButton
        Button_2                        matlab.ui.control.RadioButton
        Button_3                        matlab.ui.control.RadioButton
        Button_4                        matlab.ui.control.RadioButton
        Lamp_17                         matlab.ui.control.Lamp
        Lamp_18                         matlab.ui.control.Lamp
        UIAxes_13                       matlab.ui.control.UIAxes
        RuncBathyTab                    matlab.ui.container.Tab
        Label_209                       matlab.ui.control.Label
        ListBox_19                      matlab.ui.control.ListBox
        Label_210                       matlab.ui.control.Label
        Label_211                       matlab.ui.control.Label
        Label_212                       matlab.ui.control.Label
        Label_213                       matlab.ui.control.Label
        Label_215                       matlab.ui.control.Label
        ListBox_20                      matlab.ui.control.ListBox
        Label_218                       matlab.ui.control.Label
        ListBox_21                      matlab.ui.control.ListBox
        Label_219                       matlab.ui.control.Label
        HelpButton_9                    matlab.ui.control.Button
        Label_220                       matlab.ui.control.Label
        ListBox_22                      matlab.ui.control.ListBox
        Label_222                       matlab.ui.control.Label
        ListBox_23                      matlab.ui.control.ListBox
        Label_223                       matlab.ui.control.Label
        OffshoreSmoothingFactorButtonGroup  matlab.ui.container.ButtonGroup
        Button_5                        matlab.ui.control.RadioButton
        Button_6                        matlab.ui.control.RadioButton
        Button_7                        matlab.ui.control.RadioButton
        UITable10_3                     matlab.ui.control.Table
        Label_224                       matlab.ui.control.Label
        Label_225                       matlab.ui.control.Label
        UITable10_4                     matlab.ui.control.Table
        Label_226                       matlab.ui.control.Label
        Label_227                       matlab.ui.control.Label
        Label_228                       matlab.ui.control.Label
        Label_229                       matlab.ui.control.Label
        Label_230                       matlab.ui.control.Label
        StartcBathyButton               matlab.ui.control.Button
        Label_231                       matlab.ui.control.Label
        Label_232                       matlab.ui.control.Label
        Label_234                       matlab.ui.control.Label
        SaveBathymetryButton            matlab.ui.control.Button
        Label_236                       matlab.ui.control.Label
        FilenameEditField_10Label_3     matlab.ui.control.Label
        FilenameEditField_11Label       matlab.ui.control.Label
        FilenameEditField_11            matlab.ui.control.EditField
        Label_238                       matlab.ui.control.Label
        Label_239                       matlab.ui.control.Label
        Label_241                       matlab.ui.control.Label
        Label_240                       matlab.ui.control.Label
        MinimumPeriodsEditFieldLabel    matlab.ui.control.Label
        MinimumPeriodsEditField         matlab.ui.control.NumericEditField
        MaximumPeriodsEditFieldLabel    matlab.ui.control.Label
        MaximumPeriodsEditField         matlab.ui.control.NumericEditField
        NumberofFrequenciesEditFieldLabel  matlab.ui.control.Label
        NumberofFrequenciesEditField    matlab.ui.control.NumericEditField
        Lamp_19                         matlab.ui.control.Lamp
        Lamp_20                         matlab.ui.control.Lamp
        UIAxes_14                       matlab.ui.control.UIAxes
        SolutionExplorationTab          matlab.ui.container.Tab
        Label_242                       matlab.ui.control.Label
        ListBox_24                      matlab.ui.control.ListBox
        Label_243                       matlab.ui.control.Label
        Label_244                       matlab.ui.control.Label
        Label_245                       matlab.ui.control.Label
        Label_246                       matlab.ui.control.Label
        Label_251                       matlab.ui.control.Label
        ChooseImagerytoOverlayBathymetryOntopofButtonGroup  matlab.ui.container.ButtonGroup
        NoneButton                      matlab.ui.control.RadioButton
        SnapshotButton                  matlab.ui.control.RadioButton
        BrightestButton                 matlab.ui.control.RadioButton
        TimexButton                     matlab.ui.control.RadioButton
        OverlaySliderLabel              matlab.ui.control.Label
        OverlaySlider                   matlab.ui.control.Slider
        DepthSliderLabel                matlab.ui.control.Label
        DepthSlider                     matlab.ui.control.Slider
        DepthSlider_2Label              matlab.ui.control.Label
        DepthSlider_2                   matlab.ui.control.Slider
        OverlaySliderLabel_2            matlab.ui.control.Label
        OverlaySliderLabel_3            matlab.ui.control.Label
        OverlaySliderLabel_4            matlab.ui.control.Label
        OverlaySlider_2Label            matlab.ui.control.Label
        OverlaySlider_2                 matlab.ui.control.Slider
        ErrorSliderLabel                matlab.ui.control.Label
        ErrorSlider                     matlab.ui.control.Slider
        ErrorSlider_2Label              matlab.ui.control.Label
        ErrorSlider_2                   matlab.ui.control.Slider
        OverlaySliderLabel_5            matlab.ui.control.Label
        OverlaySliderLabel_6            matlab.ui.control.Label
        OverlaySliderLabel_7            matlab.ui.control.Label
        Label_253                       matlab.ui.control.Label
        Label_254                       matlab.ui.control.Label
        Label_255                       matlab.ui.control.Label
        EnterYCoordinatetoPlotCrossshoreprofilebelowEditFieldLabel  matlab.ui.control.Label
        EnterYCoordinatetoPlotCrossshoreprofilebelowEditField  matlab.ui.control.NumericEditField
        Label_318                       matlab.ui.control.Label
        Label_319                       matlab.ui.control.Label
        Label_320                       matlab.ui.control.Label
        Label_321                       matlab.ui.control.Label
        Label_322                       matlab.ui.control.Label
        Label_323                       matlab.ui.control.Label
        Label_324                       matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes2_2                       matlab.ui.control.UIAxes
        UIAxes5                         matlab.ui.control.UIAxes
        ExportBathymetryTab_2           matlab.ui.container.Tab
        Label_256                       matlab.ui.control.Label
        ListBox_27                      matlab.ui.control.ListBox
        Label_257                       matlab.ui.control.Label
        Label_258                       matlab.ui.control.Label
        Label_259                       matlab.ui.control.Label
        Label_260                       matlab.ui.control.Label
        HelpButton_12                   matlab.ui.control.Button
        MaximumErrorEditFieldLabel      matlab.ui.control.Label
        MaximumErrorEditField           matlab.ui.control.NumericEditField
        Slider                          matlab.ui.control.Slider
        XSmoothingDistanceEditFieldLabel  matlab.ui.control.Label
        XSmoothingDistanceEditField     matlab.ui.control.NumericEditField
        Slider_2                        matlab.ui.control.Slider
        Slider_3                        matlab.ui.control.Slider
        YSmoothingDistanceEditFieldLabel  matlab.ui.control.Label
        YSmoothingDistanceEditField     matlab.ui.control.NumericEditField
        Label_261                       matlab.ui.control.Label
        Label_262                       matlab.ui.control.Label
        Label_263                       matlab.ui.control.Label
        Label_264                       matlab.ui.control.Label
        FoldernameEditFieldLabel        matlab.ui.control.Label
        FoldernameEditField             matlab.ui.control.EditField
        ChooseImagerytoOverlayOntopofButtonGroup_3  matlab.ui.container.ButtonGroup
        NoneButton_3                    matlab.ui.control.RadioButton
        SnapshotButton_3                matlab.ui.control.RadioButton
        BrightestButton_3               matlab.ui.control.RadioButton
        TimexButton_3                   matlab.ui.control.RadioButton
        ExportOutputButton              matlab.ui.control.Button
        Label_265                       matlab.ui.control.Label
        OverlaySlider_3Label            matlab.ui.control.Label
        OverlaySlider_3                 matlab.ui.control.Slider
        OverlaySlider_3Label_2          matlab.ui.control.Label
        UITable10_5                     matlab.ui.control.Table
        Label_266                       matlab.ui.control.Label
        Label_267                       matlab.ui.control.Label
        EnterYCoordinatetoPlotCrossshoreprofilebelowEditFieldLabel_2  matlab.ui.control.Label
        EnterYCoordinatetoPlotCrossshoreprofilebelowEditField_2  matlab.ui.control.NumericEditField
        Lamp_21                         matlab.ui.control.Lamp
        Label_325                       matlab.ui.control.Label
        Label_327                       matlab.ui.control.Label
        Label_328                       matlab.ui.control.Label
        Label_329                       matlab.ui.control.Label
        Label_330                       matlab.ui.control.Label
        Label_331                       matlab.ui.control.Label
        Label_332                       matlab.ui.control.Label
        Label_333                       matlab.ui.control.Label
        UIAxes2_3                       matlab.ui.control.UIAxes
        UIAxes2_4                       matlab.ui.control.UIAxes
        UIAxes6                         matlab.ui.control.UIAxes
        ParametricBeachToolTab          matlab.ui.container.Tab
        TabGroup7                       matlab.ui.container.TabGroup
        RunPBToolTab                    matlab.ui.container.Tab
        Label_268                       matlab.ui.control.Label
        ListBox_28                      matlab.ui.control.ListBox
        Label_269                       matlab.ui.control.Label
        ListBox_29                      matlab.ui.control.ListBox
        Label_270                       matlab.ui.control.Label
        Label_271                       matlab.ui.control.Label
        Label_272                       matlab.ui.control.Label
        Label_273                       matlab.ui.control.Label
        HelpButton_13                   matlab.ui.control.Button
        StartClickingShorelineButton    matlab.ui.control.Button
        StopClickingShorelineButton     matlab.ui.control.Button
        Label_274                       matlab.ui.control.Label
        ResetShorelineButton            matlab.ui.control.Button
        StartClickingSandbarButton      matlab.ui.control.Button
        StopClickingSandbarButton       matlab.ui.control.Button
        Label_275                       matlab.ui.control.Label
        ResetSandbarButton              matlab.ui.control.Button
        Label_276                       matlab.ui.control.Label
        Label_277                       matlab.ui.control.Label
        Label_278                       matlab.ui.control.Label
        Label_279                       matlab.ui.control.Label
        HelpButton_14                   matlab.ui.control.Button
        UITable11                       matlab.ui.control.Table
        Label_281                       matlab.ui.control.Label
        Label_282                       matlab.ui.control.Label
        OverlaySliderLabel_8            matlab.ui.control.Label
        cBathySliderLabel               matlab.ui.control.Label
        cBathySlider                    matlab.ui.control.Slider
        Label_283                       matlab.ui.control.Label
        Label_284                       matlab.ui.control.Label
        Label_285                       matlab.ui.control.Label
        StartPBToolButton               matlab.ui.control.Button
        Label_286                       matlab.ui.control.Label
        Label_288                       matlab.ui.control.Label
        Label_289                       matlab.ui.control.Label
        SaveBathymetryButton_2          matlab.ui.control.Button
        Label_290                       matlab.ui.control.Label
        FilenameEditField_10Label_4     matlab.ui.control.Label
        FilenameEditField_12Label       matlab.ui.control.Label
        FilenameEditField_12            matlab.ui.control.EditField
        Label_291                       matlab.ui.control.Label
        Lamp_22                         matlab.ui.control.Lamp
        Lamp_23                         matlab.ui.control.Lamp
        Lamp_25                         matlab.ui.control.Lamp
        Lamp_26                         matlab.ui.control.Lamp
        Label_336                       matlab.ui.control.Label
        Label_353                       matlab.ui.control.Label
        Label_354                       matlab.ui.control.Label
        Label_355                       matlab.ui.control.Label
        UITable12                       matlab.ui.control.Table
        Label_361                       matlab.ui.control.Label
        EditField_5                     matlab.ui.control.NumericEditField
        EditField_6                     matlab.ui.control.NumericEditField
        YminLabel                       matlab.ui.control.Label
        YmaxLabel                       matlab.ui.control.Label
        ExtrapolateFeaturesButton       matlab.ui.control.Button
        EditField_2                     matlab.ui.control.NumericEditField
        EditField_4                     matlab.ui.control.NumericEditField
        Label_334                       matlab.ui.control.Label
        Label_335                       matlab.ui.control.Label
        Label_362                       matlab.ui.control.Label
        OffshoreXCoordinateLabel        matlab.ui.control.Label
        BarSeawardXCoordinateLabel      matlab.ui.control.Label
        Lamp_28                         matlab.ui.control.Lamp
        Label_363                       matlab.ui.control.Label
        cBathyEstimationButton          matlab.ui.control.Button
        Lamp_24                         matlab.ui.control.Lamp
        UIAxes_15                       matlab.ui.control.UIAxes
        SolutionExplorationTab_2        matlab.ui.container.Tab
        Label_292                       matlab.ui.control.Label
        ListBox_30                      matlab.ui.control.ListBox
        Label_293                       matlab.ui.control.Label
        Label_294                       matlab.ui.control.Label
        Label_295                       matlab.ui.control.Label
        Label_296                       matlab.ui.control.Label
        Label_297                       matlab.ui.control.Label
        ChooseImagerytoOverlayBathymetryOntopofButtonGroup_2  matlab.ui.container.ButtonGroup
        NoneButton_4                    matlab.ui.control.RadioButton
        SnapshotButton_4                matlab.ui.control.RadioButton
        BrightestButton_4               matlab.ui.control.RadioButton
        TimexButton_4                   matlab.ui.control.RadioButton
        OverlaySliderLabel_9            matlab.ui.control.Label
        OverlaySliderLabel_10           matlab.ui.control.Label
        OverlaySliderLabel_11           matlab.ui.control.Label
        OverlaySliderLabel_12           matlab.ui.control.Label
        OverlaySliderLabel_13           matlab.ui.control.Label
        Label_298                       matlab.ui.control.Label
        Label_299                       matlab.ui.control.Label
        Label_300                       matlab.ui.control.Label
        OverlaySlider_4Label            matlab.ui.control.Label
        OverlaySlider_4                 matlab.ui.control.Slider
        DepthSlider_3Label              matlab.ui.control.Label
        DepthSlider_3                   matlab.ui.control.Slider
        DepthSlider_4Label              matlab.ui.control.Label
        DepthSlider_4                   matlab.ui.control.Slider
        OverlaySlider_5Label            matlab.ui.control.Label
        OverlaySlider_5                 matlab.ui.control.Slider
        DepthSlider_5Label              matlab.ui.control.Label
        DepthSlider_5                   matlab.ui.control.Slider
        DepthSlider_6Label              matlab.ui.control.Label
        DepthSlider_6                   matlab.ui.control.Slider
        EnterYCoordinatetoPlotCrossshoreprofilebelowEditField_3Label  matlab.ui.control.Label
        EnterYCoordinatetoPlotCrossshoreprofilebelowEditField_3  matlab.ui.control.NumericEditField
        Label_301                       matlab.ui.control.Label
        Label_337                       matlab.ui.control.Label
        ListBox_31                      matlab.ui.control.ListBox
        Label_339                       matlab.ui.control.Label
        Label_340                       matlab.ui.control.Label
        Label_341                       matlab.ui.control.Label
        Label_342                       matlab.ui.control.Label
        Label_343                       matlab.ui.control.Label
        Label_338                       matlab.ui.control.Label
        MinLabel                        matlab.ui.control.Label
        UIAxes2_5                       matlab.ui.control.UIAxes
        UIAxes2_6                       matlab.ui.control.UIAxes
        UIAxes5_2                       matlab.ui.control.UIAxes
        ExportBathymetryTab             matlab.ui.container.Tab
        Label_302                       matlab.ui.control.Label
        ListBox_32                      matlab.ui.control.ListBox
        Label_303                       matlab.ui.control.Label
        Label_304                       matlab.ui.control.Label
        Label_305                       matlab.ui.control.Label
        Label_306                       matlab.ui.control.Label
        HelpButton_16                   matlab.ui.control.Button
        Label_307                       matlab.ui.control.Label
        Label_308                       matlab.ui.control.Label
        Label_309                       matlab.ui.control.Label
        Label_310                       matlab.ui.control.Label
        ChooseImagerytoOverlayOntopofButtonGroup_4  matlab.ui.container.ButtonGroup
        NoneButton_5                    matlab.ui.control.RadioButton
        SnapshotButton_5                matlab.ui.control.RadioButton
        BrightestButton_5               matlab.ui.control.RadioButton
        TimexButton_5                   matlab.ui.control.RadioButton
        ExportOutputButton_2            matlab.ui.control.Button
        Label_311                       matlab.ui.control.Label
        OverlaySlider_3Label_3          matlab.ui.control.Label
        UITable10_6                     matlab.ui.control.Table
        Label_312                       matlab.ui.control.Label
        Label_313                       matlab.ui.control.Label
        EnterYCoordinatetoPlotCrossshoreprofilebelowEditFieldLabel_3  matlab.ui.control.Label
        EnterYCoordinatetoPlotCrossshoreprofilebelowEditField_4  matlab.ui.control.NumericEditField
        XSmoothingDistanceEditField_3Label  matlab.ui.control.Label
        XSmoothingDistanceEditField_3   matlab.ui.control.NumericEditField
        YSmoothingDistanceEditField_3Label  matlab.ui.control.Label
        YSmoothingDistanceEditField_3   matlab.ui.control.NumericEditField
        PBToolEditFieldLabel            matlab.ui.control.Label
        PBToolEditField                 matlab.ui.control.NumericEditField
        FoldernameEditField_2Label      matlab.ui.control.Label
        FoldernameEditField_2           matlab.ui.control.EditField
        OverlaySlider_6Label            matlab.ui.control.Label
        OverlaySlider_6                 matlab.ui.control.Slider
        Label_314                       matlab.ui.control.Label
        ListBox_33                      matlab.ui.control.ListBox
        XSmoothingDistanceEditField_3Label_2  matlab.ui.control.Label
        XSmoothingDistanceEditField_3Label_3  matlab.ui.control.Label
        YSmoothingDistanceEditField_3Label_2  matlab.ui.control.Label
        Lamp_27                         matlab.ui.control.Lamp
        Label_344                       matlab.ui.control.Label
        Label_346                       matlab.ui.control.Label
        Label_349                       matlab.ui.control.Label
        Label_350                       matlab.ui.control.Label
        Label_351                       matlab.ui.control.Label
        YSmoothingDistanceEditField_3Label_3  matlab.ui.control.Label
        Label_364                       matlab.ui.control.Label
        cBathyEditFieldLabel            matlab.ui.control.Label
        cBathyEditField                 matlab.ui.control.NumericEditField
        Label_365                       matlab.ui.control.Label
        Label_366                       matlab.ui.control.Label
        BlendButton                     matlab.ui.control.Button
        Lamp_29                         matlab.ui.control.Lamp
        ResetButton                     matlab.ui.control.Button
        UIAxes2_8                       matlab.ui.control.UIAxes
        UIAxes6_2                       matlab.ui.control.UIAxes
        UIAxes2_9                       matlab.ui.control.UIAxes
        UIAxes2_10                      matlab.ui.control.UIAxes
        Image                           matlab.ui.control.Image
        NewProjectLabel                 matlab.ui.control.Label
    end


    methods (Access = private)
        
    end
 

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        echo on;
         
         % In Select Project Tab, Add Default Image
         addpath(genpath('cBathy'),genpath('CIRN_CoreFunctions'),genpath('guiParts'),genpath('parametric_beach_tool'),genpath('merge_data'))
         imshow(imread('lby_use.png'),'Parent',app.UIAxes_2) % Show Example Frame  
        end

        % Button pushed function: OpenExistingProjectButton
        function OpenExistingProjectButtonPushed(app, event)
            global pdir idir iedir vedir pidir gdir grdir mdir pname fdir
            loadProject(app)
        end

        % Button pushed function: CreateNewProjectButton
        function CreateNewProjectButtonPushed(app, event)
            global pdir idir iedir vedir pidir gdir grdir mdir pname fdir
            newProject(app)
        end

        % Button pushed function: LoadVideosButton
        function LoadVideosButtonPushed(app, event)
            global mdir 
            loadVideos(app)
        end

        % Selection change function: TabGroup2
        function TabGroup2SelectionChanged(app, event)
           % Projection Intiation Tab
            global mdir
            selectedTab = app.TabGroup2.SelectedTab;
 
            % Generate Frames Tab
            if strcmp(selectedTab.Title, 'Generate Frames')==1
                generateFramesStartup(app)
            end      
            
            % Intrinsics Tab
            if strcmp(selectedTab.Title, 'Load Intrinsics')==1
                loadIntrinsicsStartup(app)
            end  
            
        end

        % Button pushed function: ExtractFramesButton
        function ExtractFramesButtonPushed(app, event)
            % Function for extracting Frames
            global mdir fdir
            extractFramesButton(app)
            app.Lamp_4.Color=[.5 .5 .5];
        end

        % Button pushed function: LoadIntrinsicsButton
        function LoadIntrinsicsButtonPushed(app, event)
            loadIntrinsics(app)
            clearLoadIntrinsicsTab(app)
        end

        % Button pushed function: SaveIntrinsicsButton
        function SaveIntrinsicsButtonPushed(app, event)
            global idir
            saveIntrinsics(app)
        end

        % Selection change function: TabGroup3
        function TabGroup3SelectionChanged(app, event)
            global fdir
            % GCP Tab
            selectedTab = app.TabGroup3.SelectedTab;
           if strcmp(selectedTab.Title, 'Assign GCP Coordinates')==1
                assignGCPstartup(app)
           end 
           
           if strcmp(selectedTab.Title, 'Click GCPs')==1
                clickGCPsStartup(app)
            end   


        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            global fdir idir
            selectedTab = app.TabGroup.SelectedTab;
             % GCPs
            if strcmp(selectedTab.Title, 'GCPs')==1
                clickGCPsStartup(app)
            end   
            
             % Extrinsics
            if strcmp(selectedTab.Title, 'Extrinsics')==1
                initialExtrinsicsStartup(app)
            end   
            
            if strcmp(selectedTab.Title, 'Georectifcation')==1
                initialGeorectificationStartup(app)          
            end   
            
             if strcmp(selectedTab.Title, 'cBathy')==1
                clickCbathyStartup(app)
             end
             
              if strcmp(selectedTab.Title, 'Parametric Beach Tool')==1
                runPBToolStartup(app)
               end
            
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            global fdir
            clickGCPsFrameChoose(app)
        end

        % Button pushed function: AddGCPButton_2
        function AddGCPButton_2Pushed(app, event)
            global addGCPValue
            addGCPValue=1;
            app.Lamp_7.Color=[.5 .5 .5];
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            global addGCPValue UVdClick  deleteGCPValue addSCPValue deleteSCPValue originValue periodValue pbShoreValue pbBarValue
           
               if addGCPValue==1
                addGCP(app)
               end
           
               if deleteGCPValue==1
                deleteGCP(app)
               end  
               
               if addSCPValue==1
                addSCP(app)
               end
               
               if deleteSCPValue==1
                deleteSCP(app)
               end
               
               if originValue==1
                   chngOrigin(app)
               end
               
               if pbShoreValue==1
                   runPBToolclickShoreline(app)
               end
               
               if pbBarValue==1
               runPBToolclickBar(app)
               end

        end

        % Button pushed function: DeleteGCPButton
        function DeleteGCPButtonPushed(app, event)
            global deleteGCPValue
            deleteGCPValue=1;
            app.Lamp_7.Color=[.5 .5 .5];
        end

        % Button pushed function: SaveGCPsButton
        function SaveGCPsButtonPushed(app, event)
            global UVdClick gcudir
             saveGCP(app)
        end

        % Value changed function: ListBox_2
        function ListBox_2ValueChanged(app, event)
            global fdir gcudir
            clearGCPXYZTab(app)
            clickGCPsUVdChoose(app)
            
        end

        % Button pushed function: SaveGCPCoordinatesButton
        function SaveGCPCoordinatesButtonPushed(app, event)
            global gcudir  gcuxdir
            saveGCPXYZ(app)
        end

        % Value changed function: ListBox_3
        function ListBox_3ValueChanged(app, event)
            value = app.ListBox_3.Value;
            global fdir gcuxdir
            gcpXYZChoose(app)
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % Function to save the EOIO Solution
            global iedir twoSave_initialIOEO
            saveInitialExtrinsics(app)
        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
            % Function to solve the extrinsics.
            global gcuxdir twoSave_initialIOEO 
            gcpSolveButton(app)
            
        end

        % Value changed function: ListBox_5
        function ListBox_5ValueChanged(app, event)
            value = app.ListBox_5.Value;
            global fdir gcuxdir
            extrinsicsFrameSetChoose(app)
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            help_extrinsics(app)
        end

        % Callback function
        function AddSCPButtonPushed(app, event)
          
        end

        % Selection change function: TabGroup4
        function TabGroup4SelectionChanged(app, event)
            selectedTab = app.TabGroup4.SelectedTab;
            %% Extrinsics Tab Changeing between
            global fdir addSCPValue UVdClick
            if strcmp(selectedTab.Title, 'SCP Selection')==1
                clickSCPsStartup(app)
            end
            if strcmp(selectedTab.Title, 'SCP Stabilization')==1
                global fdir
                stabilizationStartup(app)
            end
            
           if strcmp(selectedTab.Title, 'No Distortion Stabilization')==1
                global fdir
                noDistStabilizationStartup(app)
            end
            
             % Extrinsics
            if strcmp(selectedTab.Title, 'Initial Solution')==1
                initialExtrinsicsStartup(app)
            end   


        end

        % Value changed function: ListBox_6
        function ListBox_6ValueChanged(app, event)
            value = app.ListBox_6.Value;
            % choose frames for SCP slection
            clickSCPsFrameChoose(app)
        end

        % Button pushed function: AddSCPButton
        function AddSCPButtonPushed2(app, event)
            %% Change Lamp Color
            app.Lamp_11.Color=[.5 .5 .5];
            global addSCPValue
            addSCPValue=1;
        end

        % Button pushed function: DeleteSCPButton
        function DeleteSCPButtonPushed(app, event)
            %% Change Lamp Color
            app.Lamp_11.Color=[.5 .5 .5];
            global deleteSCPValue
            deleteSCPValue=1;
        end

        % Button pushed function: SaveSCPsButton
        function SaveSCPsButtonPushed(app, event)
            global scudir UVdClick
            saveSCP(app)
        end

        % Value changed function: ListBox_8
        function ListBox_8ValueChanged(app, event)
            value = app.ListBox_8.Value;
            % Select Frameset for stabilization tab
            stabilizationFrameChoose(app)
        end

        % Value changed function: ListBox_9
        function ListBox_9ValueChanged(app, event)
            value = app.ListBox_9.Value;
            stabilizationInitialSolChoose(app)
            
            
        end

        % Button pushed function: SolveButton_2
        function SolveButton_2Pushed(app, event)
            stabilizationSolve(app)
        end

        % Value changed function: ListBox_7
        function ListBox_7ValueChanged(app, event)
            value = app.ListBox_7.Value;
            stabilizationSCPchoose(app)
        end

        % Button pushed function: SaveButton_2
        function SaveButton_2Pushed(app, event)
            stabilizationSaveButton(app)
        end

        % Value changed function: ListBox_10
        function ListBox_10ValueChanged(app, event)
            value = app.ListBox_10.Value;
            gridGenerationFrameChoose(app)
        end

        % Value changed function: ListBox_11
        function ListBox_11ValueChanged(app, event)
            value = app.ListBox_11.Value;
            gridGenerationSolutionChoose(app)
        end

        % Value changed function: AngledegEditField
        function AngledegEditFieldValueChanged(app, event)
            value = app.AngledegEditField.Value;
            app.RotationSlider.Value=str2num(app.AngledegEditField.Value);
            replotGridOrtho(app)
        end

        % Value changed function: RotationSlider
        function RotationSliderValueChanged(app, event)
            value = app.RotationSlider.Value;
            app.AngledegEditField.Value=num2str(app.RotationSlider.Value);
            replotGridOrtho(app)
        end

        % Cell edit callback: UITable10_2
        function UITable10_2CellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            replotGridOrtho(app)
        end

        % Value changed function: MWLElevationEditField
        function MWLElevationEditFieldValueChanged(app, event)
            value = app.MWLElevationEditField.Value;
            replotGridOrtho(app)
        end

        % Value changed function: ResolutionEditField
        function ResolutionEditFieldValueChanged(app, event)
            value = app.ResolutionEditField.Value;
            
            app.ResolutionSlider.Value=str2num(app.ResolutionEditField.Value);
            replotGridOrtho(app)
        end

        % Value changed function: ResolutionSlider
        function ResolutionSliderValueChanged(app, event)
            value = app.ResolutionSlider.Value;
             
            app.ResolutionEditField.Value=num2str(app.ResolutionSlider.Value);
            replotGridOrtho(app)
        end

        % Button pushed function: SaveGridButton
        function SaveGridButtonPushed(app, event)
            gridGenerationSave(app)
        end

        % Button pushed function: ClickOriginButton
        function ClickOriginButtonPushed(app, event)
            global originValue
            originValue=1;
        end

        % Button pushed function: ResetOriginButton
        function ResetOriginButtonPushed(app, event)
            global o_origin
            app.UITable10.Data{1,1}=o_origin(1);
            app.UITable10.Data{1,2}=o_origin(2);
            replotGridOrtho(app)
        end

        % Cell edit callback: UITable10
        function UITable10CellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            replotGridOrtho(app)
        end

        % Selection change function: TabGroup5
        function TabGroup5SelectionChanged(app, event)
            selectedTab = app.TabGroup5.SelectedTab;
            if strcmp(selectedTab.Title, 'Orthophoto Generation')==1
                orthophotoGenerationStartup(app)
            end 
            if strcmp(selectedTab.Title, 'Orthophoto Movie')==1
                orthophotoMovieStartup(app)
            end 


            if strcmp(selectedTab.Title, 'Grid Generation')==1
                initialGeorectificationStartup(app)          
            end   
            
             
        end

        % Value changed function: ListBox_13
        function ListBox_13ValueChanged(app, event)
            value = app.ListBox_13.Value;
            orthoGenFrameChoose2(app)
        end

        % Value changed function: ListBox_14
        function ListBox_14ValueChanged(app, event)
            value = app.ListBox_14.Value;
            orthoGenSolutionChoose(app)
        end

        % Value changed function: ListBox_12
        function ListBox_12ValueChanged(app, event)
            value = app.ListBox_12.Value;
            orthoGenSelectGrid(app)
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % function to start ortho processing
            orthoStartButton(app)
        end

        % Value changed function: ListBox_15
        function ListBox_15ValueChanged(app, event)
            value = app.ListBox_15.Value;
            orthoMovieOrthoSetChoose(app)
        end

        % Selection changed function: CoordinateSystemButtonGroup
        function CoordinateSystemButtonGroupSelectionChanged(app, event)
            selectedButton = app.CoordinateSystemButtonGroup.SelectedObject;
            orthoMovieTypeSelect(app)
        end

        % Selection changed function: FrameTypeButtonGroup
        function FrameTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.FrameTypeButtonGroup.SelectedObject;
            orthoMovieTypeSelect(app)
        end

        % Value changed function: PlaybackSpeedListBox
        function PlaybackSpeedListBoxValueChanged(app, event)
            
            orthoMovieReset(app)
            orthoMovieChngFname(app)
        end

        % Button pushed function: StartButton_2
        function StartButton_2Pushed(app, event)
             orthoMovieStartButton(app)
        end

        % Value changed function: ListBox_16
        function ListBox_16ValueChanged(app, event)
            value = app.ListBox_16.Value;
            timestackOrthoSelect(app)
        end

        % Value changed function: ListBox_18
        function ListBox_18ValueChanged(app, event)
            value = app.ListBox_18.Value;
             timestackPlotRes(app)
             timestackReset(app)
        end

        % Button pushed function: BuildTimestackButton
        function BuildTimestackButtonPushed(app, event)
             app.Lamp_17.Color=[1 0 0];
            timestackBuildTimestack(app)
        end

        % Selection changed function: 
        % NumberofTimestoEnhanceButtonGroup
        function NumberofTimestoEnhanceButtonGroupSelectionChanged(app, event)
            selectedButton = app.NumberofTimestoEnhanceButtonGroup.SelectedObject;
            timestackPlotRes(app)
            timestackReset(app)
        end

        % Button pushed function: SaveTimestackButton
        function SaveTimestackButtonPushed(app, event)
                         app.Lamp_18.Color=[1 0 0];

            timestackSave(app)
        end

        % Selection change function: TabGroup6
        function TabGroup6SelectionChanged(app, event)
            selectedTab = app.TabGroup6.SelectedTab;
            if strcmp(selectedTab.Title, 'Run cBathy')==1
                runCbathyStartup(app)
            end
            if strcmp(selectedTab.Title, 'Solution Exploration')==1
                solutionExplorationStartup(app)
            end
            
            if strcmp(selectedTab.Title, 'Export Bathymetry')==1
                exportBathymetryStartup(app)
            end
            
            if strcmp(selectedTab.Title, 'Timestack Generation')==1
                clickCbathyStartup(app)
             end


        end

        % Value changed function: ListBox_19
        function ListBox_19ValueChanged(app, event)
            value = app.ListBox_19.Value;
            runCbathyreset(app)
            runCbathyTSChoose(app)
        end

        % Value changed function: ListBox_20
        function ListBox_20ValueChanged(app, event)
            runCbathyreset(app)
            value = app.ListBox_20.Value;
            mplier2=[2 3 4];
        for k=1:length(mplier2)
          app.ListBox_22.Items{k}= num2str(str2num(app.ListBox_20.Value).*mplier2(k));
        end
        app.ListBox_22.Value=app.ListBox_22.Items{2};

            cbathyRunReplot(app)
        end

        % Value changed function: ListBox_21
        function ListBox_21ValueChanged(app, event)
            runCbathyreset(app)
            value = app.ListBox_21.Value;
            mplier2=[2 3 4];
              for k=1:length(mplier2)
          app.ListBox_23.Items{k}= num2str(str2num(app.ListBox_21.Value).*mplier2(k));
              end
              
              app.ListBox_23.Value=app.ListBox_23.Items{2};
            cbathyRunReplot(app)
        end

        % Value changed function: ListBox_22
        function ListBox_22ValueChanged(app, event)
            runCbathyreset(app)
            value = app.ListBox_22.Value;
            cbathyRunReplot(app)
        end

        % Value changed function: ListBox_23
        function ListBox_23ValueChanged(app, event)
            runCbathyreset(app)
            value = app.ListBox_23.Value;
          
            cbathyRunReplot(app)
        end

        % Selection changed function: 
        % OffshoreSmoothingFactorButtonGroup
        function OffshoreSmoothingFactorButtonGroupSelectionChanged(app, event)
            runCbathyreset(app)
            selectedButton = app.OffshoreSmoothingFactorButtonGroup.SelectedObject;
            cbathyRunReplot(app)
        end

        % Cell edit callback: UITable10_3
        function UITable10_3CellEdit(app, event)
            runCbathyreset(app)
            indices = event.Indices;
            newData = event.NewData;
            cbathyRunReplot(app)
        end

        % Cell edit callback: UITable10_4
        function UITable10_4CellEdit(app, event)
            runCbathyreset(app)
            indices = event.Indices;
            newData = event.NewData;
            cbathyRunReplot(app)
        end

        % Callback function
        function ChooseOffshorePointButtonPushed(app, event)
             global periodValue
            periodValue=1;
        end

        % Button pushed function: StartcBathyButton
        function StartcBathyButtonPushed(app, event)
             app.Lamp_19.Color=[1 0 0];
            runCbathyreset(app)
            startcBathyButton(app)
        end

        % Button pushed function: SaveBathymetryButton
        function SaveBathymetryButtonPushed(app, event)
            saveCbathyResults(app)
        end

        % Value changed function: ListBox_24
        function ListBox_24ValueChanged(app, event)
            value = app.ListBox_24.Value;
            solutionExplorationChooseCB(app)
        end

        % Callback function
        function ListBox_25ValueChanged(app, event)
            value = app.ListBox_25.Value;
            solutionExplorationPanel1List(app)
        end

        % Value changed function: OverlaySlider
        function OverlaySliderValueChanged(app, event)
            value = app.OverlaySlider.Value;
            SolutionExplorationOverlayTran1(app)
        end

        % Value changed function: DepthSlider
        function DepthSliderValueChanged(app, event)
            value = app.DepthSlider.Value;
            SolutionExplorationColorbarMin1(app)
        end

        % Value changed function: DepthSlider_2
        function DepthSlider_2ValueChanged(app, event)
            value = app.DepthSlider_2.Value;
            SolutionExplorationColorbarMax1(app)
        end

        % Selection changed function: 
        % ChooseImagerytoOverlayBathymetryOntopofButtonGroup
        function ChooseImagerytoOverlayBathymetryOntopofButtonGroupSelectionChanged(app, event)
            selectedButton = app.ChooseImagerytoOverlayBathymetryOntopofButtonGroup.SelectedObject;
            solutionExplorationImageChoose1(app)
        end

        % Value changed function: ErrorSlider_2
        function ErrorSlider_2ValueChanged(app, event)
            value = app.ErrorSlider_2.Value;
            SolutionExplorationColorbarMax2(app)
        end

        % Value changed function: ErrorSlider
        function ErrorSliderValueChanged(app, event)
            value = app.ErrorSlider.Value;
            SolutionExplorationColorbarMin2(app)
        end

        % Callback function
        function ChooseImagerytoOverlayOntopofButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.ChooseImagerytoOverlayOntopofButtonGroup_2.SelectedObject;
            solutionExplorationImageChoose2(app)
        end

        % Value changed function: OverlaySlider_2
        function OverlaySlider_2ValueChanged(app, event)
            value = app.OverlaySlider_2.Value;
            SolutionExplorationOverlayTran2(app)
        end

        % Callback function
        function ListBox_26ValueChanged(app, event)
            value = app.ListBox_26.Value;
            solutionExplorationPanel2List(app)
        end

        % Value changed function: ListBox_27
        function ListBox_27ValueChanged(app, event)
            value = app.ListBox_27.Value;
            exportBathymetryChooseCB(app)
        end

        % Value changed function: OverlaySlider_3
        function OverlaySlider_3ValueChanged(app, event)
            value = app.OverlaySlider_3.Value;
            exportBathymetryOverlaySlider(app)
        end

        % Selection changed function: 
        % ChooseImagerytoOverlayOntopofButtonGroup_3
        function ChooseImagerytoOverlayOntopofButtonGroup_3SelectionChanged(app, event)
            selectedButton = app.ChooseImagerytoOverlayOntopofButtonGroup_3.SelectedObject;
            exportBathymetryImageChoose(app)
        end

        % Cell edit callback: UITable10_5
        function UITable10_5CellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            exportBathymetryClim(app)
        end

        % Value changed function: MaximumErrorEditField
        function MaximumErrorEditFieldValueChanged(app, event)
            value = app.MaximumErrorEditField.Value;

            app.Slider.Value=app.MaximumErrorEditField.Value;
            exportBathymetryReplot(app)
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
               app.MaximumErrorEditField.Value=app.Slider.Value;
                exportBathymetryReplot(app)
        end

        % Value changed function: XSmoothingDistanceEditField
        function XSmoothingDistanceEditFieldValueChanged(app, event)
            value = app.XSmoothingDistanceEditField.Value;
                        app.Slider_2.Value=app.XSmoothingDistanceEditField.Value;

            exportBathymetryReplot(app)
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            value = app.Slider_2.Value;
            app.XSmoothingDistanceEditField.Value=app.Slider_2.Value;
            exportBathymetryReplot(app)
            
            
        end

        % Value changed function: YSmoothingDistanceEditField
        function YSmoothingDistanceEditFieldValueChanged(app, event)
            app.Slider_3.Value= app.YSmoothingDistanceEditField.Value;
            exportBathymetryReplot(app)
            
        end

        % Value changed function: Slider_3
        function Slider_3ValueChanged(app, event)
            app.YSmoothingDistanceEditField.Value = app.Slider_3.Value;
            exportBathymetryReplot(app)
            
        end

        % Value changed function: 
        % EnterYCoordinatetoPlotCrossshoreprofilebelowEditField
        function EnterYCoordinatetoPlotCrossshoreprofilebelowEditFieldValueChanged(app, event)
            value = app.EnterYCoordinatetoPlotCrossshoreprofilebelowEditField.Value;
            solutionExplorationProfilePlot(app)
        end

        % Value changed function: 
        % EnterYCoordinatetoPlotCrossshoreprofilebelowEditField_2
        function EnterYCoordinatetoPlotCrossshoreprofilebelowEditField_2ValueChanged(app, event)
            value = app.EnterYCoordinatetoPlotCrossshoreprofilebelowEditField_2.Value;
            exportBathymetryPlotProfile(app)
        end

        % Button pushed function: ExportOutputButton
        function ExportOutputButtonPushed(app, event)
            exportcBathyButton(app)
        end

        % Value changed function: ListBox_28
        function ListBox_28ValueChanged(app, event)
            value = app.ListBox_28.Value;
            runPBToolOrthoChoose(app)
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
            runPBToolChangeOffshoreX(app)
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            value = app.EditField_4.Value;
            runPBToolChangeSeaBarX(app)
        end

        % Value changed function: ListBox_29
        function ListBox_29ValueChanged(app, event)
            value = app.ListBox_29.Value;
            runPBToolChangeCBathy(app)
        end

        % Value changed function: cBathySlider
        function cBathySliderValueChanged(app, event)
            value = app.cBathySlider.Value;
            runPBToolOverlayTran(app)
        end

        % Callback function
        function EditField_3ValueChanged(app, event)
            value = app.EditField_3.Value;
            runPBToolChangeCbathyY(app)
        end

        % Button pushed function: StartClickingShorelineButton
        function StartClickingShorelineButtonPushed(app, event)
            global pbShoreValue
            pbShoreValue=1;
                app.Lamp_22.Color=[1 0 0];

        end

        % Button pushed function: StopClickingShorelineButton
        function StopClickingShorelineButtonPushed(app, event)
            global pbShoreValue
            pbShoreValue=0;
             app.Lamp_22.Color=[0 1 0];
             if app.Lamp_23.Color==[0 1 0];
                 app.Lamp_28.Color=[.5 .5 .5];
             end
        end

        % Button pushed function: StartClickingSandbarButton
        function StartClickingSandbarButtonPushed(app, event)
            global pbBarValue
            pbBarValue=1;
            app.Lamp_23.Color=[1 0 0];

        end

        % Button pushed function: StopClickingSandbarButton
        function StopClickingSandbarButtonPushed(app, event)
            global pbBarValue
            pbBarValue=0;
            app.Lamp_23.Color=[0 1 0];
             if app.Lamp_22.Color==[0 1 0];
                 app.Lamp_28.Color=[.5 .5 .5];
             end
        end

        % Button pushed function: ResetShorelineButton
        function ResetShorelineButtonPushed(app, event)
            pbToolResetShoreline(app)
        end

        % Button pushed function: ResetSandbarButton
        function ResetSandbarButtonPushed(app, event)
            pbToolResetSandbar(app)
        end

        % Button pushed function: cBathyEstimationButton
        function cBathyEstimationButtonPushed(app, event)
            pbToolCBathyEstimate(app)
        end

        % Callback function
        function StartClickingShorelineButton_2ValueChanged(app, event)
            value = app.StartClickingShorelineButton_2.Value;
            global pbShoreValue
            pbShoreValue=1;
        end

        % Callback function
        function StopClickingShorelineButton_2ValueChanged(app, event)
            value = app.StopClickingShorelineButton_2.Value;
             global pbShoreValue
            pbShoreValue=0;
             app.Lamp_22.Color=[0 1 0];
        end

        % Button pushed function: StartPBToolButton
        function StartPBToolButtonPushed(app, event)
            app.Lamp_25.Color=[1 0 0];
            app.Lamp_26.Color=[.5 .5 .5];
            startPBToolButton(app)

        end

        % Button pushed function: SaveBathymetryButton_2
        function SaveBathymetryButton_2Pushed(app, event)
            runPBToolSaveBathy(app)
        end

        % Selection change function: TabGroup7
        function TabGroup7SelectionChanged(app, event)
            selectedTab = app.TabGroup7.SelectedTab;
          
            if strcmp(selectedTab.Title,'Export Bathymetry')==1
                pbExportBathyStartup(app)
            end
            if strcmp(selectedTab.Title,'Solution Exploration')==1
                pbSolutionExpStartup(app)
            end
            
              if strcmp(selectedTab.Title, 'Run PB Tool')==1
                runPBToolStartup(app)
               end
        end

        % Value changed function: ListBox_30
        function ListBox_30ValueChanged(app, event)
            value = app.ListBox_30.Value;
            pbSolutionExpPbChoose(app)
        end

        % Value changed function: ListBox_31
        function ListBox_31ValueChanged(app, event)
            value = app.ListBox_31.Value;
            pbSolutionExpCBathyChoose(app)
        end

        % Selection changed function: 
        % ChooseImagerytoOverlayBathymetryOntopofButtonGroup_2
        function ChooseImagerytoOverlayBathymetryOntopofButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.ChooseImagerytoOverlayBathymetryOntopofButtonGroup_2.SelectedObject;
            pbSolutionExpImageChoose(app)
        end

        % Value changed function: OverlaySlider_4
        function OverlaySlider_4ValueChanged(app, event)
            value = app.OverlaySlider_4.Value;
            pbSolutionExpOverlayChange1(app)
        end

        % Value changed function: OverlaySlider_5
        function OverlaySlider_5ValueChanged(app, event)
            value = app.OverlaySlider_5.Value;
            pbSolutionExpOverlayChange2(app)
        end

        % Value changed function: DepthSlider_3
        function DepthSlider_3ValueChanged(app, event)
            value = app.DepthSlider_3.Value;
            pbSolutionExpColorMaxDepth1(app)
        end

        % Value changed function: DepthSlider_4
        function DepthSlider_4ValueChanged(app, event)
            value = app.DepthSlider_4.Value;
            pbSolutionExpColorMinDepth1(app)
        end

        % Value changed function: DepthSlider_5
        function DepthSlider_5ValueChanged(app, event)
            value = app.DepthSlider_5.Value;
            pbSolutionExpColorMaxDepth2(app)
        end

        % Value changed function: DepthSlider_6
        function DepthSlider_6ValueChanged(app, event)
            value = app.DepthSlider_6.Value;
            pbSolutionExpColorMinDepth2(app)
        end

        % Value changed function: 
        % EnterYCoordinatetoPlotCrossshoreprofilebelowEditField_3
        function EnterYCoordinatetoPlotCrossshoreprofilebelowEditField_3ValueChanged(app, event)
            value = app.EnterYCoordinatetoPlotCrossshoreprofilebelowEditField_3.Value;
            pbSolutionExpPlotProfile(app)
        end

        % Value changed function: ListBox_32
        function ListBox_32ValueChanged(app, event)
            value = app.ListBox_32.Value;
            pbExportBathyPBChoose(app)
        end

        % Value changed function: ListBox_33
        function ListBox_33ValueChanged(app, event)
            value = app.ListBox_33.Value;
            pbExportBathyCBChoose(app)
        end

        % Selection changed function: 
        % ChooseImagerytoOverlayOntopofButtonGroup_4
        function ChooseImagerytoOverlayOntopofButtonGroup_4SelectionChanged(app, event)
            selectedButton = app.ChooseImagerytoOverlayOntopofButtonGroup_4.SelectedObject;
            pbExportBathyChooseImage(app)
        end

        % Cell edit callback: UITable10_6
        function UITable10_6CellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            pbExportBathyCLim(app)
        end

        % Value changed function: OverlaySlider_6
        function OverlaySlider_6ValueChanged(app, event)
            value = app.OverlaySlider_6.Value;
            pbExportBathyOverlay(app)
        end

        % Callback function
        function Slider_6ValueChanged(app, event)
            value = app.Slider_6.Value;
            PBToolEditField.Value=app.Slider_6.Value;
            
        end

        % Callback function
        function Slider_5ValueChanged(app, event)
            value = app.Slider_5.Value;
            YSmoothingDistanceEditField_3.Value=app.Slider_5.Value;

        end

        % Value changed function: PBToolEditField
        function PBToolEditFieldValueChanged(app, event)
            value = app.PBToolEditField.Value;
app.Lamp_29.Color=[.5 .5 .5];
        end

        % Value changed function: YSmoothingDistanceEditField_3
        function YSmoothingDistanceEditField_3ValueChanged(app, event)
            value = app.YSmoothingDistanceEditField_3.Value;
app.Lamp_29.Color=[.5 .5 .5];
        end

        % Callback function
        function Slider_4ValueChanged(app, event)
            value = app.Slider_4.Value;
            XSmoothingDistanceEditField_3.Value=app.Slider_4.Value;
            
        end

        % Value changed function: XSmoothingDistanceEditField_3
        function XSmoothingDistanceEditField_3ValueChanged(app, event)
            value = app.XSmoothingDistanceEditField_3.Value;
            app.Lamp_29.Color=[.5 .5 .5];
        end

        % Value changed function: 
        % EnterYCoordinatetoPlotCrossshoreprofilebelowEditField_4
        function EnterYCoordinatetoPlotCrossshoreprofilebelowEditField_4ValueChanged(app, event)
            value = app.EnterYCoordinatetoPlotCrossshoreprofilebelowEditField_4.Value;
            pbExportBathyPlotProfile(app)
        end

        % Button pushed function: ExportOutputButton_2
        function ExportOutputButton_2Pushed(app, event)
            pbExportBathyExportButton(app)
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            clearFramesTab(app)
        end

        % Cell edit callback: UITable2
        function UITable2CellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            clearFramesTab(app)
        end

        % Value changed function: FrameRateHzEditField
        function FrameRateHzEditFieldValueChanged(app, event)
            value = app.FrameRateHzEditField.Value;
            clearFramesTab(app)
        end

        % Value changed function: DirectoryNameEditField
        function DirectoryNameEditFieldValueChanged(app, event)
            value = app.DirectoryNameEditField.Value;
            clearFramesTab(app)
        end

        % Cell edit callback: UITable4
        function UITable4CellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            clearLoadIntrinsicsTab(app)
        end

        % Value changed function: FilenameEditField
        function FilenameEditFieldValueChanged(app, event)
            value = app.FilenameEditField.Value;
            app.Lamp_6.Color=[.5 .5 .5];
        end

        % Value changed function: FilenameEditField_2
        function FilenameEditField_2ValueChanged(app, event)
            value = app.FilenameEditField_2.Value;
            app.Lamp_7.Color=[.5 .5 .5];
        end

        % Cell edit callback: UITable5
        function UITable5CellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            clearGCPXYZTab(app)
        end

        % Selection changed function: UnitsButtonGroup
        function UnitsButtonGroupSelectionChanged(app, event)
            selectedButton = app.UnitsButtonGroup.SelectedObject;
            clearGCPXYZTab(app)
        end

        % Value changed function: HorizontalEditField
        function HorizontalEditFieldValueChanged(app, event)
            value = app.HorizontalEditField.Value;
            clearGCPXYZTab(app)
        end

        % Value changed function: VerticalEditField
        function VerticalEditFieldValueChanged(app, event)
            value = app.VerticalEditField.Value;
            clearGCPXYZTab(app)
        end

        % Value changed function: FilenameEditField_4
        function FilenameEditField_4ValueChanged(app, event)
            value = app.FilenameEditField_4.Value;
            clearGCPXYZTab(app)
        end

        % Value changed function: ListBox_4
        function ListBox_4ValueChanged(app, event)
            global ioeoflag
            value = app.ListBox_4.Value;
            intrinsicsFrameSetChoose(app)
            extrinsicsResetSolve(app)
        end

        % Cell edit callback: UITable8
        function UITable8CellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            extrinsicsResetSolve(app)
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            value = app.CheckBox.Value;
            extrinsicsResetSolve(app)
        end

        % Value changed function: CheckBox_4
        function CheckBox_4ValueChanged(app, event)
            value = app.CheckBox_4.Value;
            extrinsicsResetSolve(app)
        end

        % Value changed function: CheckBox_5
        function CheckBox_5ValueChanged(app, event)
            value = app.CheckBox_5.Value;
            extrinsicsResetSolve(app)
        end

        % Value changed function: CheckBox_6
        function CheckBox_6ValueChanged(app, event)
            value = app.CheckBox_6.Value;
            extrinsicsResetSolve(app)
        end

        % Value changed function: CheckBox_7
        function CheckBox_7ValueChanged(app, event)
            value = app.CheckBox_7.Value;
            extrinsicsResetSolve(app)
        end

        % Value changed function: CheckBox_8
        function CheckBox_8ValueChanged(app, event)
            value = app.CheckBox_8.Value;
            extrinsicsResetSolve(app)
        end

        % Value changed function: GCPNumbersEditField
        function GCPNumbersEditFieldValueChanged(app, event)
            value = app.GCPNumbersEditField.Value;

            extrinsicsResetSolve(app)
            resetIOEOFig(app)
        end

        % Value changed function: FilenameEditField_5
        function FilenameEditField_5ValueChanged(app, event)
            value = app.FilenameEditField_5.Value;
            app.Lamp_10.Color=[.5 .5 .5];
        end

        % Value changed function: FilenameEditField_6
        function FilenameEditField_6ValueChanged(app, event)
            value = app.FilenameEditField_6.Value;
            app.Lamp_11.Color=[.5 .5 .5];
        end

        % Cell edit callback: UITable9_2
        function UITable9_2CellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            stablizationReset(app)
        end

        % Value changed function: FilenameEditField_7
        function FilenameEditField_7ValueChanged(app, event)
            value = app.FilenameEditField_7.Value;
            app.Lamp_13.Color=[.5 .5 .5];
        end

        % Value changed function: FilenameEditField_8
        function FilenameEditField_8ValueChanged(app, event)
            value = app.FilenameEditField_8.Value;
            
            app.Lamp_14.Color=[.5 .5 .5];
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            orthoGenSelectGrid(app)
            orthoGenReset(app)
        end

        % Value changed function: OutputDirectoryNameEditField
        function OutputDirectoryNameEditFieldValueChanged(app, event)
            value = app.OutputDirectoryNameEditField.Value;
            orthoGenReset(app)
        end

        % Value changed function: FilenameEditField_9
        function FilenameEditField_9ValueChanged(app, event)
            value = app.FilenameEditField_9.Value;
            orthoMovieReset(app)
        end

        % Value changed function: QualityAffectsMovieSizeListBox
        function QualityAffectsMovieSizeListBoxValueChanged(app, event)
            value = app.QualityAffectsMovieSizeListBox.Value;
            orthoMovieReset(app)
        end

        % Value changed function: FilenameEditField_10
        function FilenameEditField_10ValueChanged(app, event)
            value = app.FilenameEditField_10.Value;
            app.Lamp_18.Color=[.5 .5 .5];

        end

        % Value changed function: MinimumPeriodsEditField
        function MinimumPeriodsEditFieldValueChanged(app, event)
            value = app.MinimumPeriodsEditField.Value;
            runCbathyreset(app)
        end

        % Value changed function: MaximumPeriodsEditField
        function MaximumPeriodsEditFieldValueChanged(app, event)
            value = app.MaximumPeriodsEditField.Value;
            runCbathyreset(app)
        end

        % Value changed function: NumberofFrequenciesEditField
        function NumberofFrequenciesEditFieldValueChanged(app, event)
            value = app.NumberofFrequenciesEditField.Value;
            runCbathyreset(app)
        end

        % Value changed function: FilenameEditField_11
        function FilenameEditField_11ValueChanged(app, event)
            value = app.FilenameEditField_11.Value;
            app.Lamp_20.Color=[.5 .5 .5];
        end

        % Value changed function: FoldernameEditField
        function FoldernameEditFieldValueChanged(app, event)
            value = app.FoldernameEditField.Value;
            
            app.Lamp_21.Color=[.5 .5 .5];

        end

        % Cell edit callback: UITable11
        function UITable11CellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            app.Lamp_24.Color=[.5 .5 .5];
        app.Lamp_25.Color=[.5 .5 .5];
        app.Lamp_26.Color=[.5 .5 .5];
        end

        % Cell edit callback: UITable12
        function UITable12CellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
     
            app.Lamp_25.Color=[.5 .5 .5];
            app.Lamp_26.Color=[.5 .5 .5];
        end

        % Value changed function: FilenameEditField_12
        function FilenameEditField_12ValueChanged(app, event)
            value = app.FilenameEditField_12.Value;
            app.Lamp_26.Color=[.5 .5 .5];
        end

        % Button pushed function: HelpButton_3
        function HelpButton_3Pushed(app, event)
            help_gcp(app)
        end

        % Button pushed function: HelpButton_17
        function HelpButton_17Pushed(app, event)
            help_intrinsics(app)
        end

        % Button pushed function: HelpButton_2
        function HelpButton_2Pushed(app, event)
            help_scpclick(app)
        end

        % Button pushed function: HelpButton_5
        function HelpButton_5Pushed(app, event)
            help_rotation(app)
        end

        % Button pushed function: HelpButton_6
        function HelpButton_6Pushed(app, event)
            help_gridgen(app)
        end

        % Button pushed function: HelpButton_7
        function HelpButton_7Pushed(app, event)
            help_timestack(app)
        end

        % Button pushed function: HelpButton_9
        function HelpButton_9Pushed(app, event)
            help_cbathy(app)
        end

        % Button pushed function: ExtrapolateFeaturesButton
        function ExtrapolateFeaturesButtonPushed(app, event)
            runPBToolExtrapolateFeatures(app)
            app.Lamp_25.Color=[.5 .5 .5];
            app.Lamp_26.Color=[.5 .5 .5];
        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
            value = app.EditField_5.Value;
                app.Lamp_28.Color=[.5 .5 .5];

        end

        % Value changed function: EditField_6
        function EditField_6ValueChanged(app, event)
            value = app.EditField_6.Value;
                app.Lamp_29.Color=[.5 .5 .5];

        end

        % Button pushed function: BlendButton
        function BlendButtonPushed(app, event)
            app.Lamp_29.Color=[.5 .5 .5];
            pbExportBathyBlender(app)
        end

        % Value changed function: cBathyEditField
        function cBathyEditFieldValueChanged(app, event)
            pbExportBathyRePlot(app)
            value = app.cBathyEditField.Value;
            app.Lamp_29.Color=[.5 .5 .5];
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            pbExportBathyPBChoose(app)
        end

        % Button pushed function: HelpButton_12
        function HelpButton_12Pushed(app, event)
            help_exportCbathy(app)
        end

        % Button pushed function: HelpButton_13
        function HelpButton_13Pushed(app, event)
            help_feature(app)
        end

        % Button pushed function: HelpButton_14
        function HelpButton_14Pushed(app, event)
            help_estimate(app)
        end

        % Button pushed function: HelpButton_16
        function HelpButton_16Pushed(app, event)
            help_pbexport(app)
        end

        % Button pushed function: NoDistortionButton
        function NoDistortionButtonPushed(app, event)
            global mdir
            loadNoDistortion(app)
            clearNoDistortionTab
        end

        % Value changed function: ListBox_34
        function ListBox_34ValueChanged(app, event)
            value = app.ListBox_34.Value;
            noDistStabilizationFrameChoose(app)
        end

        % Button pushed function: AddROIButton
        function AddROIButtonPushed(app, event)
            global R rCount
            addROI(app)
        end

        % Button pushed function: FindPointsButton
        function FindPointsButtonPushed(app, event)
            global R pointsA ptThresh
            findFastPoints(app)
        end

        % Button pushed function: StabilizeButton
        function StabilizeButtonPushed(app, event)
            global R pointsA fdir iedir ptThresh TFORM
            noDistStabilizeButton(app)
        end

        % Button pushed function: SaveButton_3
        function SaveButton_3Pushed(app, event)
            global Ro iedir ptThresh fdir TFORM
            noDistStabilizationSave(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.ProjectInitiationTab = uitab(app.TabGroup);
            app.ProjectInitiationTab.Title = 'Project Initiation';
            app.Label = uilabel(app.ProjectInitiationTab);
            app.Label.Text = '';
            app.TabGroup2 = uitabgroup(app.ProjectInitiationTab);
            app.TabGroup2.SelectionChangedFcn = createCallbackFcn(app, @TabGroup2SelectionChanged, true);
            app.CreateLoadProjectTab = uitab(app.TabGroup2);
            app.CreateLoadProjectTab.Title = 'Create/Load Project';
            app.CreateNewProjectButton = uibutton(app.CreateLoadProjectTab, 'push');
            app.CreateNewProjectButton.ButtonPushedFcn = createCallbackFcn(app, @CreateNewProjectButtonPushed, true);
            app.CreateNewProjectButton.Text = 'Create New Project';
            app.OpenExistingProjectButton = uibutton(app.CreateLoadProjectTab, 'push');
            app.OpenExistingProjectButton.ButtonPushedFcn = createCallbackFcn(app, @OpenExistingProjectButtonPushed, true);
            app.OpenExistingProjectButton.Text = 'Open Existing Project';
            app.LoadVideosButton = uibutton(app.CreateLoadProjectTab, 'push');
            app.LoadVideosButton.ButtonPushedFcn = createCallbackFcn(app, @LoadVideosButtonPushed, true);
            app.LoadVideosButton.Text = 'Load Videos';
            app.Label_2 = uilabel(app.CreateLoadProjectTab);
            app.Label_2.Text = 'If starting new project, click button below and enter project name and ';
            app.Label_3 = uilabel(app.CreateLoadProjectTab);
            app.Label_3.Text = 'choose saving directory.';
            app.Label_4 = uilabel(app.CreateLoadProjectTab);
            app.Label_4.Text = 'After creating a project, click button below to select UAS hover';
            app.Label_5 = uilabel(app.CreateLoadProjectTab);
            app.Label_5.Text = 'video files. You can select more than one.';
            app.Label_6 = uilabel(app.CreateLoadProjectTab);
            app.Label_6.Text = 'Once videos are loaded an example frame will display to the left.';
            app.Label_7 = uilabel(app.CreateLoadProjectTab);
            app.Label_7.Text = 'Afterwards click the ''Generate Frames'' tab to continue.';
            app.Label_8 = uilabel(app.CreateLoadProjectTab);
            app.Label_8.Text = 'Click button below to load existing project. Choose the primary folder';
            app.Label_9 = uilabel(app.CreateLoadProjectTab);
            app.Label_9.Text = 'where data and products are contained. ';
            app.Label_10 = uilabel(app.CreateLoadProjectTab);
            app.Label_10.Text = 'After opening, user can click on any tab where user left off.';
            app.Label_11 = uilabel(app.CreateLoadProjectTab);
            app.Label_11.Text = 'Load Project';
            app.Label_12 = uilabel(app.CreateLoadProjectTab);
            app.Label_12.Text = 'New Project';
            app.Label_120 = uilabel(app.CreateLoadProjectTab);
            app.Label_120.Text = '____________________________________________________';
            app.Label_121 = uilabel(app.CreateLoadProjectTab);
            app.Label_121.Text = '____________________________________________________';
            app.Lamp = uilamp(app.CreateLoadProjectTab);
            app.Lamp_2 = uilamp(app.CreateLoadProjectTab);
            app.Lamp_3 = uilamp(app.CreateLoadProjectTab);
            app.UIAxes_2 = uiaxes(app.CreateLoadProjectTab);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.GenerateFramesTab_2 = uitab(app.TabGroup2);
            app.GenerateFramesTab_2.Title = 'Generate Frames';
            app.UITable = uitable(app.GenerateFramesTab_2);
            app.UITable.ColumnName = {'Filename'; 'Order'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.Label_13 = uilabel(app.GenerateFramesTab_2);
            app.Label_13.Text = 'Assign the movie loading order by entering number';
            app.Label_14 = uilabel(app.GenerateFramesTab_2);
            app.Label_14.Text = 'If user does not want to load movie, enter 0 in ''order''';
            app.Label_15 = uilabel(app.GenerateFramesTab_2);
            app.Label_15.Text = 'Movie Order';
            app.Label_16 = uilabel(app.GenerateFramesTab_2);
            app.Label_16.Text = 'Start Time';
            app.Label_17 = uilabel(app.GenerateFramesTab_2);
            app.Label_17.Text = 'Enter timestamp of first frame of first movie';
            app.UITable2 = uitable(app.GenerateFramesTab_2);
            app.UITable2.ColumnName = {'Year'; 'Month'; 'Day'; 'Hour'; 'Minute'; 'Second'};
            app.UITable2.RowName = {};
            app.UITable2.ColumnEditable = true;
            app.UITable2.CellEditCallback = createCallbackFcn(app, @UITable2CellEdit, true);
            app.Label_18 = uilabel(app.GenerateFramesTab_2);
            app.Label_18.Text = 'Frame Rate';
            app.Label_19 = uilabel(app.GenerateFramesTab_2);
            app.Label_19.Text = 'Enter desired frame rate of image output in Hz';
            app.Label_20 = uilabel(app.GenerateFramesTab_2);
            app.Label_20.Text = 'Minimum: 1 Hz';
            app.Label_21 = uilabel(app.GenerateFramesTab_2);
            app.Label_21.Text = 'Maximum: ';
            app.FrameRateHzEditFieldLabel = uilabel(app.GenerateFramesTab_2);
            app.FrameRateHzEditFieldLabel.Text = 'Frame Rate (Hz)';
            app.FrameRateHzEditField = uieditfield(app.GenerateFramesTab_2, 'numeric');
            app.FrameRateHzEditField.ValueChangedFcn = createCallbackFcn(app, @FrameRateHzEditFieldValueChanged, true);
            app.Label_22 = uilabel(app.GenerateFramesTab_2);
            app.Label_22.Text = 'Start Extraction';
            app.Label_23 = uilabel(app.GenerateFramesTab_2);
            app.Label_23.Text = 'Click button to start frame output, status is displayed to right';
            app.Label_24 = uilabel(app.GenerateFramesTab_2);
            app.Label_24.Text = 'When complete, click ''Load Intrinsics'' tab to continue ';
            app.ExtractFramesButton = uibutton(app.GenerateFramesTab_2, 'push');
            app.ExtractFramesButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractFramesButtonPushed, true);
            app.ExtractFramesButton.Text = 'Extract Frames';
            app.Label_25 = uilabel(app.GenerateFramesTab_2);
            app.Label_25.Text = 'Processing Status';
            app.UITable3 = uitable(app.GenerateFramesTab_2);
            app.UITable3.ColumnName = {'Filename'; 'Frames Processed'; 'Total Frames'};
            app.UITable3.RowName = {};
            app.UITable3_2 = uitable(app.GenerateFramesTab_2);
            app.UITable3_2.ColumnName = {'Specified dt [s]'; 'Actual dt [s]'; 'Standard Deviation of Actual dt [s]'};
            app.UITable3_2.RowName = {};
            app.Label_26 = uilabel(app.GenerateFramesTab_2);
            app.Label_26.Text = 'Processing Statistics';
            app.Label_32 = uilabel(app.GenerateFramesTab_2);
            app.Label_32.Text = 'Save Name';
            app.DirectoryNameEditFieldLabel = uilabel(app.GenerateFramesTab_2);
            app.DirectoryNameEditFieldLabel.Text = 'Directory Name';
            app.DirectoryNameEditField = uieditfield(app.GenerateFramesTab_2, 'text');
            app.DirectoryNameEditField.ValueChangedFcn = createCallbackFcn(app, @DirectoryNameEditFieldValueChanged, true);
            app.Label_33 = uilabel(app.GenerateFramesTab_2);
            app.Label_33.Text = 'Enter name of frame directory, do not use spaces ';
            app.Label_122 = uilabel(app.GenerateFramesTab_2);
            app.Label_122.Text = '____________________________________________________';
            app.Label_123 = uilabel(app.GenerateFramesTab_2);
            app.Label_123.Text = '____________________________________________________';
            app.Label_124 = uilabel(app.GenerateFramesTab_2);
            app.Label_124.Text = '____________________________________________________';
            app.Label_125 = uilabel(app.GenerateFramesTab_2);
            app.Label_125.Text = '____________________________________________________';
            app.Label_126 = uilabel(app.GenerateFramesTab_2);
            app.Label_126.Text = '____________________________________________________';
            app.Lamp_4 = uilamp(app.GenerateFramesTab_2);
            app.UIAxes_3 = uiaxes(app.GenerateFramesTab_2);
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.LoadIntrinsicsTab_2 = uitab(app.TabGroup2);
            app.LoadIntrinsicsTab_2.Title = 'Load Intrinsics';
            app.Label_27 = uilabel(app.LoadIntrinsicsTab_2);
            app.Label_27.Text = 'A. Load Cal-Tech Calibration File';
            app.Label_28 = uilabel(app.LoadIntrinsicsTab_2);
            app.Label_28.Text = 'C. Manually Enter Values';
            app.LoadIntrinsicsButton = uibutton(app.LoadIntrinsicsTab_2, 'push');
            app.LoadIntrinsicsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadIntrinsicsButtonPushed, true);
            app.LoadIntrinsicsButton.Text = 'Load Intrinsics';
            app.Label_31 = uilabel(app.LoadIntrinsicsTab_2);
            app.Label_31.Text = 'No New Intrinsics Loaded Yet';
            app.UITable4 = uitable(app.LoadIntrinsicsTab_2);
            app.UITable4.ColumnName = {'Variable'; 'Value'};
            app.UITable4.RowName = {};
            app.UITable4.ColumnEditable = [false true];
            app.UITable4.CellEditCallback = createCallbackFcn(app, @UITable4CellEdit, true);
            app.Label_35 = uilabel(app.LoadIntrinsicsTab_2);
            app.Label_35.Text = 'Continue to ''Save Intrinsic File''';
            app.Label_34 = uilabel(app.LoadIntrinsicsTab_2);
            app.Label_34.Text = 'B. Assume No Distortion';
            app.FilenameEditFieldLabel = uilabel(app.LoadIntrinsicsTab_2);
            app.FilenameEditFieldLabel.Text = 'File name';
            app.FilenameEditField = uieditfield(app.LoadIntrinsicsTab_2, 'text');
            app.FilenameEditField.ValueChangedFcn = createCallbackFcn(app, @FilenameEditFieldValueChanged, true);
            app.SaveIntrinsicsButton = uibutton(app.LoadIntrinsicsTab_2, 'push');
            app.SaveIntrinsicsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveIntrinsicsButtonPushed, true);
            app.SaveIntrinsicsButton.Text = 'Save Intrinsics';
            app.Label_38 = uilabel(app.LoadIntrinsicsTab_2);
            app.Label_38.Text = 'No Spaces';
            app.Label_39 = uilabel(app.LoadIntrinsicsTab_2);
            app.Label_39.Text = 'Project has no intrinsics';
            app.Label_127 = uilabel(app.LoadIntrinsicsTab_2);
            app.Label_127.Text = '____________________________________________________';
            app.Label_128 = uilabel(app.LoadIntrinsicsTab_2);
            app.Label_128.Text = '____________________________________________________';
            app.Lamp_5 = uilamp(app.LoadIntrinsicsTab_2);
            app.Lamp_6 = uilamp(app.LoadIntrinsicsTab_2);
            app.HelpButton_17 = uibutton(app.LoadIntrinsicsTab_2, 'push');
            app.HelpButton_17.ButtonPushedFcn = createCallbackFcn(app, @HelpButton_17Pushed, true);
            app.HelpButton_17.Text = 'Help';
            app.Label_367 = uilabel(app.LoadIntrinsicsTab_2);
            app.Label_367.Text = 'Choose one of options, A,B or C. ';
            app.NoDistortionButton = uibutton(app.LoadIntrinsicsTab_2, 'push');
            app.NoDistortionButton.ButtonPushedFcn = createCallbackFcn(app, @NoDistortionButtonPushed, true);
            app.NoDistortionButton.Text = 'No Distortion';
            app.Lamp_30 = uilamp(app.LoadIntrinsicsTab_2);
            app.Label_369 = uilabel(app.LoadIntrinsicsTab_2);
            app.Label_369.Text = 'Save Intrinsic File';
            app.Label_370 = uilabel(app.LoadIntrinsicsTab_2);
            app.Label_370.Text = '____________________________________________________';
            app.GCPsTab = uitab(app.TabGroup);
            app.GCPsTab.Title = 'GCPs';
            app.TabGroup3 = uitabgroup(app.GCPsTab);
            app.TabGroup3.SelectionChangedFcn = createCallbackFcn(app, @TabGroup3SelectionChanged, true);
            app.ClickGCPsTab = uitab(app.TabGroup3);
            app.ClickGCPsTab.Title = 'Click GCPs';
            app.AddGCPButton_2 = uibutton(app.ClickGCPsTab, 'push');
            app.AddGCPButton_2.ButtonPushedFcn = createCallbackFcn(app, @AddGCPButton_2Pushed, true);
            app.AddGCPButton_2.Text = 'Add GCP';
            app.Label_41 = uilabel(app.ClickGCPsTab);
            app.Label_41.Text = 'Zoom first, then click ''Add GCPs'' to add points. ';
            app.Label_43 = uilabel(app.ClickGCPsTab);
            app.Label_43.Text = 'Add GCPs';
            app.Label_45 = uilabel(app.ClickGCPsTab);
            app.Label_45.Text = 'Delete GCPs';
            app.DeleteGCPButton = uibutton(app.ClickGCPsTab, 'push');
            app.DeleteGCPButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteGCPButtonPushed, true);
            app.DeleteGCPButton.Text = 'Delete GCP';
            app.Label_48 = uilabel(app.ClickGCPsTab);
            app.Label_48.Text = 'Saving GCPs';
            app.Label_50 = uilabel(app.ClickGCPsTab);
            app.Label_50.Text = 'When all GCPs added continue to ''Saving GCPs''';
            app.FilenameEditField_2Label = uilabel(app.ClickGCPsTab);
            app.FilenameEditField_2Label.Text = 'File name';
            app.FilenameEditField_2 = uieditfield(app.ClickGCPsTab, 'text');
            app.FilenameEditField_2.ValueChangedFcn = createCallbackFcn(app, @FilenameEditField_2ValueChanged, true);
            app.Label_49 = uilabel(app.ClickGCPsTab);
            app.Label_49.Text = 'When saved, continue to  ''Assign GCP Coordinates'' Tab ';
            app.Label_54 = uilabel(app.ClickGCPsTab);
            app.Label_54.Text = 'Select Frame Set';
            app.ListBox = uilistbox(app.ClickGCPsTab);
            app.ListBox.Items = {};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {};
            app.Label_44 = uilabel(app.ClickGCPsTab);
            app.Label_44.Text = 'Click GCP in image,Click ''House'' button to restore view';
            app.Label_52 = uilabel(app.ClickGCPsTab);
            app.Label_52.Text = 'No Spaces';
            app.Label_55 = uilabel(app.ClickGCPsTab);
            app.Label_55.Text = 'Click ''Delete GCPs'' to delete points. ';
            app.Label_56 = uilabel(app.ClickGCPsTab);
            app.Label_56.Text = 'Click GCP in image, Click ''House'' button to restore view';
            app.SaveGCPsButton = uibutton(app.ClickGCPsTab, 'push');
            app.SaveGCPsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveGCPsButtonPushed, true);
            app.SaveGCPsButton.Text = 'Save GCPs';
            app.Label_113 = uilabel(app.ClickGCPsTab);
            app.Label_113.Text = '____________________________________________________';
            app.Label_114 = uilabel(app.ClickGCPsTab);
            app.Label_114.Text = '____________________________________________________';
            app.Label_115 = uilabel(app.ClickGCPsTab);
            app.Label_115.Text = '____________________________________________________';
            app.Label_116 = uilabel(app.ClickGCPsTab);
            app.Label_116.Text = '____________________________________________________';
            app.HelpButton_3 = uibutton(app.ClickGCPsTab, 'push');
            app.HelpButton_3.ButtonPushedFcn = createCallbackFcn(app, @HelpButton_3Pushed, true);
            app.HelpButton_3.Text = 'Help';
            app.Lamp_7 = uilamp(app.ClickGCPsTab);
            app.UIAxes_4 = uiaxes(app.ClickGCPsTab);
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.YTick = [];
            app.AssignGCPCoordinatesTab = uitab(app.TabGroup3);
            app.AssignGCPCoordinatesTab.Title = 'Assign GCP Coordinates';
            app.Label_57 = uilabel(app.AssignGCPCoordinatesTab);
            app.Label_57.Text = 'Select Clicked GCPs File';
            app.ListBox_2 = uilistbox(app.AssignGCPCoordinatesTab);
            app.ListBox_2.Items = {};
            app.ListBox_2.ValueChangedFcn = createCallbackFcn(app, @ListBox_2ValueChanged, true);
            app.ListBox_2.Value = {};
            app.Label_58 = uilabel(app.AssignGCPCoordinatesTab);
            app.Label_58.Text = 'Enter GCP Coordinates';
            app.Label_59 = uilabel(app.AssignGCPCoordinatesTab);
            app.Label_59.Text = 'Enter Datum Descriptions';
            app.Label_60 = uilabel(app.AssignGCPCoordinatesTab);
            app.Label_60.Text = 'Save File';
            app.Label_61 = uilabel(app.AssignGCPCoordinatesTab);
            app.Label_61.Text = 'When saved, continue to  ''Extrinsics'' Tab ';
            app.Label_62 = uilabel(app.AssignGCPCoordinatesTab);
            app.Label_62.Text = 'No Spaces';
            app.SaveGCPCoordinatesButton = uibutton(app.AssignGCPCoordinatesTab, 'push');
            app.SaveGCPCoordinatesButton.ButtonPushedFcn = createCallbackFcn(app, @SaveGCPCoordinatesButtonPushed, true);
            app.SaveGCPCoordinatesButton.Text = 'Save GCP Coordinates';
            app.HorizontalEditFieldLabel = uilabel(app.AssignGCPCoordinatesTab);
            app.HorizontalEditFieldLabel.Text = 'Horizontal';
            app.HorizontalEditField = uieditfield(app.AssignGCPCoordinatesTab, 'text');
            app.HorizontalEditField.ValueChangedFcn = createCallbackFcn(app, @HorizontalEditFieldValueChanged, true);
            app.FilenameEditField_4Label = uilabel(app.AssignGCPCoordinatesTab);
            app.FilenameEditField_4Label.Text = 'File name';
            app.FilenameEditField_4 = uieditfield(app.AssignGCPCoordinatesTab, 'text');
            app.FilenameEditField_4.ValueChangedFcn = createCallbackFcn(app, @FilenameEditField_4ValueChanged, true);
            app.VerticalEditFieldLabel = uilabel(app.AssignGCPCoordinatesTab);
            app.VerticalEditFieldLabel.Text = 'Vertical';
            app.VerticalEditField = uieditfield(app.AssignGCPCoordinatesTab, 'text');
            app.VerticalEditField.ValueChangedFcn = createCallbackFcn(app, @VerticalEditFieldValueChanged, true);
            app.Label_64 = uilabel(app.AssignGCPCoordinatesTab);
            app.Label_64.Text = 'Enter useful descriptions for datum metadata, continue to ''Save File''';
            app.Label_65 = uilabel(app.AssignGCPCoordinatesTab);
            app.Label_65.Text = 'Enter GCP coordinates for numbers corresponding to left';
            app.UITable5 = uitable(app.AssignGCPCoordinatesTab);
            app.UITable5.ColumnName = {'Number'; 'X [m]'; 'Y [m]'; 'Z [m]'};
            app.UITable5.RowName = {};
            app.UITable5.ColumnEditable = [false true true true];
            app.UITable5.CellEditCallback = createCallbackFcn(app, @UITable5CellEdit, true);
            app.UnitsButtonGroup = uibuttongroup(app.AssignGCPCoordinatesTab);
            app.UnitsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @UnitsButtonGroupSelectionChanged, true);
            app.UnitsButtonGroup.Title = 'Units';
            app.ftButton = uiradiobutton(app.UnitsButtonGroup);
            app.ftButton.Text = 'ft';
            app.ftButton.Value = true;
            app.mButton = uiradiobutton(app.UnitsButtonGroup);
            app.mButton.Text = 'm';
            app.Label_117 = uilabel(app.AssignGCPCoordinatesTab);
            app.Label_117.Text = '____________________________________________________';
            app.Label_118 = uilabel(app.AssignGCPCoordinatesTab);
            app.Label_118.Text = '____________________________________________________';
            app.Label_119 = uilabel(app.AssignGCPCoordinatesTab);
            app.Label_119.Text = '____________________________________________________';
            app.Lamp_8 = uilamp(app.AssignGCPCoordinatesTab);
            app.Label_360 = uilabel(app.AssignGCPCoordinatesTab);
            app.Label_360.Text = 'Note: Coordinates must be in projected coordinate system';
            app.UIAxes_5 = uiaxes(app.AssignGCPCoordinatesTab);
            app.UIAxes_5.XTick = [];
            app.UIAxes_5.YTick = [];
            app.ExtrinsicsTab = uitab(app.TabGroup);
            app.ExtrinsicsTab.Title = 'Extrinsics';
            app.TabGroup4 = uitabgroup(app.ExtrinsicsTab);
            app.TabGroup4.SelectionChangedFcn = createCallbackFcn(app, @TabGroup4SelectionChanged, true);
            app.InitialSolutionTab = uitab(app.TabGroup4);
            app.InitialSolutionTab.Title = 'Initial Solution';
            app.Label_66 = uilabel(app.InitialSolutionTab);
            app.Label_66.Text = 'Select GCPs File';
            app.ListBox_3 = uilistbox(app.InitialSolutionTab);
            app.ListBox_3.Items = {};
            app.ListBox_3.ValueChangedFcn = createCallbackFcn(app, @ListBox_3ValueChanged, true);
            app.ListBox_3.Value = {};
            app.Label_67 = uilabel(app.InitialSolutionTab);
            app.Label_67.Text = 'Initial Guess';
            app.CheckBox = uicheckbox(app.InitialSolutionTab);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '';
            app.CheckBox_4 = uicheckbox(app.InitialSolutionTab);
            app.CheckBox_4.ValueChangedFcn = createCallbackFcn(app, @CheckBox_4ValueChanged, true);
            app.CheckBox_4.Text = '';
            app.CheckBox_5 = uicheckbox(app.InitialSolutionTab);
            app.CheckBox_5.ValueChangedFcn = createCallbackFcn(app, @CheckBox_5ValueChanged, true);
            app.CheckBox_5.Text = '';
            app.CheckBox_6 = uicheckbox(app.InitialSolutionTab);
            app.CheckBox_6.ValueChangedFcn = createCallbackFcn(app, @CheckBox_6ValueChanged, true);
            app.CheckBox_6.Text = '';
            app.CheckBox_7 = uicheckbox(app.InitialSolutionTab);
            app.CheckBox_7.ValueChangedFcn = createCallbackFcn(app, @CheckBox_7ValueChanged, true);
            app.CheckBox_7.Text = '';
            app.CheckBox_8 = uicheckbox(app.InitialSolutionTab);
            app.CheckBox_8.ValueChangedFcn = createCallbackFcn(app, @CheckBox_8ValueChanged, true);
            app.CheckBox_8.Text = '';
            app.Label_70 = uilabel(app.InitialSolutionTab);
            app.Label_70.Text = 'Click check boxes to set variable as ''fixed''';
            app.Label_75 = uilabel(app.InitialSolutionTab);
            app.Label_75.Text = 'Select GCPs ';
            app.Label_76 = uilabel(app.InitialSolutionTab);
            app.Label_76.Text = 'Enter GCP numbers (on left) to use, seperate by commas';
            app.GCPNumbersEditFieldLabel = uilabel(app.InitialSolutionTab);
            app.GCPNumbersEditFieldLabel.Text = 'GCP Numbers';
            app.GCPNumbersEditField = uieditfield(app.InitialSolutionTab, 'text');
            app.GCPNumbersEditField.ValueChangedFcn = createCallbackFcn(app, @GCPNumbersEditFieldValueChanged, true);
            app.Label_81 = uilabel(app.InitialSolutionTab);
            app.Label_81.Text = 'Solution Metrics';
            app.UITable7 = uitable(app.InitialSolutionTab);
            app.UITable7.ColumnName = {'Variable'; 'Value'; 'Uncertainty (+-)'};
            app.UITable7.RowName = {};
            app.UITable7.ColumnEditable = true;
            app.UITable7_2 = uitable(app.InitialSolutionTab);
            app.UITable7_2.ColumnName = {'GCP'; 'E Error'; 'N Error'};
            app.UITable7_2.RowName = {};
            app.Label_79 = uilabel(app.InitialSolutionTab);
            app.Label_79.Text = 'No Spaces';
            app.Label_83 = uilabel(app.InitialSolutionTab);
            app.Label_83.Text = 'When Saved, go to ';
            app.Label_84 = uilabel(app.InitialSolutionTab);
            app.Label_84.Text = 'Saving Solution';
            app.SolveButton = uibutton(app.InitialSolutionTab, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            app.UITable8 = uitable(app.InitialSolutionTab);
            app.UITable8.ColumnName = {'E'; 'N'; 'Z'; 'Azimuth'; 'Tilt'; 'Swing'};
            app.UITable8.RowName = {};
            app.UITable8.ColumnEditable = true;
            app.UITable8.CellEditCallback = createCallbackFcn(app, @UITable8CellEdit, true);
            app.SaveButton = uibutton(app.InitialSolutionTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.Label_86 = uilabel(app.InitialSolutionTab);
            app.Label_86.Text = 'Select Frame Set';
            app.ListBox_5 = uilistbox(app.InitialSolutionTab);
            app.ListBox_5.Items = {};
            app.ListBox_5.ValueChangedFcn = createCallbackFcn(app, @ListBox_5ValueChanged, true);
            app.ListBox_5.Value = {};
            app.Label_85 = uilabel(app.InitialSolutionTab);
            app.Label_85.Text = 'Select Intrinsics File';
            app.ListBox_4 = uilistbox(app.InitialSolutionTab);
            app.ListBox_4.Items = {};
            app.ListBox_4.ValueChangedFcn = createCallbackFcn(app, @ListBox_4ValueChanged, true);
            app.ListBox_4.Value = {};
            app.Label_71 = uilabel(app.InitialSolutionTab);
            app.Label_71.Text = 'Coordinate System';
            app.Label_72 = uilabel(app.InitialSolutionTab);
            app.Label_72.Text = 'Horizontal:';
            app.Label_73 = uilabel(app.InitialSolutionTab);
            app.Label_73.Text = 'Vertical:';
            app.Label_74 = uilabel(app.InitialSolutionTab);
            app.Label_74.Text = 'Units:';
            app.FilenameEditField_5Label = uilabel(app.InitialSolutionTab);
            app.FilenameEditField_5Label.Text = 'Filename';
            app.FilenameEditField_5 = uieditfield(app.InitialSolutionTab, 'text');
            app.FilenameEditField_5.ValueChangedFcn = createCallbackFcn(app, @FilenameEditField_5ValueChanged, true);
            app.HelpButton = uibutton(app.InitialSolutionTab, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.Label_101 = uilabel(app.InitialSolutionTab);
            app.Label_101.Text = '''SCP Selection'' tab';
            app.Label_103 = uilabel(app.InitialSolutionTab);
            app.Label_103.Text = '____________________________________________________';
            app.Label_98 = uilabel(app.InitialSolutionTab);
            app.Label_98.Text = 'Select Frame Set, Intrinsics and GCPs file and continue to ''Initial Guess''';
            app.Label_100 = uilabel(app.InitialSolutionTab);
            app.Label_100.Text = 'Then click solve!!';
            app.Label_104 = uilabel(app.InitialSolutionTab);
            app.Label_104.Text = '____________________________________________________';
            app.Label_105 = uilabel(app.InitialSolutionTab);
            app.Label_105.Text = '____________________________________________________';
            app.Label_106 = uilabel(app.InitialSolutionTab);
            app.Label_106.Text = '____________________________________________________';
            app.Lamp_9 = uilamp(app.InitialSolutionTab);
            app.Lamp_10 = uilamp(app.InitialSolutionTab);
            app.FocalLengthEditField = uieditfield(app.InitialSolutionTab, 'text');
            app.CheckBox_9 = uicheckbox(app.InitialSolutionTab);
            app.CheckBox_9.Text = '';
            app.FocalLengthEditFieldLabel_2 = uilabel(app.InitialSolutionTab);
            app.FocalLengthEditFieldLabel_2.Text = '(Pixels)';
            app.Label_371 = uilabel(app.InitialSolutionTab);
            app.Label_371.Text = ' Continue to Select GCPs';
            app.FocalLengthEditFieldLabel_3 = uilabel(app.InitialSolutionTab);
            app.FocalLengthEditFieldLabel_3.Text = 'Focal Length';
            app.FocalLengthEditFieldLabel_4 = uilabel(app.InitialSolutionTab);
            app.FocalLengthEditFieldLabel_4.Text = 'UAS Position and Pose';
            app.Label_372 = uilabel(app.InitialSolutionTab);
            app.Label_372.Text = 'Check That They Make Sense!!!';
            app.UIAxes_6 = uiaxes(app.InitialSolutionTab);
            app.UIAxes_6.XTick = [];
            app.UIAxes_6.YTick = [];
            app.SCPSelectionTab = uitab(app.TabGroup4);
            app.SCPSelectionTab.Title = 'SCP Selection';
            app.AddSCPButton = uibutton(app.SCPSelectionTab, 'push');
            app.AddSCPButton.ButtonPushedFcn = createCallbackFcn(app, @AddSCPButtonPushed2, true);
            app.AddSCPButton.Text = 'Add SCP';
            app.Label_87 = uilabel(app.SCPSelectionTab);
            app.Label_87.Text = 'Zoom first, then click ''Add SCPs'' to add points. ';
            app.Label_88 = uilabel(app.SCPSelectionTab);
            app.Label_88.Text = 'Add SCPs';
            app.Label_89 = uilabel(app.SCPSelectionTab);
            app.Label_89.Text = 'Delete SCPs';
            app.DeleteSCPButton = uibutton(app.SCPSelectionTab, 'push');
            app.DeleteSCPButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteSCPButtonPushed, true);
            app.DeleteSCPButton.Text = 'Delete SCP';
            app.Label_90 = uilabel(app.SCPSelectionTab);
            app.Label_90.Text = 'Saving SCPs';
            app.Label_91 = uilabel(app.SCPSelectionTab);
            app.Label_91.Text = 'When all SCPs added continue to ''Saving SCPs''';
            app.Label_92 = uilabel(app.SCPSelectionTab);
            app.Label_92.Text = 'When saved, continue to  ''Stabilization'' Tab ';
            app.Label_93 = uilabel(app.SCPSelectionTab);
            app.Label_93.Text = 'Select Frame Set';
            app.ListBox_6 = uilistbox(app.SCPSelectionTab);
            app.ListBox_6.Items = {};
            app.ListBox_6.ValueChangedFcn = createCallbackFcn(app, @ListBox_6ValueChanged, true);
            app.ListBox_6.Value = {};
            app.Label_94 = uilabel(app.SCPSelectionTab);
            app.Label_94.Text = 'Click SCP in image,a new window will open to define SCP parameters';
            app.Label_95 = uilabel(app.SCPSelectionTab);
            app.Label_95.Text = 'No Spaces';
            app.Label_96 = uilabel(app.SCPSelectionTab);
            app.Label_96.Text = 'Click ''Delete SCPs'' to delete points. ';
            app.Label_97 = uilabel(app.SCPSelectionTab);
            app.Label_97.Text = 'Click SCP in image, Click ''House'' button to restore view';
            app.SaveSCPsButton = uibutton(app.SCPSelectionTab, 'push');
            app.SaveSCPsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSCPsButtonPushed, true);
            app.SaveSCPsButton.Text = 'Save SCPs';
            app.FilenameEditField_6Label = uilabel(app.SCPSelectionTab);
            app.FilenameEditField_6Label.Text = 'File name';
            app.FilenameEditField_6 = uieditfield(app.SCPSelectionTab, 'text');
            app.FilenameEditField_6.ValueChangedFcn = createCallbackFcn(app, @FilenameEditField_6ValueChanged, true);
            app.Label_108 = uilabel(app.SCPSelectionTab);
            app.Label_108.Text = 'After parameter entry, Click ''House'' button to restore view';
            app.Label_109 = uilabel(app.SCPSelectionTab);
            app.Label_109.Text = '____________________________________________________';
            app.Label_110 = uilabel(app.SCPSelectionTab);
            app.Label_110.Text = '____________________________________________________';
            app.Label_111 = uilabel(app.SCPSelectionTab);
            app.Label_111.Text = '____________________________________________________';
            app.Label_112 = uilabel(app.SCPSelectionTab);
            app.Label_112.Text = '____________________________________________________';
            app.HelpButton_2 = uibutton(app.SCPSelectionTab, 'push');
            app.HelpButton_2.ButtonPushedFcn = createCallbackFcn(app, @HelpButton_2Pushed, true);
            app.HelpButton_2.Text = 'Help';
            app.Lamp_11 = uilamp(app.SCPSelectionTab);
            app.UIAxes_7 = uiaxes(app.SCPSelectionTab);
            app.UIAxes_7.XTick = [];
            app.UIAxes_7.YTick = [];
            app.SCPStabilizationTab = uitab(app.TabGroup4);
            app.SCPStabilizationTab.Title = 'SCP Stabilization';
            app.ListBox_7 = uilistbox(app.SCPStabilizationTab);
            app.ListBox_7.Items = {};
            app.ListBox_7.ValueChangedFcn = createCallbackFcn(app, @ListBox_7ValueChanged, true);
            app.ListBox_7.Value = {};
            app.Label_130 = uilabel(app.SCPStabilizationTab);
            app.Label_130.Text = 'Select Frame Set';
            app.ListBox_8 = uilistbox(app.SCPStabilizationTab);
            app.ListBox_8.Items = {};
            app.ListBox_8.ValueChangedFcn = createCallbackFcn(app, @ListBox_8ValueChanged, true);
            app.ListBox_8.Value = {};
            app.Label_131 = uilabel(app.SCPStabilizationTab);
            app.Label_131.Text = 'Select Initial Solution';
            app.ListBox_9 = uilistbox(app.SCPStabilizationTab);
            app.ListBox_9.Items = {};
            app.ListBox_9.ValueChangedFcn = createCallbackFcn(app, @ListBox_9ValueChanged, true);
            app.ListBox_9.Value = {};
            app.Label_132 = uilabel(app.SCPStabilizationTab);
            app.Label_132.Text = 'Coordinate System';
            app.Label_133 = uilabel(app.SCPStabilizationTab);
            app.Label_133.Text = 'Horizontal:';
            app.Label_134 = uilabel(app.SCPStabilizationTab);
            app.Label_134.Text = 'Vertical:';
            app.Label_135 = uilabel(app.SCPStabilizationTab);
            app.Label_135.Text = 'Units:';
            app.Label_136 = uilabel(app.SCPStabilizationTab);
            app.Label_136.Text = '____________________________________________________';
            app.Label_137 = uilabel(app.SCPStabilizationTab);
            app.Label_137.Text = 'Select Frame Set, Initial Solution and SCPs file and then fill below table';
            app.Label_138 = uilabel(app.SCPStabilizationTab);
            app.Label_138.Text = '____________________________________________________';
            app.Label_139 = uilabel(app.SCPStabilizationTab);
            app.Label_139.Text = 'Select SCPs File';
            app.SolveButton_2 = uibutton(app.SCPStabilizationTab, 'push');
            app.SolveButton_2.ButtonPushedFcn = createCallbackFcn(app, @SolveButton_2Pushed, true);
            app.SolveButton_2.Text = 'Solve';
            app.Label_140 = uilabel(app.SCPStabilizationTab);
            app.Label_140.Text = '____________________________________________________';
            app.SaveButton_2 = uibutton(app.SCPStabilizationTab, 'push');
            app.SaveButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_2Pushed, true);
            app.SaveButton_2.Text = 'Save';
            app.Label_141 = uilabel(app.SCPStabilizationTab);
            app.Label_141.Text = 'Saving Solutions';
            app.Label_142 = uilabel(app.SCPStabilizationTab);
            app.Label_142.Text = 'When saved, continue to  ''Georectification'' Tab ';
            app.FilenameEditField_7Label = uilabel(app.SCPStabilizationTab);
            app.FilenameEditField_7Label.Text = 'File name';
            app.FilenameEditField_7 = uieditfield(app.SCPStabilizationTab, 'text');
            app.FilenameEditField_7.ValueChangedFcn = createCallbackFcn(app, @FilenameEditField_7ValueChanged, true);
            app.Label_143 = uilabel(app.SCPStabilizationTab);
            app.Label_143.Text = 'No Spaces';
            app.Label_129 = uilabel(app.SCPStabilizationTab);
            app.Label_129.Text = 'Solution Metrics';
            app.UITable9 = uitable(app.SCPStabilizationTab);
            app.UITable9.ColumnName = {'E'; 'N'; 'Z'; 'Azimuth'; 'Tilt'; 'Swing'};
            app.UITable9.RowName = {''};
            app.UITable9_2 = uitable(app.SCPStabilizationTab);
            app.UITable9_2.ColumnName = {'Z Elevation'};
            app.UITable9_2.RowName = {''};
            app.UITable9_2.ColumnEditable = true;
            app.UITable9_2.CellEditCallback = createCallbackFcn(app, @UITable9_2CellEdit, true);
            app.Label_144 = uilabel(app.SCPStabilizationTab);
            app.Label_144.Text = 'Enter Estimated SCP Elevations';
            app.Label_145 = uilabel(app.SCPStabilizationTab);
            app.Label_145.Text = '____________________________________________________';
            app.Label_146 = uilabel(app.SCPStabilizationTab);
            app.Label_146.Text = 'Once entered, click ''solve''';
            app.Lamp_12 = uilamp(app.SCPStabilizationTab);
            app.Lamp_13 = uilamp(app.SCPStabilizationTab);
            app.Label_358 = uilabel(app.SCPStabilizationTab);
            app.Label_358.Text = 'Educated guess in correct ';
            app.Label_359 = uilabel(app.SCPStabilizationTab);
            app.Label_359.Text = 'Vertical Datum and Units';
            app.UIAxes_8 = uiaxes(app.SCPStabilizationTab);
            app.UIAxes_8.XTick = [];
            app.UIAxes_8.YTick = [];
            app.NoDistortionStabilizationTab = uitab(app.TabGroup4);
            app.NoDistortionStabilizationTab.Title = 'No Distortion Stabilization';
            app.Label_373 = uilabel(app.NoDistortionStabilizationTab);
            app.Label_373.Text = 'Select Frame Set';
            app.ListBox_34 = uilistbox(app.NoDistortionStabilizationTab);
            app.ListBox_34.Items = {};
            app.ListBox_34.ValueChangedFcn = createCallbackFcn(app, @ListBox_34ValueChanged, true);
            app.ListBox_34.Value = {};
            app.Label_374 = uilabel(app.NoDistortionStabilizationTab);
            app.Label_374.Text = 'Select Initial Solution';
            app.ListBox_35 = uilistbox(app.NoDistortionStabilizationTab);
            app.ListBox_35.Items = {};
            app.ListBox_35.Value = {};
            app.Label_375 = uilabel(app.NoDistortionStabilizationTab);
            app.Label_375.Text = '____________________________________________________';
            app.Label_376 = uilabel(app.NoDistortionStabilizationTab);
            app.Label_376.Text = '____________________________________________________';
            app.SaveButton_3 = uibutton(app.NoDistortionStabilizationTab, 'push');
            app.SaveButton_3.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_3Pushed, true);
            app.SaveButton_3.Text = 'Save';
            app.Label_377 = uilabel(app.NoDistortionStabilizationTab);
            app.Label_377.Text = 'Saving Solutions';
            app.Label_378 = uilabel(app.NoDistortionStabilizationTab);
            app.Label_378.Text = 'When saved, continue to  ''Georectification'' Tab ';
            app.FilenameEditField_7Label_2 = uilabel(app.NoDistortionStabilizationTab);
            app.FilenameEditField_7Label_2.Text = 'File name';
            app.FilenameEditField_13 = uieditfield(app.NoDistortionStabilizationTab, 'text');
            app.Label_379 = uilabel(app.NoDistortionStabilizationTab);
            app.Label_379.Text = 'No Spaces';
            app.Lamp_31 = uilamp(app.NoDistortionStabilizationTab);
            app.Label_380 = uilabel(app.NoDistortionStabilizationTab);
            app.Label_380.Text = 'Note, only non-distortion solutions can be utalized in this method.';
            app.Label_381 = uilabel(app.NoDistortionStabilizationTab);
            app.Label_381.Text = '____________________________________________________';
            app.Label_382 = uilabel(app.NoDistortionStabilizationTab);
            app.Label_382.Text = 'Add Regions of Interest (ROI) for Stabilization';
            app.AddROIButton = uibutton(app.NoDistortionStabilizationTab, 'push');
            app.AddROIButton.ButtonPushedFcn = createCallbackFcn(app, @AddROIButtonPushed, true);
            app.AddROIButton.Text = 'Add ROI';
            app.Label_383 = uilabel(app.NoDistortionStabilizationTab);
            app.Label_383.Text = '____________________________________________________';
            app.Label_385 = uilabel(app.NoDistortionStabilizationTab);
            app.Label_385.Text = 'Identify Points';
            app.StabilizeButton = uibutton(app.NoDistortionStabilizationTab, 'push');
            app.StabilizeButton.ButtonPushedFcn = createCallbackFcn(app, @StabilizeButtonPushed, true);
            app.StabilizeButton.Text = 'Stabilize';
            app.Lamp_32 = uilamp(app.NoDistortionStabilizationTab);
            app.ThresholdEditFieldLabel = uilabel(app.NoDistortionStabilizationTab);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.NoDistortionStabilizationTab, 'text');
            app.Label_384 = uilabel(app.NoDistortionStabilizationTab);
            app.Label_384.Text = 'Threshold values between 0-1; 1 having less points';
            app.Lamp_33 = uilamp(app.NoDistortionStabilizationTab);
            app.FindPointsButton = uibutton(app.NoDistortionStabilizationTab, 'push');
            app.FindPointsButton.ButtonPushedFcn = createCallbackFcn(app, @FindPointsButtonPushed, true);
            app.FindPointsButton.Text = 'Find Points';
            app.Label_386 = uilabel(app.NoDistortionStabilizationTab);
            app.Label_386.Text = '____________________________________________________';
            app.Label_387 = uilabel(app.NoDistortionStabilizationTab);
            app.Label_387.Text = 'Once Satisfied,  continue to ''Saving Solutions'' ';
            app.Label_388 = uilabel(app.NoDistortionStabilizationTab);
            app.Label_388.Text = 'Stabilize Imagery';
            app.Label_389 = uilabel(app.NoDistortionStabilizationTab);
            app.Label_389.Text = 'Otherwise, reselect Frame Set and Start Again';
            app.Label_390 = uilabel(app.NoDistortionStabilizationTab);
            app.Label_390.Text = 'Continue to ''Identify Points''';
            app.Label_391 = uilabel(app.NoDistortionStabilizationTab);
            app.Label_391.Text = 'Once Satisfied with displayed points, continue to ''Stabilize Imagery''';
            app.UIAxes_16 = uiaxes(app.NoDistortionStabilizationTab);
            app.UIAxes_16.XTick = [];
            app.UIAxes_16.YTick = [];
            app.GeorectifcationTab = uitab(app.TabGroup);
            app.GeorectifcationTab.Title = 'Georectifcation';
            app.TabGroup5 = uitabgroup(app.GeorectifcationTab);
            app.TabGroup5.SelectionChangedFcn = createCallbackFcn(app, @TabGroup5SelectionChanged, true);
            app.GridGenerationTab = uitab(app.TabGroup5);
            app.GridGenerationTab.Title = 'Grid Generation';
            app.Label_147 = uilabel(app.GridGenerationTab);
            app.Label_147.Text = 'Select Frame Set';
            app.ListBox_10 = uilistbox(app.GridGenerationTab);
            app.ListBox_10.Items = {};
            app.ListBox_10.ValueChangedFcn = createCallbackFcn(app, @ListBox_10ValueChanged, true);
            app.ListBox_10.Value = {};
            app.Label_148 = uilabel(app.GridGenerationTab);
            app.Label_148.Text = 'Select Solution';
            app.ListBox_11 = uilistbox(app.GridGenerationTab);
            app.ListBox_11.Items = {};
            app.ListBox_11.ValueChangedFcn = createCallbackFcn(app, @ListBox_11ValueChanged, true);
            app.ListBox_11.Value = {};
            app.Label_149 = uilabel(app.GridGenerationTab);
            app.Label_149.Text = '____________________________________________________';
            app.Label_150 = uilabel(app.GridGenerationTab);
            app.Label_150.Text = '____________________________________________________';
            app.Label_151 = uilabel(app.GridGenerationTab);
            app.Label_151.Text = 'Select Frame Set, Initial Solution and continue to ''Local Coordinate System''';
            app.Label_152 = uilabel(app.GridGenerationTab);
            app.Label_152.Text = 'Local Coordinate System';
            app.RotationSlider = uislider(app.GridGenerationTab);
            app.RotationSlider.Limits = [0 360];
            app.RotationSlider.ValueChangedFcn = createCallbackFcn(app, @RotationSliderValueChanged, true);
            app.UITable10 = uitable(app.GridGenerationTab);
            app.UITable10.ColumnName = {'Eo'; 'No'};
            app.UITable10.RowName = {'Origin'};
            app.UITable10.ColumnEditable = true;
            app.UITable10.CellEditCallback = createCallbackFcn(app, @UITable10CellEdit, true);
            app.Label_153 = uilabel(app.GridGenerationTab);
            app.Label_153.Text = 'Enter Angle and Easting and Northing of Local Origin, Continue to Grid Limits';
            app.HelpButton_5 = uibutton(app.GridGenerationTab, 'push');
            app.HelpButton_5.ButtonPushedFcn = createCallbackFcn(app, @HelpButton_5Pushed, true);
            app.HelpButton_5.Text = 'Help';
            app.Label_154 = uilabel(app.GridGenerationTab);
            app.Label_154.Text = '____________________________________________________';
            app.Label_155 = uilabel(app.GridGenerationTab);
            app.Label_155.Text = 'Local Grid Limits';
            app.ResolutionSlider = uislider(app.GridGenerationTab);
            app.ResolutionSlider.ValueChangedFcn = createCallbackFcn(app, @ResolutionSliderValueChanged, true);
            app.HelpButton_6 = uibutton(app.GridGenerationTab, 'push');
            app.HelpButton_6.ButtonPushedFcn = createCallbackFcn(app, @HelpButton_6Pushed, true);
            app.HelpButton_6.Text = 'Help';
            app.UITable10_2 = uitable(app.GridGenerationTab);
            app.UITable10_2.ColumnName = {'Min'; 'Max'};
            app.UITable10_2.RowName = {'X,Y'};
            app.UITable10_2.ColumnEditable = true;
            app.UITable10_2.CellEditCallback = createCallbackFcn(app, @UITable10_2CellEdit, true);
            app.Label_156 = uilabel(app.GridGenerationTab);
            app.Label_156.Text = '____________________________________________________';
            app.Label_157 = uilabel(app.GridGenerationTab);
            app.Label_157.Text = 'Enter limits and resolution in local coordinates and continue to ''Saving Grid''';
            app.Label_158 = uilabel(app.GridGenerationTab);
            app.Label_158.Text = 'Saving Grid';
            app.Label_159 = uilabel(app.GridGenerationTab);
            app.Label_159.Text = 'When saved, continue to  ''Orthophoto Generation''  or ''cBathy'' Tab ';
            app.SaveGridButton = uibutton(app.GridGenerationTab, 'push');
            app.SaveGridButton.ButtonPushedFcn = createCallbackFcn(app, @SaveGridButtonPushed, true);
            app.SaveGridButton.Text = 'Save Grid';
            app.FilenameEditField_8Label = uilabel(app.GridGenerationTab);
            app.FilenameEditField_8Label.Text = 'File name';
            app.FilenameEditField_8 = uieditfield(app.GridGenerationTab, 'text');
            app.FilenameEditField_8.ValueChangedFcn = createCallbackFcn(app, @FilenameEditField_8ValueChanged, true);
            app.ResolutionEditFieldLabel = uilabel(app.GridGenerationTab);
            app.ResolutionEditFieldLabel.Text = 'Resolution';
            app.ResolutionEditField = uieditfield(app.GridGenerationTab, 'text');
            app.ResolutionEditField.ValueChangedFcn = createCallbackFcn(app, @ResolutionEditFieldValueChanged, true);
            app.AngledegEditFieldLabel = uilabel(app.GridGenerationTab);
            app.AngledegEditFieldLabel.Text = 'Angle [deg]';
            app.AngledegEditField = uieditfield(app.GridGenerationTab, 'text');
            app.AngledegEditField.ValueChangedFcn = createCallbackFcn(app, @AngledegEditFieldValueChanged, true);
            app.ClickOriginButton = uibutton(app.GridGenerationTab, 'push');
            app.ClickOriginButton.ButtonPushedFcn = createCallbackFcn(app, @ClickOriginButtonPushed, true);
            app.ClickOriginButton.Text = 'Click Origin';
            app.MWLElevationEditFieldLabel = uilabel(app.GridGenerationTab);
            app.MWLElevationEditFieldLabel.Text = 'MWL  Elevation';
            app.MWLElevationEditField = uieditfield(app.GridGenerationTab, 'text');
            app.MWLElevationEditField.ValueChangedFcn = createCallbackFcn(app, @MWLElevationEditFieldValueChanged, true);
            app.ResetOriginButton = uibutton(app.GridGenerationTab, 'push');
            app.ResetOriginButton.ButtonPushedFcn = createCallbackFcn(app, @ResetOriginButtonPushed, true);
            app.ResetOriginButton.Text = 'Reset Origin';
            app.Lamp_14 = uilamp(app.GridGenerationTab);
            app.Label_315 = uilabel(app.GridGenerationTab);
            app.Label_315.Text = '[]';
            app.Label_316 = uilabel(app.GridGenerationTab);
            app.Label_316.Text = '[]';
            app.Label_352 = uilabel(app.GridGenerationTab);
            app.Label_352.Text = '[]';
            app.Label_356 = uilabel(app.GridGenerationTab);
            app.Label_356.Text = 'Datum: ';
            app.Label_357 = uilabel(app.GridGenerationTab);
            app.Label_357.Text = 'Datum: ';
            app.UIAxes = uiaxes(app.GridGenerationTab);
            title(app.UIAxes, 'Geographical Grid')
            xlabel(app.UIAxes, 'E')
            ylabel(app.UIAxes, 'N')
            app.UIAxes_9 = uiaxes(app.GridGenerationTab);
            title(app.UIAxes_9, 'Local Grid')
            xlabel(app.UIAxes_9, 'X')
            ylabel(app.UIAxes_9, 'Y')
            app.OrthophotoGenerationTab = uitab(app.TabGroup5);
            app.OrthophotoGenerationTab.Title = 'Orthophoto Generation';
            app.Label_160 = uilabel(app.OrthophotoGenerationTab);
            app.Label_160.Text = 'Select Grid File';
            app.ListBox_12 = uilistbox(app.OrthophotoGenerationTab);
            app.ListBox_12.Items = {};
            app.ListBox_12.ValueChangedFcn = createCallbackFcn(app, @ListBox_12ValueChanged, true);
            app.ListBox_12.Value = {};
            app.Label_161 = uilabel(app.OrthophotoGenerationTab);
            app.Label_161.Text = 'Select Frame Set';
            app.ListBox_13 = uilistbox(app.OrthophotoGenerationTab);
            app.ListBox_13.Items = {};
            app.ListBox_13.ValueChangedFcn = createCallbackFcn(app, @ListBox_13ValueChanged, true);
            app.ListBox_13.Value = {};
            app.Label_162 = uilabel(app.OrthophotoGenerationTab);
            app.Label_162.Text = 'Select Solutions File';
            app.ListBox_14 = uilistbox(app.OrthophotoGenerationTab);
            app.ListBox_14.Items = {};
            app.ListBox_14.ValueChangedFcn = createCallbackFcn(app, @ListBox_14ValueChanged, true);
            app.ListBox_14.Value = {};
            app.Label_163 = uilabel(app.OrthophotoGenerationTab);
            app.Label_163.Text = '____________________________________________________';
            app.Label_164 = uilabel(app.OrthophotoGenerationTab);
            app.Label_164.Text = 'Enter tide + Select Frame Set, Solution and Grid file and continue to ''Output''';
            app.Label_165 = uilabel(app.OrthophotoGenerationTab);
            app.Label_165.Text = '____________________________________________________';
            app.Label_166 = uilabel(app.OrthophotoGenerationTab);
            app.Label_166.Text = 'Enter Tidal Level';
            app.Label_167 = uilabel(app.OrthophotoGenerationTab);
            app.Label_167.Text = 'Relative to Mean Waterl Level';
            app.Label_170 = uilabel(app.OrthophotoGenerationTab);
            app.Label_170.Text = 'Output';
            app.OutputDirectoryNameEditFieldLabel = uilabel(app.OrthophotoGenerationTab);
            app.OutputDirectoryNameEditFieldLabel.Text = 'Output Directory Name';
            app.OutputDirectoryNameEditField = uieditfield(app.OrthophotoGenerationTab, 'text');
            app.OutputDirectoryNameEditField.ValueChangedFcn = createCallbackFcn(app, @OutputDirectoryNameEditFieldValueChanged, true);
            app.Label_172 = uilabel(app.OrthophotoGenerationTab);
            app.Label_172.Text = 'Processing Status';
            app.UITable3_3 = uitable(app.OrthophotoGenerationTab);
            app.UITable3_3.ColumnName = {'Frames Processed'; 'Total Frames'};
            app.UITable3_3.RowName = {};
            app.Label_173 = uilabel(app.OrthophotoGenerationTab);
            app.Label_173.Text = '____________________________________________________';
            app.StartButton = uibutton(app.OrthophotoGenerationTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.Label_171 = uilabel(app.OrthophotoGenerationTab);
            app.Label_171.Text = 'When complete continue to ''Orthophoto Movie'' or ''cBathy tab''';
            app.Label_174 = uilabel(app.OrthophotoGenerationTab);
            app.Label_174.Text = 'Note: Function will output both local and geographical grids';
            app.EditField = uieditfield(app.OrthophotoGenerationTab, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.Lamp_15 = uilamp(app.OrthophotoGenerationTab);
            app.Label_317 = uilabel(app.OrthophotoGenerationTab);
            app.Label_317.Text = '[]';
            app.UIAxes_10 = uiaxes(app.OrthophotoGenerationTab);
            title(app.UIAxes_10, 'Timex Build')
            xlabel(app.UIAxes_10, 'X')
            ylabel(app.UIAxes_10, 'Y')
            app.UIAxes_11 = uiaxes(app.OrthophotoGenerationTab);
            title(app.UIAxes_11, 'Brightest Build')
            xlabel(app.UIAxes_11, 'X')
            ylabel(app.UIAxes_11, 'Y')
            app.OrthophotoMovieTab = uitab(app.TabGroup5);
            app.OrthophotoMovieTab.Title = 'Orthophoto Movie';
            app.Label_175 = uilabel(app.OrthophotoMovieTab);
            app.Label_175.Text = 'Select Orthophoto Set';
            app.ListBox_15 = uilistbox(app.OrthophotoMovieTab);
            app.ListBox_15.Items = {};
            app.ListBox_15.ValueChangedFcn = createCallbackFcn(app, @ListBox_15ValueChanged, true);
            app.ListBox_15.Value = {};
            app.Label_176 = uilabel(app.OrthophotoMovieTab);
            app.Label_176.Text = '____________________________________________________';
            app.CoordinateSystemButtonGroup = uibuttongroup(app.OrthophotoMovieTab);
            app.CoordinateSystemButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CoordinateSystemButtonGroupSelectionChanged, true);
            app.CoordinateSystemButtonGroup.Title = 'Coordinate System';
            app.LocalButton = uiradiobutton(app.CoordinateSystemButtonGroup);
            app.LocalButton.Text = 'Local';
            app.LocalButton.Value = true;
            app.GeographicButton = uiradiobutton(app.CoordinateSystemButtonGroup);
            app.GeographicButton.Text = 'Geographic';
            app.FrameTypeButtonGroup = uibuttongroup(app.OrthophotoMovieTab);
            app.FrameTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FrameTypeButtonGroupSelectionChanged, true);
            app.FrameTypeButtonGroup.Title = 'Frame Type';
            app.AxesButton = uiradiobutton(app.FrameTypeButtonGroup);
            app.AxesButton.Text = 'Axes';
            app.AxesButton.Value = true;
            app.NoAxesButton = uiradiobutton(app.FrameTypeButtonGroup);
            app.NoAxesButton.Text = 'No-Axes';
            app.Label_177 = uilabel(app.OrthophotoMovieTab);
            app.Label_177.Text = 'Select Movie Parameters';
            app.Label_178 = uilabel(app.OrthophotoMovieTab);
            app.Label_178.Text = '____________________________________________________';
            app.PlaybackSpeedListBoxLabel = uilabel(app.OrthophotoMovieTab);
            app.PlaybackSpeedListBoxLabel.Text = 'Playback Speed';
            app.PlaybackSpeedListBox = uilistbox(app.OrthophotoMovieTab);
            app.PlaybackSpeedListBox.Items = {'RealTime', '2x', '5x', '10x', '20x'};
            app.PlaybackSpeedListBox.ValueChangedFcn = createCallbackFcn(app, @PlaybackSpeedListBoxValueChanged, true);
            app.PlaybackSpeedListBox.Value = 'RealTime';
            app.QualityAffectsMovieSizeListBoxLabel = uilabel(app.OrthophotoMovieTab);
            app.QualityAffectsMovieSizeListBoxLabel.Text = 'Quality (Affects Movie Size)';
            app.QualityAffectsMovieSizeListBox = uilistbox(app.OrthophotoMovieTab);
            app.QualityAffectsMovieSizeListBox.Items = {};
            app.QualityAffectsMovieSizeListBox.ValueChangedFcn = createCallbackFcn(app, @QualityAffectsMovieSizeListBoxValueChanged, true);
            app.QualityAffectsMovieSizeListBox.Value = {};
            app.Label_179 = uilabel(app.OrthophotoMovieTab);
            app.Label_179.Text = 'Saving Movie';
            app.Label_180 = uilabel(app.OrthophotoMovieTab);
            app.Label_180.Text = '____________________________________________________';
            app.Label_181 = uilabel(app.OrthophotoMovieTab);
            app.Label_181.Text = 'No Spaces';
            app.FilenameEditField_9Label = uilabel(app.OrthophotoMovieTab);
            app.FilenameEditField_9Label.Text = 'Filename';
            app.FilenameEditField_9 = uieditfield(app.OrthophotoMovieTab, 'text');
            app.FilenameEditField_9.ValueChangedFcn = createCallbackFcn(app, @FilenameEditField_9ValueChanged, true);
            app.StartButton_2 = uibutton(app.OrthophotoMovieTab, 'push');
            app.StartButton_2.ButtonPushedFcn = createCallbackFcn(app, @StartButton_2Pushed, true);
            app.StartButton_2.Text = 'Start';
            app.Label_182 = uilabel(app.OrthophotoMovieTab);
            app.Label_182.Text = 'Select Orthophoto Set, Coordinate System and Frame Type and continue to';
            app.Label_183 = uilabel(app.OrthophotoMovieTab);
            app.Label_183.Text = '''Select Movie Parameters''';
            app.Label_184 = uilabel(app.OrthophotoMovieTab);
            app.Label_184.Text = 'Select Playback Speed and Quality and continue to ''Saving Movie''';
            app.Label_185 = uilabel(app.OrthophotoMovieTab);
            app.Label_185.Text = 'Movie will be saved in Selected Orthophoto Set Folder';
            app.Label_186 = uilabel(app.OrthophotoMovieTab);
            app.Label_186.Text = 'Processing Status';
            app.UITable3_4 = uitable(app.OrthophotoMovieTab);
            app.UITable3_4.ColumnName = {'Frames Processed'; 'Total Frames'};
            app.UITable3_4.RowName = {};
            app.Lamp_16 = uilamp(app.OrthophotoMovieTab);
            app.UIAxes_12 = uiaxes(app.OrthophotoMovieTab);
            app.UIAxes_12.XTick = [];
            app.UIAxes_12.YTick = [];
            app.cBathyTab = uitab(app.TabGroup);
            app.cBathyTab.Title = 'cBathy';
            app.TabGroup6 = uitabgroup(app.cBathyTab);
            app.TabGroup6.SelectionChangedFcn = createCallbackFcn(app, @TabGroup6SelectionChanged, true);
            app.TimestackGenerationTab = uitab(app.TabGroup6);
            app.TimestackGenerationTab.Title = 'Timestack Generation';
            app.Label_187 = uilabel(app.TimestackGenerationTab);
            app.Label_187.Text = 'Select Local Orthophoto Set';
            app.ListBox_16 = uilistbox(app.TimestackGenerationTab);
            app.ListBox_16.Items = {};
            app.ListBox_16.ValueChangedFcn = createCallbackFcn(app, @ListBox_16ValueChanged, true);
            app.ListBox_16.Value = {};
            app.Label_188 = uilabel(app.TimestackGenerationTab);
            app.Label_188.Text = '____________________________________________________';
            app.Label_189 = uilabel(app.TimestackGenerationTab);
            app.Label_189.Text = '____________________________________________________';
            app.Label_190 = uilabel(app.TimestackGenerationTab);
            app.Label_190.Text = 'When orthophoto set is selected, continue to ''Select Resoltion''';
            app.Label_191 = uilabel(app.TimestackGenerationTab);
            app.Label_191.Text = 'Select Resolution';
            app.Label_192 = uilabel(app.TimestackGenerationTab);
            app.Label_192.Text = 'Resolution is limited by grid resolution of orthoset. If MATLAB runs out of memory  ';
            app.Label_193 = uilabel(app.TimestackGenerationTab);
            app.Label_193.Text = ' when trying to build timestack, try decreasing resolution';
            app.ListBox_18 = uilistbox(app.TimestackGenerationTab);
            app.ListBox_18.Items = {};
            app.ListBox_18.ValueChangedFcn = createCallbackFcn(app, @ListBox_18ValueChanged, true);
            app.ListBox_18.Value = {};
            app.Label_194 = uilabel(app.TimestackGenerationTab);
            app.Label_194.Text = 'High Detail';
            app.Label_195 = uilabel(app.TimestackGenerationTab);
            app.Label_195.Text = '_';
            app.Label_196 = uilabel(app.TimestackGenerationTab);
            app.Label_196.Text = '_';
            app.Label_197 = uilabel(app.TimestackGenerationTab);
            app.Label_197.Text = 'Low Detail';
            app.Label_198 = uilabel(app.TimestackGenerationTab);
            app.Label_198.Text = '____________________________________________________';
            app.Label_199 = uilabel(app.TimestackGenerationTab);
            app.Label_199.Text = 'When complete continue to ''Build Timestack''';
            app.Label_200 = uilabel(app.TimestackGenerationTab);
            app.Label_200.Text = 'Build Timestack';
            app.Label_201 = uilabel(app.TimestackGenerationTab);
            app.Label_201.Text = 'When saved, continue to  ''Orthophoto Generation''  or ''cBathy'' Tab ';
            app.SaveTimestackButton = uibutton(app.TimestackGenerationTab, 'push');
            app.SaveTimestackButton.ButtonPushedFcn = createCallbackFcn(app, @SaveTimestackButtonPushed, true);
            app.SaveTimestackButton.Text = 'Save Timestack';
            app.FilenameEditField_10Label = uilabel(app.TimestackGenerationTab);
            app.FilenameEditField_10Label.Text = 'File name';
            app.FilenameEditField_10 = uieditfield(app.TimestackGenerationTab, 'text');
            app.FilenameEditField_10.ValueChangedFcn = createCallbackFcn(app, @FilenameEditField_10ValueChanged, true);
            app.BuildTimestackButton = uibutton(app.TimestackGenerationTab, 'push');
            app.BuildTimestackButton.ButtonPushedFcn = createCallbackFcn(app, @BuildTimestackButtonPushed, true);
            app.BuildTimestackButton.Text = 'Build Timestack';
            app.Label_202 = uilabel(app.TimestackGenerationTab);
            app.Label_202.Text = '____________________________________________________';
            app.Label_203 = uilabel(app.TimestackGenerationTab);
            app.Label_203.Text = 'Saving Timestack';
            app.Label_204 = uilabel(app.TimestackGenerationTab);
            app.Label_204.Text = 'When complete continue to ''Saving Timestack'' or change Resolution if Error';
            app.Label_205 = uilabel(app.TimestackGenerationTab);
            app.Label_205.Text = 'Processing Status';
            app.UITable3_5 = uitable(app.TimestackGenerationTab);
            app.UITable3_5.ColumnName = {'Frames Processed'; 'Total Frames'; ''};
            app.UITable3_5.RowName = {};
            app.HelpButton_7 = uibutton(app.TimestackGenerationTab, 'push');
            app.HelpButton_7.ButtonPushedFcn = createCallbackFcn(app, @HelpButton_7Pushed, true);
            app.HelpButton_7.Text = 'Help';
            app.FilenameEditField_10Label_2 = uilabel(app.TimestackGenerationTab);
            app.FilenameEditField_10Label_2.Text = 'No Spaces';
            app.Label_206 = uilabel(app.TimestackGenerationTab);
            app.Label_206.Text = '____________________________________________________';
            app.Label_207 = uilabel(app.TimestackGenerationTab);
            app.Label_207.Text = 'Enhance Contrast';
            app.Label_208 = uilabel(app.TimestackGenerationTab);
            app.Label_208.Text = 'After selection continue to ''Build Timestack''';
            app.NumberofTimestoEnhanceButtonGroup = uibuttongroup(app.TimestackGenerationTab);
            app.NumberofTimestoEnhanceButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @NumberofTimestoEnhanceButtonGroupSelectionChanged, true);
            app.NumberofTimestoEnhanceButtonGroup.Title = 'Number of Times to Enhance';
            app.Button = uiradiobutton(app.NumberofTimestoEnhanceButtonGroup);
            app.Button.Text = '0';
            app.Button.Value = true;
            app.Button_2 = uiradiobutton(app.NumberofTimestoEnhanceButtonGroup);
            app.Button_2.Text = '1';
            app.Button_3 = uiradiobutton(app.NumberofTimestoEnhanceButtonGroup);
            app.Button_3.Text = '2';
            app.Button_4 = uiradiobutton(app.NumberofTimestoEnhanceButtonGroup);
            app.Button_4.Text = '3';
            app.Lamp_17 = uilamp(app.TimestackGenerationTab);
            app.Lamp_18 = uilamp(app.TimestackGenerationTab);
            app.UIAxes_13 = uiaxes(app.TimestackGenerationTab);
            title(app.UIAxes_13, 'Example Resolution/Contrast')
            xlabel(app.UIAxes_13, 'X')
            ylabel(app.UIAxes_13, 'Y')
            app.UIAxes_13.XTick = [0 0.5 1];
            app.RuncBathyTab = uitab(app.TabGroup6);
            app.RuncBathyTab.Title = 'Run cBathy';
            app.Label_209 = uilabel(app.RuncBathyTab);
            app.Label_209.Text = 'Select Timestack';
            app.ListBox_19 = uilistbox(app.RuncBathyTab);
            app.ListBox_19.Items = {};
            app.ListBox_19.ValueChangedFcn = createCallbackFcn(app, @ListBox_19ValueChanged, true);
            app.ListBox_19.Value = {};
            app.Label_210 = uilabel(app.RuncBathyTab);
            app.Label_210.Text = '____________________________________________________';
            app.Label_211 = uilabel(app.RuncBathyTab);
            app.Label_211.Text = '____________________________________________________';
            app.Label_212 = uilabel(app.RuncBathyTab);
            app.Label_212.Text = 'When timestack set is selected, continue to ''Domain Parameters''';
            app.Label_213 = uilabel(app.RuncBathyTab);
            app.Label_213.Text = 'Domain Parameters';
            app.Label_215 = uilabel(app.RuncBathyTab);
            app.Label_215.Text = 'Solution Points';
            app.ListBox_20 = uilistbox(app.RuncBathyTab);
            app.ListBox_20.Items = {};
            app.ListBox_20.ValueChangedFcn = createCallbackFcn(app, @ListBox_20ValueChanged, true);
            app.ListBox_20.Value = {};
            app.Label_218 = uilabel(app.RuncBathyTab);
            app.Label_218.Text = 'Cross-shore Resolution';
            app.ListBox_21 = uilistbox(app.RuncBathyTab);
            app.ListBox_21.Items = {};
            app.ListBox_21.ValueChangedFcn = createCallbackFcn(app, @ListBox_21ValueChanged, true);
            app.ListBox_21.Value = {};
            app.Label_219 = uilabel(app.RuncBathyTab);
            app.Label_219.Text = 'Alongshore Resolution';
            app.HelpButton_9 = uibutton(app.RuncBathyTab, 'push');
            app.HelpButton_9.ButtonPushedFcn = createCallbackFcn(app, @HelpButton_9Pushed, true);
            app.HelpButton_9.Text = 'Help';
            app.Label_220 = uilabel(app.RuncBathyTab);
            app.Label_220.Text = 'Analysis Area for Each Solution Point';
            app.ListBox_22 = uilistbox(app.RuncBathyTab);
            app.ListBox_22.Items = {};
            app.ListBox_22.ValueChangedFcn = createCallbackFcn(app, @ListBox_22ValueChanged, true);
            app.ListBox_22.Value = {};
            app.Label_222 = uilabel(app.RuncBathyTab);
            app.Label_222.Text = 'Cross-shore Extent';
            app.ListBox_23 = uilistbox(app.RuncBathyTab);
            app.ListBox_23.Items = {};
            app.ListBox_23.ValueChangedFcn = createCallbackFcn(app, @ListBox_23ValueChanged, true);
            app.ListBox_23.Value = {};
            app.Label_223 = uilabel(app.RuncBathyTab);
            app.Label_223.Text = 'Alongshore Extent';
            app.OffshoreSmoothingFactorButtonGroup = uibuttongroup(app.RuncBathyTab);
            app.OffshoreSmoothingFactorButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OffshoreSmoothingFactorButtonGroupSelectionChanged, true);
            app.OffshoreSmoothingFactorButtonGroup.Title = 'Offshore Smoothing Factor';
            app.Button_5 = uiradiobutton(app.OffshoreSmoothingFactorButtonGroup);
            app.Button_5.Text = '1';
            app.Button_5.Value = true;
            app.Button_6 = uiradiobutton(app.OffshoreSmoothingFactorButtonGroup);
            app.Button_6.Text = '2';
            app.Button_7 = uiradiobutton(app.OffshoreSmoothingFactorButtonGroup);
            app.Button_7.Text = '3';
            app.UITable10_3 = uitable(app.RuncBathyTab);
            app.UITable10_3.ColumnName = {'Min'; 'Max'};
            app.UITable10_3.RowName = {'X, Y'};
            app.UITable10_3.ColumnEditable = true;
            app.UITable10_3.CellEditCallback = createCallbackFcn(app, @UITable10_3CellEdit, true);
            app.Label_224 = uilabel(app.RuncBathyTab);
            app.Label_224.Text = 'Total Domain Limits';
            app.Label_225 = uilabel(app.RuncBathyTab);
            app.Label_225.Text = 'Estimated Depth Limits';
            app.UITable10_4 = uitable(app.RuncBathyTab);
            app.UITable10_4.ColumnName = {'Min'; 'Max'};
            app.UITable10_4.RowName = {'Z'};
            app.UITable10_4.ColumnEditable = true;
            app.UITable10_4.CellEditCallback = createCallbackFcn(app, @UITable10_4CellEdit, true);
            app.Label_226 = uilabel(app.RuncBathyTab);
            app.Label_226.Text = 'Solution Frequencies';
            app.Label_227 = uilabel(app.RuncBathyTab);
            app.Label_227.Text = ' Estimated Depth Limits';
            app.Label_228 = uilabel(app.RuncBathyTab);
            app.Label_228.Text = 'When complete, continue to ''Solution Frequencies''';
            app.Label_229 = uilabel(app.RuncBathyTab);
            app.Label_229.Text = '____________________________________________________';
            app.Label_230 = uilabel(app.RuncBathyTab);
            app.Label_230.Text = 'Start cBathy';
            app.StartcBathyButton = uibutton(app.RuncBathyTab, 'push');
            app.StartcBathyButton.ButtonPushedFcn = createCallbackFcn(app, @StartcBathyButtonPushed, true);
            app.StartcBathyButton.Text = 'Start cBathy';
            app.Label_231 = uilabel(app.RuncBathyTab);
            app.Label_231.Text = 'When complete continue to ''Saving Bathymetry'' ';
            app.Label_232 = uilabel(app.RuncBathyTab);
            app.Label_232.Text = 'Only change if initial results are poor';
            app.Label_234 = uilabel(app.RuncBathyTab);
            app.Label_234.Text = 'When saved, continue to  ''Solution Exploration'' Tab ';
            app.SaveBathymetryButton = uibutton(app.RuncBathyTab, 'push');
            app.SaveBathymetryButton.ButtonPushedFcn = createCallbackFcn(app, @SaveBathymetryButtonPushed, true);
            app.SaveBathymetryButton.Text = 'Save Bathymetry';
            app.Label_236 = uilabel(app.RuncBathyTab);
            app.Label_236.Text = 'Saving Bathymetry';
            app.FilenameEditField_10Label_3 = uilabel(app.RuncBathyTab);
            app.FilenameEditField_10Label_3.Text = 'No Spaces';
            app.FilenameEditField_11Label = uilabel(app.RuncBathyTab);
            app.FilenameEditField_11Label.Text = 'File name';
            app.FilenameEditField_11 = uieditfield(app.RuncBathyTab, 'text');
            app.FilenameEditField_11.ValueChangedFcn = createCallbackFcn(app, @FilenameEditField_11ValueChanged, true);
            app.Label_238 = uilabel(app.RuncBathyTab);
            app.Label_238.Text = '____________________________________________________';
            app.Label_239 = uilabel(app.RuncBathyTab);
            app.Label_239.Text = '____________________________________________________';
            app.Label_241 = uilabel(app.RuncBathyTab);
            app.Label_241.Text = 'Please run with default parameters';
            app.Label_240 = uilabel(app.RuncBathyTab);
            app.Label_240.Text = 'Continue to ''Start cBathy''';
            app.MinimumPeriodsEditFieldLabel = uilabel(app.RuncBathyTab);
            app.MinimumPeriodsEditFieldLabel.Text = 'Minimum Period [s]';
            app.MinimumPeriodsEditField = uieditfield(app.RuncBathyTab, 'numeric');
            app.MinimumPeriodsEditField.ValueChangedFcn = createCallbackFcn(app, @MinimumPeriodsEditFieldValueChanged, true);
            app.MinimumPeriodsEditField.Value = 4;
            app.MaximumPeriodsEditFieldLabel = uilabel(app.RuncBathyTab);
            app.MaximumPeriodsEditFieldLabel.Text = 'Maximum Period [s]';
            app.MaximumPeriodsEditField = uieditfield(app.RuncBathyTab, 'numeric');
            app.MaximumPeriodsEditField.ValueChangedFcn = createCallbackFcn(app, @MaximumPeriodsEditFieldValueChanged, true);
            app.MaximumPeriodsEditField.Value = 18;
            app.NumberofFrequenciesEditFieldLabel = uilabel(app.RuncBathyTab);
            app.NumberofFrequenciesEditFieldLabel.Text = 'Number of Frequencies';
            app.NumberofFrequenciesEditField = uieditfield(app.RuncBathyTab, 'numeric');
            app.NumberofFrequenciesEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofFrequenciesEditFieldValueChanged, true);
            app.NumberofFrequenciesEditField.Value = 10;
            app.Lamp_19 = uilamp(app.RuncBathyTab);
            app.Lamp_20 = uilamp(app.RuncBathyTab);
            app.UIAxes_14 = uiaxes(app.RuncBathyTab);
            title(app.UIAxes_14, 'Example Solution Area')
            xlabel(app.UIAxes_14, 'X')
            ylabel(app.UIAxes_14, 'Y')
            app.SolutionExplorationTab = uitab(app.TabGroup6);
            app.SolutionExplorationTab.Title = 'Solution Exploration';
            app.Label_242 = uilabel(app.SolutionExplorationTab);
            app.Label_242.Text = 'Select cBathy File';
            app.ListBox_24 = uilistbox(app.SolutionExplorationTab);
            app.ListBox_24.Items = {};
            app.ListBox_24.ValueChangedFcn = createCallbackFcn(app, @ListBox_24ValueChanged, true);
            app.ListBox_24.Value = {};
            app.Label_243 = uilabel(app.SolutionExplorationTab);
            app.Label_243.Text = '____________________________________________________';
            app.Label_244 = uilabel(app.SolutionExplorationTab);
            app.Label_244.Text = '____________________________________________________';
            app.Label_245 = uilabel(app.SolutionExplorationTab);
            app.Label_245.Text = 'When file set is selected, continue to ''Plot Results''';
            app.Label_246 = uilabel(app.SolutionExplorationTab);
            app.Label_246.Text = 'Plot Results';
            app.Label_251 = uilabel(app.SolutionExplorationTab);
            app.Label_251.Text = 'When satisfied, continue to ''Export Bathymetry''';
            app.ChooseImagerytoOverlayBathymetryOntopofButtonGroup = uibuttongroup(app.SolutionExplorationTab);
            app.ChooseImagerytoOverlayBathymetryOntopofButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChooseImagerytoOverlayBathymetryOntopofButtonGroupSelectionChanged, true);
            app.ChooseImagerytoOverlayBathymetryOntopofButtonGroup.Title = 'Choose Imagery to Overlay Bathymetry Ontop of ';
            app.NoneButton = uiradiobutton(app.ChooseImagerytoOverlayBathymetryOntopofButtonGroup);
            app.NoneButton.Text = 'None';
            app.NoneButton.Value = true;
            app.SnapshotButton = uiradiobutton(app.ChooseImagerytoOverlayBathymetryOntopofButtonGroup);
            app.SnapshotButton.Text = 'Snapshot';
            app.BrightestButton = uiradiobutton(app.ChooseImagerytoOverlayBathymetryOntopofButtonGroup);
            app.BrightestButton.Text = 'Brightest';
            app.TimexButton = uiradiobutton(app.ChooseImagerytoOverlayBathymetryOntopofButtonGroup);
            app.TimexButton.Text = 'Timex';
            app.OverlaySliderLabel = uilabel(app.SolutionExplorationTab);
            app.OverlaySliderLabel.Text = 'Overlay';
            app.OverlaySlider = uislider(app.SolutionExplorationTab);
            app.OverlaySlider.ValueChangedFcn = createCallbackFcn(app, @OverlaySliderValueChanged, true);
            app.DepthSliderLabel = uilabel(app.SolutionExplorationTab);
            app.DepthSliderLabel.Text = 'Depth';
            app.DepthSlider = uislider(app.SolutionExplorationTab);
            app.DepthSlider.ValueChangedFcn = createCallbackFcn(app, @DepthSliderValueChanged, true);
            app.DepthSlider_2Label = uilabel(app.SolutionExplorationTab);
            app.DepthSlider_2Label.Text = 'Depth';
            app.DepthSlider_2 = uislider(app.SolutionExplorationTab);
            app.DepthSlider_2.ValueChangedFcn = createCallbackFcn(app, @DepthSlider_2ValueChanged, true);
            app.OverlaySliderLabel_2 = uilabel(app.SolutionExplorationTab);
            app.OverlaySliderLabel_2.Text = 'Transpar.';
            app.OverlaySliderLabel_3 = uilabel(app.SolutionExplorationTab);
            app.OverlaySliderLabel_3.Text = 'Max';
            app.OverlaySliderLabel_4 = uilabel(app.SolutionExplorationTab);
            app.OverlaySliderLabel_4.Text = 'Min';
            app.OverlaySlider_2Label = uilabel(app.SolutionExplorationTab);
            app.OverlaySlider_2Label.Text = 'Overlay';
            app.OverlaySlider_2 = uislider(app.SolutionExplorationTab);
            app.OverlaySlider_2.ValueChangedFcn = createCallbackFcn(app, @OverlaySlider_2ValueChanged, true);
            app.ErrorSliderLabel = uilabel(app.SolutionExplorationTab);
            app.ErrorSliderLabel.Text = 'Error';
            app.ErrorSlider = uislider(app.SolutionExplorationTab);
            app.ErrorSlider.ValueChangedFcn = createCallbackFcn(app, @ErrorSliderValueChanged, true);
            app.ErrorSlider_2Label = uilabel(app.SolutionExplorationTab);
            app.ErrorSlider_2Label.Text = 'Error';
            app.ErrorSlider_2 = uislider(app.SolutionExplorationTab);
            app.ErrorSlider_2.ValueChangedFcn = createCallbackFcn(app, @ErrorSlider_2ValueChanged, true);
            app.OverlaySliderLabel_5 = uilabel(app.SolutionExplorationTab);
            app.OverlaySliderLabel_5.Text = 'Transpar.';
            app.OverlaySliderLabel_6 = uilabel(app.SolutionExplorationTab);
            app.OverlaySliderLabel_6.Text = 'Min';
            app.OverlaySliderLabel_7 = uilabel(app.SolutionExplorationTab);
            app.OverlaySliderLabel_7.Text = 'Max';
            app.Label_253 = uilabel(app.SolutionExplorationTab);
            app.Label_253.Text = '';
            app.Label_254 = uilabel(app.SolutionExplorationTab);
            app.Label_254.Text = '';
            app.Label_255 = uilabel(app.SolutionExplorationTab);
            app.Label_255.Text = '';
            app.EnterYCoordinatetoPlotCrossshoreprofilebelowEditFieldLabel = uilabel(app.SolutionExplorationTab);
            app.EnterYCoordinatetoPlotCrossshoreprofilebelowEditFieldLabel.Text = 'Enter Y-Coordinate to Plot Cross-shore profile below';
            app.EnterYCoordinatetoPlotCrossshoreprofilebelowEditField = uieditfield(app.SolutionExplorationTab, 'numeric');
            app.EnterYCoordinatetoPlotCrossshoreprofilebelowEditField.ValueChangedFcn = createCallbackFcn(app, @EnterYCoordinatetoPlotCrossshoreprofilebelowEditFieldValueChanged, true);
            app.Label_318 = uilabel(app.SolutionExplorationTab);
            app.Label_318.Text = '[]';
            app.Label_319 = uilabel(app.SolutionExplorationTab);
            app.Label_319.Text = '[]';
            app.Label_320 = uilabel(app.SolutionExplorationTab);
            app.Label_320.Text = '[%]';
            app.Label_321 = uilabel(app.SolutionExplorationTab);
            app.Label_321.Text = '[]';
            app.Label_322 = uilabel(app.SolutionExplorationTab);
            app.Label_322.Text = '[]';
            app.Label_323 = uilabel(app.SolutionExplorationTab);
            app.Label_323.Text = '[%]';
            app.Label_324 = uilabel(app.SolutionExplorationTab);
            app.Label_324.Text = '[]';
            app.UIAxes2 = uiaxes(app.SolutionExplorationTab);
            title(app.UIAxes2, 'Estimated Depth')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes2_2 = uiaxes(app.SolutionExplorationTab);
            title(app.UIAxes2_2, 'Estimated Depth Error ')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            app.UIAxes5 = uiaxes(app.SolutionExplorationTab);
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Z')
            app.ExportBathymetryTab_2 = uitab(app.TabGroup6);
            app.ExportBathymetryTab_2.Title = 'Export Bathymetry';
            app.Label_256 = uilabel(app.ExportBathymetryTab_2);
            app.Label_256.Text = 'Select cBathy File';
            app.ListBox_27 = uilistbox(app.ExportBathymetryTab_2);
            app.ListBox_27.Items = {};
            app.ListBox_27.ValueChangedFcn = createCallbackFcn(app, @ListBox_27ValueChanged, true);
            app.ListBox_27.Value = {};
            app.Label_257 = uilabel(app.ExportBathymetryTab_2);
            app.Label_257.Text = '____________________________________________________';
            app.Label_258 = uilabel(app.ExportBathymetryTab_2);
            app.Label_258.Text = '____________________________________________________';
            app.Label_259 = uilabel(app.ExportBathymetryTab_2);
            app.Label_259.Text = 'When file set is selected, continue to ''Filter Data';
            app.Label_260 = uilabel(app.ExportBathymetryTab_2);
            app.Label_260.Text = 'Filter Data';
            app.HelpButton_12 = uibutton(app.ExportBathymetryTab_2, 'push');
            app.HelpButton_12.ButtonPushedFcn = createCallbackFcn(app, @HelpButton_12Pushed, true);
            app.HelpButton_12.Text = 'Help';
            app.MaximumErrorEditFieldLabel = uilabel(app.ExportBathymetryTab_2);
            app.MaximumErrorEditFieldLabel.Text = 'Maximum Error';
            app.MaximumErrorEditField = uieditfield(app.ExportBathymetryTab_2, 'numeric');
            app.MaximumErrorEditField.ValueChangedFcn = createCallbackFcn(app, @MaximumErrorEditFieldValueChanged, true);
            app.Slider = uislider(app.ExportBathymetryTab_2);
            app.Slider.Limits = [0 10];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.XSmoothingDistanceEditFieldLabel = uilabel(app.ExportBathymetryTab_2);
            app.XSmoothingDistanceEditFieldLabel.Text = 'X Smoothing Distance';
            app.XSmoothingDistanceEditField = uieditfield(app.ExportBathymetryTab_2, 'numeric');
            app.XSmoothingDistanceEditField.ValueChangedFcn = createCallbackFcn(app, @XSmoothingDistanceEditFieldValueChanged, true);
            app.Slider_2 = uislider(app.ExportBathymetryTab_2);
            app.Slider_2.Limits = [0 300];
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Slider_3 = uislider(app.ExportBathymetryTab_2);
            app.Slider_3.Limits = [0 300];
            app.Slider_3.ValueChangedFcn = createCallbackFcn(app, @Slider_3ValueChanged, true);
            app.YSmoothingDistanceEditFieldLabel = uilabel(app.ExportBathymetryTab_2);
            app.YSmoothingDistanceEditFieldLabel.Text = 'Y Smoothing Distance';
            app.YSmoothingDistanceEditField = uieditfield(app.ExportBathymetryTab_2, 'numeric');
            app.YSmoothingDistanceEditField.ValueChangedFcn = createCallbackFcn(app, @YSmoothingDistanceEditFieldValueChanged, true);
            app.Label_261 = uilabel(app.ExportBathymetryTab_2);
            app.Label_261.Text = 'When filtering parameters are selected continue to ''Export Output''';
            app.Label_262 = uilabel(app.ExportBathymetryTab_2);
            app.Label_262.Text = '____________________________________________________';
            app.Label_263 = uilabel(app.ExportBathymetryTab_2);
            app.Label_263.Text = 'Export Output';
            app.Label_264 = uilabel(app.ExportBathymetryTab_2);
            app.Label_264.Text = 'No Spaces';
            app.FoldernameEditFieldLabel = uilabel(app.ExportBathymetryTab_2);
            app.FoldernameEditFieldLabel.Text = 'Folder name';
            app.FoldernameEditField = uieditfield(app.ExportBathymetryTab_2, 'text');
            app.FoldernameEditField.ValueChangedFcn = createCallbackFcn(app, @FoldernameEditFieldValueChanged, true);
            app.ChooseImagerytoOverlayOntopofButtonGroup_3 = uibuttongroup(app.ExportBathymetryTab_2);
            app.ChooseImagerytoOverlayOntopofButtonGroup_3.SelectionChangedFcn = createCallbackFcn(app, @ChooseImagerytoOverlayOntopofButtonGroup_3SelectionChanged, true);
            app.ChooseImagerytoOverlayOntopofButtonGroup_3.Title = 'Choose Imagery to Overlay Ontop of, selection will be exported as well';
            app.NoneButton_3 = uiradiobutton(app.ChooseImagerytoOverlayOntopofButtonGroup_3);
            app.NoneButton_3.Text = 'None';
            app.NoneButton_3.Value = true;
            app.SnapshotButton_3 = uiradiobutton(app.ChooseImagerytoOverlayOntopofButtonGroup_3);
            app.SnapshotButton_3.Text = 'Snapshot';
            app.BrightestButton_3 = uiradiobutton(app.ChooseImagerytoOverlayOntopofButtonGroup_3);
            app.BrightestButton_3.Text = 'Brightest';
            app.TimexButton_3 = uiradiobutton(app.ChooseImagerytoOverlayOntopofButtonGroup_3);
            app.TimexButton_3.Text = 'Timex';
            app.ExportOutputButton = uibutton(app.ExportBathymetryTab_2, 'push');
            app.ExportOutputButton.ButtonPushedFcn = createCallbackFcn(app, @ExportOutputButtonPushed, true);
            app.ExportOutputButton.Text = 'Export Output';
            app.Label_265 = uilabel(app.ExportBathymetryTab_2);
            app.Label_265.Text = 'When complete continue to ''Parametric Beach Tool'' for further analysis';
            app.OverlaySlider_3Label = uilabel(app.ExportBathymetryTab_2);
            app.OverlaySlider_3Label.Text = 'Overlay';
            app.OverlaySlider_3 = uislider(app.ExportBathymetryTab_2);
            app.OverlaySlider_3.ValueChangedFcn = createCallbackFcn(app, @OverlaySlider_3ValueChanged, true);
            app.OverlaySlider_3Label_2 = uilabel(app.ExportBathymetryTab_2);
            app.OverlaySlider_3Label_2.Text = 'Transparency';
            app.UITable10_5 = uitable(app.ExportBathymetryTab_2);
            app.UITable10_5.ColumnName = {'Max'; 'Min'};
            app.UITable10_5.RowName = {'Depth'};
            app.UITable10_5.ColumnEditable = true;
            app.UITable10_5.CellEditCallback = createCallbackFcn(app, @UITable10_5CellEdit, true);
            app.Label_266 = uilabel(app.ExportBathymetryTab_2);
            app.Label_266.Text = 'Depth';
            app.Label_267 = uilabel(app.ExportBathymetryTab_2);
            app.Label_267.Text = 'Limits';
            app.EnterYCoordinatetoPlotCrossshoreprofilebelowEditFieldLabel_2 = uilabel(app.ExportBathymetryTab_2);
            app.EnterYCoordinatetoPlotCrossshoreprofilebelowEditFieldLabel_2.Text = 'Enter Y-Coordinate to Plot Cross-shore profile ';
            app.EnterYCoordinatetoPlotCrossshoreprofilebelowEditField_2 = uieditfield(app.ExportBathymetryTab_2, 'numeric');
            app.EnterYCoordinatetoPlotCrossshoreprofilebelowEditField_2.ValueChangedFcn = createCallbackFcn(app, @EnterYCoordinatetoPlotCrossshoreprofilebelowEditField_2ValueChanged, true);
            app.Lamp_21 = uilamp(app.ExportBathymetryTab_2);
            app.Label_325 = uilabel(app.ExportBathymetryTab_2);
            app.Label_325.Text = '[]';
            app.Label_327 = uilabel(app.ExportBathymetryTab_2);
            app.Label_327.Text = '[]';
            app.Label_328 = uilabel(app.ExportBathymetryTab_2);
            app.Label_328.Text = '[]';
            app.Label_329 = uilabel(app.ExportBathymetryTab_2);
            app.Label_329.Text = '[]';
            app.Label_330 = uilabel(app.ExportBathymetryTab_2);
            app.Label_330.Text = '[]';
            app.Label_331 = uilabel(app.ExportBathymetryTab_2);
            app.Label_331.Text = '[]';
            app.Label_332 = uilabel(app.ExportBathymetryTab_2);
            app.Label_332.Text = '[]';
            app.Label_333 = uilabel(app.ExportBathymetryTab_2);
            app.Label_333.Text = '[%]';
            app.UIAxes2_3 = uiaxes(app.ExportBathymetryTab_2);
            title(app.UIAxes2_3, 'Raw Bathymetry')
            xlabel(app.UIAxes2_3, 'X')
            ylabel(app.UIAxes2_3, 'Y')
            app.UIAxes2_4 = uiaxes(app.ExportBathymetryTab_2);
            title(app.UIAxes2_4, 'Filtered Bathymetry')
            xlabel(app.UIAxes2_4, 'X')
            ylabel(app.UIAxes2_4, 'Y')
            app.UIAxes6 = uiaxes(app.ExportBathymetryTab_2);
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Z')
            app.ParametricBeachToolTab = uitab(app.TabGroup);
            app.ParametricBeachToolTab.Title = 'Parametric Beach Tool';
            app.TabGroup7 = uitabgroup(app.ParametricBeachToolTab);
            app.TabGroup7.SelectionChangedFcn = createCallbackFcn(app, @TabGroup7SelectionChanged, true);
            app.RunPBToolTab = uitab(app.TabGroup7);
            app.RunPBToolTab.Title = 'Run PB Tool';
            app.Label_268 = uilabel(app.RunPBToolTab);
            app.Label_268.Text = 'Select Orthophoto Set';
            app.ListBox_28 = uilistbox(app.RunPBToolTab);
            app.ListBox_28.Items = {};
            app.ListBox_28.ValueChangedFcn = createCallbackFcn(app, @ListBox_28ValueChanged, true);
            app.ListBox_28.Value = {};
            app.Label_269 = uilabel(app.RunPBToolTab);
            app.Label_269.Text = 'Select cBathy File (Optional)';
            app.ListBox_29 = uilistbox(app.RunPBToolTab);
            app.ListBox_29.Items = {};
            app.ListBox_29.ValueChangedFcn = createCallbackFcn(app, @ListBox_29ValueChanged, true);
            app.ListBox_29.Value = {};
            app.Label_270 = uilabel(app.RunPBToolTab);
            app.Label_270.Text = '____________________________________________________';
            app.Label_271 = uilabel(app.RunPBToolTab);
            app.Label_271.Text = '____________________________________________________';
            app.Label_272 = uilabel(app.RunPBToolTab);
            app.Label_272.Text = 'After selecting Timex and cBathy (optional) files, continue to ''Feature Identification''';
            app.Label_273 = uilabel(app.RunPBToolTab);
            app.Label_273.Text = 'Feature Identification';
            app.HelpButton_13 = uibutton(app.RunPBToolTab, 'push');
            app.HelpButton_13.ButtonPushedFcn = createCallbackFcn(app, @HelpButton_13Pushed, true);
            app.HelpButton_13.Text = 'Help';
            app.StartClickingShorelineButton = uibutton(app.RunPBToolTab, 'push');
            app.StartClickingShorelineButton.ButtonPushedFcn = createCallbackFcn(app, @StartClickingShorelineButtonPushed, true);
            app.StartClickingShorelineButton.Text = 'Start Clicking Shoreline';
            app.StopClickingShorelineButton = uibutton(app.RunPBToolTab, 'push');
            app.StopClickingShorelineButton.ButtonPushedFcn = createCallbackFcn(app, @StopClickingShorelineButtonPushed, true);
            app.StopClickingShorelineButton.Text = 'Stop Clicking Shoreline';
            app.Label_274 = uilabel(app.RunPBToolTab);
            app.Label_274.Text = 'Shoreline Estimate (Pink)';
            app.ResetShorelineButton = uibutton(app.RunPBToolTab, 'push');
            app.ResetShorelineButton.ButtonPushedFcn = createCallbackFcn(app, @ResetShorelineButtonPushed, true);
            app.ResetShorelineButton.Text = 'Reset Shoreline';
            app.StartClickingSandbarButton = uibutton(app.RunPBToolTab, 'push');
            app.StartClickingSandbarButton.ButtonPushedFcn = createCallbackFcn(app, @StartClickingSandbarButtonPushed, true);
            app.StartClickingSandbarButton.Text = 'Start Clicking Sandbar';
            app.StopClickingSandbarButton = uibutton(app.RunPBToolTab, 'push');
            app.StopClickingSandbarButton.ButtonPushedFcn = createCallbackFcn(app, @StopClickingSandbarButtonPushed, true);
            app.StopClickingSandbarButton.Text = 'Stop Clicking Sandbar';
            app.Label_275 = uilabel(app.RunPBToolTab);
            app.Label_275.Text = 'Sandbar Estimate (Green)';
            app.ResetSandbarButton = uibutton(app.RunPBToolTab, 'push');
            app.ResetSandbarButton.ButtonPushedFcn = createCallbackFcn(app, @ResetSandbarButtonPushed, true);
            app.ResetSandbarButton.Text = 'Reset Sandbar';
            app.Label_276 = uilabel(app.RunPBToolTab);
            app.Label_276.Text = '____________________________________________________';
            app.Label_277 = uilabel(app.RunPBToolTab);
            app.Label_277.Text = 'Parameter Estimates';
            app.Label_278 = uilabel(app.RunPBToolTab);
            app.Label_278.Text = 'After selecting shoreline,sandbar, and offshore limit, continue to ''Parameter Estimates''';
            app.Label_279 = uilabel(app.RunPBToolTab);
            app.Label_279.Text = 'Enter parameters manually below or click ';
            app.HelpButton_14 = uibutton(app.RunPBToolTab, 'push');
            app.HelpButton_14.ButtonPushedFcn = createCallbackFcn(app, @HelpButton_14Pushed, true);
            app.HelpButton_14.Text = 'Help';
            app.UITable11 = uitable(app.RunPBToolTab);
            app.UITable11.ColumnName = {'Depth Offshore'; 'Seaward Depth of Bar'; 'Offshore Slope'; 'Onshore Slope '};
            app.UITable11.RowName = {};
            app.UITable11.ColumnEditable = true;
            app.UITable11.CellEditCallback = createCallbackFcn(app, @UITable11CellEdit, true);
            app.Label_281 = uilabel(app.RunPBToolTab);
            app.Label_281.Text = 'After all 4 parameters are entered, continue to ''Desired Resolution''';
            app.Label_282 = uilabel(app.RunPBToolTab);
            app.Label_282.Text = 'Click features below, do not zoom or change transparency when clicking.';
            app.OverlaySliderLabel_8 = uilabel(app.RunPBToolTab);
            app.OverlaySliderLabel_8.Text = 'Transpar.';
            app.cBathySliderLabel = uilabel(app.RunPBToolTab);
            app.cBathySliderLabel.Text = 'cBathy';
            app.cBathySlider = uislider(app.RunPBToolTab);
            app.cBathySlider.ValueChangedFcn = createCallbackFcn(app, @cBathySliderValueChanged, true);
            app.cBathySlider.Value = 100;
            app.Label_283 = uilabel(app.RunPBToolTab);
            app.Label_283.Text = '____________________________________________________';
            app.Label_284 = uilabel(app.RunPBToolTab);
            app.Label_284.Text = '____________________________________________________';
            app.Label_285 = uilabel(app.RunPBToolTab);
            app.Label_285.Text = 'Run Parametric Beach Tool';
            app.StartPBToolButton = uibutton(app.RunPBToolTab, 'push');
            app.StartPBToolButton.ButtonPushedFcn = createCallbackFcn(app, @StartPBToolButtonPushed, true);
            app.StartPBToolButton.Text = 'Start PB Tool';
            app.Label_286 = uilabel(app.RunPBToolTab);
            app.Label_286.Text = 'At completion continue to ''Saving Bathymetry'' ';
            app.Label_288 = uilabel(app.RunPBToolTab);
            app.Label_288.Text = '____________________________________________________';
            app.Label_289 = uilabel(app.RunPBToolTab);
            app.Label_289.Text = 'When saved, continue to  ''Solution ';
            app.SaveBathymetryButton_2 = uibutton(app.RunPBToolTab, 'push');
            app.SaveBathymetryButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveBathymetryButton_2Pushed, true);
            app.SaveBathymetryButton_2.Text = 'Save Bathymetry';
            app.Label_290 = uilabel(app.RunPBToolTab);
            app.Label_290.Text = 'Saving Bathymetry';
            app.FilenameEditField_10Label_4 = uilabel(app.RunPBToolTab);
            app.FilenameEditField_10Label_4.Text = 'No Spaces';
            app.FilenameEditField_12Label = uilabel(app.RunPBToolTab);
            app.FilenameEditField_12Label.Text = 'File name';
            app.FilenameEditField_12 = uieditfield(app.RunPBToolTab, 'text');
            app.FilenameEditField_12.ValueChangedFcn = createCallbackFcn(app, @FilenameEditField_12ValueChanged, true);
            app.Label_291 = uilabel(app.RunPBToolTab);
            app.Label_291.Text = 'Exploration'' Tab';
            app.Lamp_22 = uilamp(app.RunPBToolTab);
            app.Lamp_23 = uilamp(app.RunPBToolTab);
            app.Lamp_25 = uilamp(app.RunPBToolTab);
            app.Lamp_26 = uilamp(app.RunPBToolTab);
            app.Label_336 = uilabel(app.RunPBToolTab);
            app.Label_336.Text = '[%]';
            app.Label_353 = uilabel(app.RunPBToolTab);
            app.Label_353.Text = '____________________________________________________';
            app.Label_354 = uilabel(app.RunPBToolTab);
            app.Label_354.Text = 'Desired Resolution';
            app.Label_355 = uilabel(app.RunPBToolTab);
            app.Label_355.Text = 'Then continue to ''Run Parametric Beach Tool''';
            app.UITable12 = uitable(app.RunPBToolTab);
            app.UITable12.ColumnName = {'X'; 'Y'};
            app.UITable12.RowName = {};
            app.UITable12.ColumnEditable = true;
            app.UITable12.CellEditCallback = createCallbackFcn(app, @UITable12CellEdit, true);
            app.Label_361 = uilabel(app.RunPBToolTab);
            app.Label_361.Text = 'Extrapolation Extents (Optional)';
            app.EditField_5 = uieditfield(app.RunPBToolTab, 'numeric');
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            app.EditField_6 = uieditfield(app.RunPBToolTab, 'numeric');
            app.EditField_6.ValueChangedFcn = createCallbackFcn(app, @EditField_6ValueChanged, true);
            app.YminLabel = uilabel(app.RunPBToolTab);
            app.YminLabel.Text = ' Y min []';
            app.YmaxLabel = uilabel(app.RunPBToolTab);
            app.YmaxLabel.Text = ' Y max []';
            app.ExtrapolateFeaturesButton = uibutton(app.RunPBToolTab, 'push');
            app.ExtrapolateFeaturesButton.ButtonPushedFcn = createCallbackFcn(app, @ExtrapolateFeaturesButtonPushed, true);
            app.ExtrapolateFeaturesButton.Text = 'Extrapolate Features';
            app.EditField_2 = uieditfield(app.RunPBToolTab, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_4 = uieditfield(app.RunPBToolTab, 'numeric');

            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.Label_334 = uilabel(app.RunPBToolTab);
            app.Label_334.Text = '[]';
            app.Label_335 = uilabel(app.RunPBToolTab);
            app.Label_335.Text = '[]';
            app.Label_362 = uilabel(app.RunPBToolTab);
            app.Label_362.Text = 'Cross-shore Limits (required)';
            app.OffshoreXCoordinateLabel = uilabel(app.RunPBToolTab);
            app.OffshoreXCoordinateLabel.Text = 'Offshore  X Coordinate';
            app.BarSeawardXCoordinateLabel = uilabel(app.RunPBToolTab);
            app.BarSeawardXCoordinateLabel.Text = 'Bar Seaward X Coordinate';
            app.Lamp_28 = uilamp(app.RunPBToolTab);
            app.Label_363 = uilabel(app.RunPBToolTab);
            app.Label_363.Text = ' ''cBathy Estimation'' to estimate parameters';
            app.cBathyEstimationButton = uibutton(app.RunPBToolTab, 'push');
            app.cBathyEstimationButton.ButtonPushedFcn = createCallbackFcn(app, @cBathyEstimationButtonPushed, true);
            app.cBathyEstimationButton.Text = 'cBathy Estimation ';
            app.Lamp_24 = uilamp(app.RunPBToolTab);
            app.UIAxes_15 = uiaxes(app.RunPBToolTab);
            title(app.UIAxes_15, 'Timex')
            xlabel(app.UIAxes_15, 'X')
            ylabel(app.UIAxes_15, 'Y')
            app.SolutionExplorationTab_2 = uitab(app.TabGroup7);
            app.SolutionExplorationTab_2.Title = 'Solution Exploration';
            app.Label_292 = uilabel(app.SolutionExplorationTab_2);
            app.Label_292.Text = 'Select PB Tool File';
            app.ListBox_30 = uilistbox(app.SolutionExplorationTab_2);
            app.ListBox_30.Items = {};
            app.ListBox_30.ValueChangedFcn = createCallbackFcn(app, @ListBox_30ValueChanged, true);
            app.ListBox_30.Value = {};
            app.Label_293 = uilabel(app.SolutionExplorationTab_2);
            app.Label_293.Text = '____________________________________________________';
            app.Label_294 = uilabel(app.SolutionExplorationTab_2);
            app.Label_294.Text = '____________________________________________________';
            app.Label_295 = uilabel(app.SolutionExplorationTab_2);
            app.Label_295.Text = 'When file set is selected, continue to ''Plot Results''';
            app.Label_296 = uilabel(app.SolutionExplorationTab_2);
            app.Label_296.Text = 'Plot Results';
            app.Label_297 = uilabel(app.SolutionExplorationTab_2);
            app.Label_297.Text = 'When satisfied, continue to ''Export Bathymetry''';
            app.ChooseImagerytoOverlayBathymetryOntopofButtonGroup_2 = uibuttongroup(app.SolutionExplorationTab_2);
            app.ChooseImagerytoOverlayBathymetryOntopofButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ChooseImagerytoOverlayBathymetryOntopofButtonGroup_2SelectionChanged, true);
            app.ChooseImagerytoOverlayBathymetryOntopofButtonGroup_2.Title = 'Choose Imagery to Overlay Bathymetry Ontop of ';
            app.NoneButton_4 = uiradiobutton(app.ChooseImagerytoOverlayBathymetryOntopofButtonGroup_2);
            app.NoneButton_4.Text = 'None';
            app.NoneButton_4.Value = true;
            app.SnapshotButton_4 = uiradiobutton(app.ChooseImagerytoOverlayBathymetryOntopofButtonGroup_2);
            app.SnapshotButton_4.Text = 'Snapshot';
            app.BrightestButton_4 = uiradiobutton(app.ChooseImagerytoOverlayBathymetryOntopofButtonGroup_2);
            app.BrightestButton_4.Text = 'Brightest';
            app.TimexButton_4 = uiradiobutton(app.ChooseImagerytoOverlayBathymetryOntopofButtonGroup_2);
            app.TimexButton_4.Text = 'Timex';
            app.OverlaySliderLabel_9 = uilabel(app.SolutionExplorationTab_2);
            app.OverlaySliderLabel_9.Text = 'Transpar.';
            app.OverlaySliderLabel_10 = uilabel(app.SolutionExplorationTab_2);
            app.OverlaySliderLabel_10.Text = 'Max';
            app.OverlaySliderLabel_11 = uilabel(app.SolutionExplorationTab_2);
            app.OverlaySliderLabel_11.Text = 'Min';
            app.OverlaySliderLabel_12 = uilabel(app.SolutionExplorationTab_2);
            app.OverlaySliderLabel_12.Text = 'Transpar.';
            app.OverlaySliderLabel_13 = uilabel(app.SolutionExplorationTab_2);
            app.OverlaySliderLabel_13.Text = 'Max';
            app.Label_298 = uilabel(app.SolutionExplorationTab_2);
            app.Label_298.Text = '';
            app.Label_299 = uilabel(app.SolutionExplorationTab_2);
            app.Label_299.Text = '';
            app.Label_300 = uilabel(app.SolutionExplorationTab_2);
            app.Label_300.Text = '';
            app.OverlaySlider_4Label = uilabel(app.SolutionExplorationTab_2);
            app.OverlaySlider_4Label.Text = 'Overlay';
            app.OverlaySlider_4 = uislider(app.SolutionExplorationTab_2);
            app.OverlaySlider_4.ValueChangedFcn = createCallbackFcn(app, @OverlaySlider_4ValueChanged, true);
            app.DepthSlider_3Label = uilabel(app.SolutionExplorationTab_2);
            app.DepthSlider_3Label.Text = 'Depth';
            app.DepthSlider_3 = uislider(app.SolutionExplorationTab_2);
            app.DepthSlider_3.ValueChangedFcn = createCallbackFcn(app, @DepthSlider_3ValueChanged, true);
            app.DepthSlider_4Label = uilabel(app.SolutionExplorationTab_2);
            app.DepthSlider_4Label.Text = 'Depth';
            app.DepthSlider_4 = uislider(app.SolutionExplorationTab_2);
            app.DepthSlider_4.ValueChangedFcn = createCallbackFcn(app, @DepthSlider_4ValueChanged, true);
            app.OverlaySlider_5Label = uilabel(app.SolutionExplorationTab_2);
            app.OverlaySlider_5Label.Text = 'Overlay';
            app.OverlaySlider_5 = uislider(app.SolutionExplorationTab_2);
            app.OverlaySlider_5.ValueChangedFcn = createCallbackFcn(app, @OverlaySlider_5ValueChanged, true);
            app.DepthSlider_5Label = uilabel(app.SolutionExplorationTab_2);
            app.DepthSlider_5Label.Text = 'Depth';
            app.DepthSlider_5 = uislider(app.SolutionExplorationTab_2);
            app.DepthSlider_5.ValueChangedFcn = createCallbackFcn(app, @DepthSlider_5ValueChanged, true);
            app.DepthSlider_6Label = uilabel(app.SolutionExplorationTab_2);
            app.DepthSlider_6Label.Text = 'Depth';
            app.DepthSlider_6 = uislider(app.SolutionExplorationTab_2);
            app.DepthSlider_6.ValueChangedFcn = createCallbackFcn(app, @DepthSlider_6ValueChanged, true);
            app.EnterYCoordinatetoPlotCrossshoreprofilebelowEditField_3Label = uilabel(app.SolutionExplorationTab_2);
            app.EnterYCoordinatetoPlotCrossshoreprofilebelowEditField_3Label.Text = 'Enter Y-Coordinate to Plot Cross-shore profile below';
            app.EnterYCoordinatetoPlotCrossshoreprofilebelowEditField_3 = uieditfield(app.SolutionExplorationTab_2, 'numeric');
            app.EnterYCoordinatetoPlotCrossshoreprofilebelowEditField_3.ValueChangedFcn = createCallbackFcn(app, @EnterYCoordinatetoPlotCrossshoreprofilebelowEditField_3ValueChanged, true);
            app.Label_301 = uilabel(app.SolutionExplorationTab_2);
            app.Label_301.Text = 'Select cBathy File';
            app.Label_337 = uilabel(app.SolutionExplorationTab_2);
            app.Label_337.Text = '[]';
            app.ListBox_31 = uilistbox(app.SolutionExplorationTab_2);
            app.ListBox_31.Items = {};
            app.ListBox_31.ValueChangedFcn = createCallbackFcn(app, @ListBox_31ValueChanged, true);
            app.ListBox_31.Value = {};
            app.Label_339 = uilabel(app.SolutionExplorationTab_2);
            app.Label_339.Text = '[]';
            app.Label_340 = uilabel(app.SolutionExplorationTab_2);
            app.Label_340.Text = '[%]';
            app.Label_341 = uilabel(app.SolutionExplorationTab_2);
            app.Label_341.Text = '[]';
            app.Label_342 = uilabel(app.SolutionExplorationTab_2);
            app.Label_342.Text = '[]';
            app.Label_343 = uilabel(app.SolutionExplorationTab_2);
            app.Label_343.Text = '[%]';
            app.Label_338 = uilabel(app.SolutionExplorationTab_2);
            app.Label_338.Text = '[]';
            app.MinLabel = uilabel(app.SolutionExplorationTab_2);
            app.MinLabel.Text = 'Min';
            app.UIAxes2_5 = uiaxes(app.SolutionExplorationTab_2);
            title(app.UIAxes2_5, 'Parametric Beach Tool Estimated Depth')
            xlabel(app.UIAxes2_5, 'X')
            ylabel(app.UIAxes2_5, 'Y')
            app.UIAxes2_6 = uiaxes(app.SolutionExplorationTab_2);
            title(app.UIAxes2_6, 'cBathyEstimated Depth')
            xlabel(app.UIAxes2_6, 'X')
            ylabel(app.UIAxes2_6, 'Y')
            app.UIAxes5_2 = uiaxes(app.SolutionExplorationTab_2);
            xlabel(app.UIAxes5_2, 'X')
            ylabel(app.UIAxes5_2, 'Y')
            app.ExportBathymetryTab = uitab(app.TabGroup7);
            app.ExportBathymetryTab.Title = 'Export Bathymetry';
            app.Label_302 = uilabel(app.ExportBathymetryTab);
            app.Label_302.Text = 'Select PB Tool File';
            app.ListBox_32 = uilistbox(app.ExportBathymetryTab);
            app.ListBox_32.Items = {};
            app.ListBox_32.ValueChangedFcn = createCallbackFcn(app, @ListBox_32ValueChanged, true);
            app.ListBox_32.Value = {};
            app.Label_303 = uilabel(app.ExportBathymetryTab);
            app.Label_303.Text = '____________________________________________________';
            app.Label_304 = uilabel(app.ExportBathymetryTab);
            app.Label_304.Text = '____________________________________________________';
            app.Label_305 = uilabel(app.ExportBathymetryTab);
            app.Label_305.Text = 'When file set is selected, continue to ''Blend Data''';
            app.Label_306 = uilabel(app.ExportBathymetryTab);
            app.Label_306.Text = 'Blend Data';
            app.HelpButton_16 = uibutton(app.ExportBathymetryTab, 'push');
            app.HelpButton_16.ButtonPushedFcn = createCallbackFcn(app, @HelpButton_16Pushed, true);
            app.HelpButton_16.Text = 'Help';
            app.Label_307 = uilabel(app.ExportBathymetryTab);
            app.Label_307.Text = 'When filtering parameters are selected continue to ''Export Output''';
            app.Label_308 = uilabel(app.ExportBathymetryTab);
            app.Label_308.Text = '____________________________________________________';
            app.Label_309 = uilabel(app.ExportBathymetryTab);
            app.Label_309.Text = 'Export Output';
            app.Label_310 = uilabel(app.ExportBathymetryTab);
            app.Label_310.Text = 'No Spaces';
            app.ChooseImagerytoOverlayOntopofButtonGroup_4 = uibuttongroup(app.ExportBathymetryTab);
            app.ChooseImagerytoOverlayOntopofButtonGroup_4.SelectionChangedFcn = createCallbackFcn(app, @ChooseImagerytoOverlayOntopofButtonGroup_4SelectionChanged, true);
            app.ChooseImagerytoOverlayOntopofButtonGroup_4.Title = 'Choose Imagery to Overlay Ontop of, selection will be exported as well';
            app.NoneButton_5 = uiradiobutton(app.ChooseImagerytoOverlayOntopofButtonGroup_4);
            app.NoneButton_5.Text = 'None';
            app.NoneButton_5.Value = true;
            app.SnapshotButton_5 = uiradiobutton(app.ChooseImagerytoOverlayOntopofButtonGroup_4);
            app.SnapshotButton_5.Text = 'Snapshot';
            app.BrightestButton_5 = uiradiobutton(app.ChooseImagerytoOverlayOntopofButtonGroup_4);
            app.BrightestButton_5.Text = 'Brightest';
            app.TimexButton_5 = uiradiobutton(app.ChooseImagerytoOverlayOntopofButtonGroup_4);
            app.TimexButton_5.Text = 'Timex';
            app.ExportOutputButton_2 = uibutton(app.ExportBathymetryTab, 'push');
            app.ExportOutputButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExportOutputButton_2Pushed, true);
            app.ExportOutputButton_2.Text = 'Export Output';
            app.Label_311 = uilabel(app.ExportBathymetryTab);
            app.Label_311.Text = 'When complete continue to ''Parametric Beach Tool'' for further analysis';
            app.OverlaySlider_3Label_3 = uilabel(app.ExportBathymetryTab);
            app.OverlaySlider_3Label_3.Text = 'Transparency';
            app.UITable10_6 = uitable(app.ExportBathymetryTab);
            app.UITable10_6.ColumnName = {'Max'; 'Min'};
            app.UITable10_6.RowName = {'Depth'};
            app.UITable10_6.ColumnEditable = true;
            app.UITable10_6.CellEditCallback = createCallbackFcn(app, @UITable10_6CellEdit, true);
            app.Label_312 = uilabel(app.ExportBathymetryTab);
            app.Label_312.Text = 'Depth';
            app.Label_313 = uilabel(app.ExportBathymetryTab);
            app.Label_313.Text = 'Limits';
            app.EnterYCoordinatetoPlotCrossshoreprofilebelowEditFieldLabel_3 = uilabel(app.ExportBathymetryTab);
            app.EnterYCoordinatetoPlotCrossshoreprofilebelowEditFieldLabel_3.Text = 'Enter Y-Coordinate to Plot Cross-shore profile ';
            app.EnterYCoordinatetoPlotCrossshoreprofilebelowEditField_4 = uieditfield(app.ExportBathymetryTab, 'numeric');
            app.EnterYCoordinatetoPlotCrossshoreprofilebelowEditField_4.ValueChangedFcn = createCallbackFcn(app, @EnterYCoordinatetoPlotCrossshoreprofilebelowEditField_4ValueChanged, true);
            app.XSmoothingDistanceEditField_3Label = uilabel(app.ExportBathymetryTab);
            app.XSmoothingDistanceEditField_3Label.Text = 'X Smoothing Distance';
            app.XSmoothingDistanceEditField_3 = uieditfield(app.ExportBathymetryTab, 'numeric');
            app.XSmoothingDistanceEditField_3.ValueChangedFcn = createCallbackFcn(app, @XSmoothingDistanceEditField_3ValueChanged, true);
            app.YSmoothingDistanceEditField_3Label = uilabel(app.ExportBathymetryTab);
            app.YSmoothingDistanceEditField_3Label.Text = 'Y Smoothing Distance';
            app.YSmoothingDistanceEditField_3 = uieditfield(app.ExportBathymetryTab, 'numeric');
            app.YSmoothingDistanceEditField_3.ValueChangedFcn = createCallbackFcn(app, @YSmoothingDistanceEditField_3ValueChanged, true);
            app.PBToolEditFieldLabel = uilabel(app.ExportBathymetryTab);
            app.PBToolEditFieldLabel.Text = 'PB Tool';
            app.PBToolEditField = uieditfield(app.ExportBathymetryTab, 'numeric');
            app.PBToolEditField.ValueChangedFcn = createCallbackFcn(app, @PBToolEditFieldValueChanged, true);
            app.FoldernameEditField_2Label = uilabel(app.ExportBathymetryTab);
            app.FoldernameEditField_2Label.Text = 'Folder name';
            app.FoldernameEditField_2 = uieditfield(app.ExportBathymetryTab, 'text');
            app.OverlaySlider_6Label = uilabel(app.ExportBathymetryTab);
            app.OverlaySlider_6Label.Text = 'Overlay';
            app.OverlaySlider_6 = uislider(app.ExportBathymetryTab);
            app.OverlaySlider_6.ValueChangedFcn = createCallbackFcn(app, @OverlaySlider_6ValueChanged, true);
            app.Label_314 = uilabel(app.ExportBathymetryTab);
            app.Label_314.Text = 'Select cBathy File';
            app.ListBox_33 = uilistbox(app.ExportBathymetryTab);
            app.ListBox_33.Items = {};
            app.ListBox_33.ValueChangedFcn = createCallbackFcn(app, @ListBox_33ValueChanged, true);
            app.ListBox_33.Value = {};
            app.XSmoothingDistanceEditField_3Label_2 = uilabel(app.ExportBathymetryTab);
            app.XSmoothingDistanceEditField_3Label_2.Text = 'PB Tool ';
            app.XSmoothingDistanceEditField_3Label_3 = uilabel(app.ExportBathymetryTab);
            app.XSmoothingDistanceEditField_3Label_3.Text = 'PB Tool ';
            app.YSmoothingDistanceEditField_3Label_2 = uilabel(app.ExportBathymetryTab);
            app.YSmoothingDistanceEditField_3Label_2.Text = 'Solution Weighting';
            app.Lamp_27 = uilamp(app.ExportBathymetryTab);
            app.Label_344 = uilabel(app.ExportBathymetryTab);
            app.Label_344.Text = '[%]';
            app.Label_346 = uilabel(app.ExportBathymetryTab);
            app.Label_346.Text = '[]';
            app.Label_349 = uilabel(app.ExportBathymetryTab);
            app.Label_349.Text = '[%]';
            app.Label_350 = uilabel(app.ExportBathymetryTab);
            app.Label_350.Text = '[]';
            app.Label_351 = uilabel(app.ExportBathymetryTab);
            app.Label_351.Text = '[]';
            app.YSmoothingDistanceEditField_3Label_3 = uilabel(app.ExportBathymetryTab);
            app.YSmoothingDistanceEditField_3Label_3.Text = 'Error Threshold';
            app.Label_364 = uilabel(app.ExportBathymetryTab);
            app.Label_364.Text = '[]';
            app.cBathyEditFieldLabel = uilabel(app.ExportBathymetryTab);
            app.cBathyEditFieldLabel.Text = 'cBathy';
            app.cBathyEditField = uieditfield(app.ExportBathymetryTab, 'numeric');
            app.cBathyEditField.ValueChangedFcn = createCallbackFcn(app, @cBathyEditFieldValueChanged, true);
            app.Label_365 = uilabel(app.ExportBathymetryTab);
            app.Label_365.Text = '____________________________________________________';
            app.Label_366 = uilabel(app.ExportBathymetryTab);
            app.Label_366.Text = 'Display Data';
            app.BlendButton = uibutton(app.ExportBathymetryTab, 'push');
            app.BlendButton.ButtonPushedFcn = createCallbackFcn(app, @BlendButtonPushed, true);
            app.BlendButton.Text = 'Blend';
            app.Lamp_29 = uilamp(app.ExportBathymetryTab);
            app.ResetButton = uibutton(app.ExportBathymetryTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.UIAxes2_8 = uiaxes(app.ExportBathymetryTab);
            title(app.UIAxes2_8, 'Blended Bathymetry')
            xlabel(app.UIAxes2_8, 'X')
            ylabel(app.UIAxes2_8, 'Y')
            app.UIAxes6_2 = uiaxes(app.ExportBathymetryTab);
            title(app.UIAxes6_2, 'Cross-Shore Profile')
            xlabel(app.UIAxes6_2, 'X')
            ylabel(app.UIAxes6_2, 'Z')
            app.UIAxes2_9 = uiaxes(app.ExportBathymetryTab);
            title(app.UIAxes2_9, 'Raw cBathy Bathymetry')
            xlabel(app.UIAxes2_9, 'X')
            ylabel(app.UIAxes2_9, 'Y')
            app.UIAxes2_10 = uiaxes(app.ExportBathymetryTab);
            title(app.UIAxes2_10, 'Raw PB Tool Bathymetry')
            xlabel(app.UIAxes2_10, 'X')
            ylabel(app.UIAxes2_10, 'Y')
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'ERDCLOGO.PNG';
            app.NewProjectLabel = uilabel(app.UIFigure);
            app.NewProjectLabel.Text = 'New Project';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = uasToolbox

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end